classdef winMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainApp                         matlab.ui.Figure
        Grid1                           matlab.ui.container.GridLayout
        menu_Panel                      matlab.ui.container.Panel
        menu_Grid1                      matlab.ui.container.GridLayout
        menu_Grid2                      matlab.ui.container.GridLayout
        menu_Grid2A                     matlab.ui.container.GridLayout
        menu_Separator1_2               matlab.ui.control.Image
        Button_AnatelDB                 matlab.ui.control.Button
        Button_Logger                   matlab.ui.control.StateButton
        Button_Home                     matlab.ui.control.StateButton
        menu_Separator1                 matlab.ui.control.Image
        Button_Task                     matlab.ui.control.StateButton
        menu_Grid2B                     matlab.ui.container.GridLayout
        menu_userPath                   matlab.ui.control.DropDown
        menu_searchPath                 matlab.ui.control.Image
        tool_Panel                      matlab.ui.container.Panel
        tool_Grid1                      matlab.ui.container.GridLayout
        tool_Grid2                      matlab.ui.container.GridLayout
        tool_Task                       matlab.ui.control.Label
        tool_REC                        matlab.ui.control.Image
        tool_Button3                    matlab.ui.control.Button
        tool_Button2                    matlab.ui.control.Button
        tool_Button1                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab1_home                       matlab.ui.container.Tab
        home_Grid                       matlab.ui.container.GridLayout
        appColetaR2022av110Label        matlab.ui.control.Label
        home_label5                     matlab.ui.control.Label
        home_label4                     matlab.ui.control.Label
        home_label3                     matlab.ui.control.Label
        home_label2                     matlab.ui.control.Label
        home_label1                     matlab.ui.control.Label
        home_img5                       matlab.ui.control.Button
        home_img4                       matlab.ui.control.Button
        home_img3                       matlab.ui.control.Button
        home_img2                       matlab.ui.control.Button
        home_img1                       matlab.ui.control.Button
        Tab2_task                       matlab.ui.container.Tab
        task_Grid                       matlab.ui.container.GridLayout
        task_ButtonGrid                 matlab.ui.container.GridLayout
        task_Button2                    matlab.ui.control.Button
        task_Button1                    matlab.ui.control.Button
        task_LogFile                    matlab.ui.control.Hyperlink
        task_scpiListEdit               matlab.ui.control.Button
        task_NameEdit                   matlab.ui.control.Button
        Separator1                      matlab.ui.control.Image
        task_info                       matlab.ui.control.Button
        task_Panel2                     matlab.ui.container.Panel
        task_GridB                      matlab.ui.container.GridLayout
        task_Panel21                    matlab.ui.container.Panel
        task_Grid21                     matlab.ui.container.GridLayout
        Task_SyncRef                    matlab.ui.control.DropDown
        Task_SyncRefLabel               matlab.ui.control.Label
        Task_ResetCommand               matlab.ui.control.DropDown
        Task_ResetCommandLabel          matlab.ui.control.Label
        Task_Label9_6                   matlab.ui.control.Label
        task_Panel_22                   matlab.ui.container.Panel
        task_Grid22                     matlab.ui.container.GridLayout
        task_IntegrationTimeLabel       matlab.ui.control.Label
        task_IntegrationTime            matlab.ui.control.NumericEditField
        task_LevelUnit2Label            matlab.ui.control.Label
        task_LevelUnit2                 matlab.ui.control.EditField
        task_Detector2Label             matlab.ui.control.Label
        task_Detector2                  matlab.ui.control.DropDown
        task_PreampLabel                matlab.ui.control.Label
        task_Preamp                     matlab.ui.control.DropDown
        task_attFactorLabel             matlab.ui.control.Label
        task_attFactor                  matlab.ui.control.DropDown
        task_attModeLabel               matlab.ui.control.Label
        task_attMode                    matlab.ui.control.DropDown
        task_Resolution2Label           matlab.ui.control.Label
        task_Resolution2                matlab.ui.control.DropDown
        task_SelectivityLabel           matlab.ui.control.Label
        task_Selectivity                matlab.ui.control.DropDown
        task_DataPointsLabel            matlab.ui.control.Label
        task_DataPoints2                matlab.ui.control.NumericEditField
        task_DataPoints1                matlab.ui.control.Spinner
        task_StepWidth2Label            matlab.ui.control.Label
        task_StepWidth2                 matlab.ui.control.DropDown
        task_StepWidth1                 matlab.ui.control.NumericEditField
        task_GridB2                     matlab.ui.container.GridLayout
        task_imgRefresh                 matlab.ui.control.Image
        task_PanelLabel                 matlab.ui.control.Label
        task_antennaPanel               matlab.ui.container.Panel
        task_antennaGrid                matlab.ui.container.GridLayout
        task_ElevationLabel             matlab.ui.control.Label
        task_Elevation                  matlab.ui.control.NumericEditField
        task_AzimuthLabel               matlab.ui.control.Label
        task_Azimuth                    matlab.ui.control.NumericEditField
        task_HeightLabel                matlab.ui.control.Label
        task_Height                     matlab.ui.control.NumericEditField
        task_Antenna                    matlab.ui.control.DropDown
        task_AntennaLabel               matlab.ui.control.Label
        task_gpsPanel                   matlab.ui.container.Panel
        task_gpsGrid                    matlab.ui.container.GridLayout
        task_manualLongitude            matlab.ui.control.NumericEditField
        task_manualLongitudeLabel       matlab.ui.control.Label
        task_manualLatitude             matlab.ui.control.NumericEditField
        task_manualLatitudeLabel        matlab.ui.control.Label
        task_gpsRevisitTime             matlab.ui.control.NumericEditField
        task_gpsRevisitTimeLabel        matlab.ui.control.Label
        task_gpsListGrid                matlab.ui.container.GridLayout
        task_gpsConnectivity            matlab.ui.control.Button
        task_gpsList                    matlab.ui.control.DropDown
        task_gpsListLabel               matlab.ui.control.Label
        task_scpiListGrid               matlab.ui.container.GridLayout
        task_scpiConnectivity           matlab.ui.control.Button
        task_scpiListLabel              matlab.ui.control.Label
        task_scpiList                   matlab.ui.control.DropDown
        task_Label2                     matlab.ui.control.Label
        task_Panel1                     matlab.ui.container.Panel
        task_GridA                      matlab.ui.container.GridLayout
        task_NameLabel                  matlab.ui.control.Label
        task_Name                       matlab.ui.control.DropDown
        task_GridA3                     matlab.ui.container.GridLayout
        task_BitsPerPointLabel          matlab.ui.control.Label
        task_BitsPerPoint               matlab.ui.control.EditField
        task_DurationUnit               matlab.ui.control.DropDown
        task_DurationLabel              matlab.ui.control.Label
        task_Duration                   matlab.ui.control.NumericEditField
        task_Tree                       matlab.ui.container.Tree
        task_TreeLabel                  matlab.ui.control.Label
        task_GridA2                     matlab.ui.container.GridLayout
        task_maskFile                   matlab.ui.control.Button
        task_addMaskFile                matlab.ui.control.Button
        task_TypeLabel                  matlab.ui.control.Label
        task_Type                       matlab.ui.control.DropDown
        task_BandLabel                  matlab.ui.control.Label
        task_PanelA1                    matlab.ui.container.Panel
        task_GridA4                     matlab.ui.container.GridLayout
        task_LevelUnitLabel             matlab.ui.control.Label
        task_LevelUnit                  matlab.ui.control.EditField
        task_RFModeLabel                matlab.ui.control.Label
        task_RFMode                     matlab.ui.control.EditField
        task_DetectorLabel              matlab.ui.control.Label
        task_Detector                   matlab.ui.control.EditField
        task_TraceModeLabel             matlab.ui.control.Label
        task_TraceMode                  matlab.ui.control.EditField
        task_IntegrationFactorLabel     matlab.ui.control.Label
        task_IntegrationFactor          matlab.ui.control.NumericEditField
        task_FreqStopLabel              matlab.ui.control.Label
        task_FreqStartLabel             matlab.ui.control.Label
        task_IDLabel                    matlab.ui.control.Label
        task_RevisitTime                matlab.ui.control.NumericEditField
        task_RevisitTimeLabel           matlab.ui.control.Label
        task_Resolution                 matlab.ui.control.NumericEditField
        task_ResolutionLabel            matlab.ui.control.Label
        task_StepWidth                  matlab.ui.control.NumericEditField
        task_StepWidthLabel             matlab.ui.control.Label
        task_DescriptionLabel           matlab.ui.control.Label
        task_FreqStop                   matlab.ui.control.NumericEditField
        task_FreqStart                  matlab.ui.control.NumericEditField
        task_Description                matlab.ui.control.EditField
        task_ID                         matlab.ui.control.NumericEditField
        task_LabelGrid                  matlab.ui.container.GridLayout
        task_PlotVisibility             matlab.ui.control.Image
        task_Label1                     matlab.ui.control.Label
        Tab3_logger                     matlab.ui.container.Tab
        logger_Grid1                    matlab.ui.container.GridLayout
        logger_toolbar                  matlab.ui.container.GridLayout
        logger_ButtonPlay               matlab.ui.control.Button
        logger_ftpStatus                matlab.ui.control.Label
        logger_winSCP                   matlab.ui.control.Button
        logger_Separator2               matlab.ui.control.Image
        logger_Monitor                  matlab.ui.control.Button
        logger_EditRFeyeList            matlab.ui.control.Button
        logger_Separator1               matlab.ui.control.Image
        logger_info                     matlab.ui.control.Button
        loger_Panel2                    matlab.ui.container.Panel
        logger_Panel2_Grid              matlab.ui.container.GridLayout
        logger_TextInfo                 matlab.ui.control.TextArea
        logger_TextInfoLabel            matlab.ui.control.Label
        logger_Panel4                   matlab.ui.container.Panel
        logger_Panel4_Grid              matlab.ui.container.GridLayout
        logger_DataPoints               matlab.ui.control.NumericEditField
        logger_DataPointsLabel          matlab.ui.control.Label
        logger_Operation                matlab.ui.control.EditField
        logger_OperationLabel           matlab.ui.control.Label
        logger_StepWidth                matlab.ui.control.NumericEditField
        logger_StepWidthLabel           matlab.ui.control.Label
        logger_FreqStopLabel            matlab.ui.control.Label
        logger_FreqStop                 matlab.ui.control.NumericEditField
        logger_FreqStart                matlab.ui.control.NumericEditField
        logger_FreqStartLabel           matlab.ui.control.Label
        logger_ThreadID                 matlab.ui.control.NumericEditField
        logger_ThreadIDLabel            matlab.ui.control.Label
        logger_LevelUnitLabel           matlab.ui.control.Label
        logger_LevelUnit                matlab.ui.control.EditField
        logger_ResolutionLabel          matlab.ui.control.Label
        logger_Resolution               matlab.ui.control.NumericEditField
        logger_DescriptionLabel         matlab.ui.control.Label
        logger_Description              matlab.ui.control.EditField
        logger_Sublabel3                matlab.ui.control.Label
        logger_Panel3                   matlab.ui.container.Panel
        logger_Panel3_Grid              matlab.ui.container.GridLayout
        logger_Mask                     matlab.ui.control.Button
        logger_Script                   matlab.ui.control.Button
        logger_MaskName                 matlab.ui.control.DropDown
        logger_MaskNameLabel            matlab.ui.control.Label
        logger_ScriptName               matlab.ui.control.EditField
        logger_ScriptNameLabel          matlab.ui.control.Label
        logger_Sublabel2                matlab.ui.control.Label
        logger_Tree                     matlab.ui.container.CheckBoxTree
        logger_Sublabel1                matlab.ui.control.Label
        logger_Label2                   matlab.ui.control.Label
        logger_Panel1                   matlab.ui.container.Panel
        logger_Panel1_Grid              matlab.ui.container.GridLayout
        logger_udpDatagrams_Grid        matlab.ui.container.GridLayout
        logger_Pause                    matlab.ui.control.Spinner
        logger_PauseLabel               matlab.ui.control.Label
        logger_AvailableDatagrams       matlab.ui.control.NumericEditField
        logger_AvailableDatagramsLabel  matlab.ui.control.Label
        logger_Panel1a                  matlab.ui.container.Panel
        logger_Panel1a_Grid             matlab.ui.container.GridLayout
        logger_Port                     matlab.ui.control.NumericEditField
        logger_PortLabel                matlab.ui.control.Label
        logger_IP                       matlab.ui.control.DropDown
        logger_IPLabel                  matlab.ui.control.Label
        logger_Receiver_Grid            matlab.ui.container.GridLayout
        logger_Conectivity              matlab.ui.control.Button
        logger_NodeList                 matlab.ui.control.DropDown
        logger_NodeListLabel            matlab.ui.control.Label
        logger_Grid2                    matlab.ui.container.GridLayout
        logger_PlotVisibility           matlab.ui.control.Image
        logger_Label1                   matlab.ui.control.Label
    end


    properties (Access = public)
        
        Version
        RootFolder = [];

        % Variáveis lidas no início da aplicação:
        instrInfo
        
        scpiList
        rfeyeList

        taskList
        bandList        
        
        EB500Map

        % Instrumentos e sockets:
        Instrument    = [];
        udpPortArray  = {};
        udpPort       = [];
        gpsInstrument = [];
        instrTimeout
        ftpObj  = [];

        % Related to live plot
        traceInfo     = struct('SelectedNode', 0, ...
                               'Mode', [0, 0, 0], ...
                               'plotLayout', 0);

        fig = [];

        tb_refreshView = [];
        tb_interaction = [];
        tb_layout      = [];
        tb_minHold     = [];
        tb_meanHold    = [];
        tb_maxHold     = [];
        tb_waterfall   = [];
        tb_mask        = [];
        tb_rec         = [];

        axes1 = [];
        axes2 = [];

        line_ClrWrite = [];
        line_minHold  = [];
        line_Average  = [];
        line_maxHold  = [];
        surface_wFall = [];
        cBar1         = [];

        General = struct('Version', '',                         ...
                         'Image', struct('Visibility', 'on',    ...
                                         'Format', 'jpeg',      ...         % Image format - JPEG (default) and PNG
                                         'Resolution', 120),    ...         % Image resolution - 100, 120 (default), 150 e 200 dpi 
                         'Colors', [0.07, 0.07, 0.75;  ...                  % color1 (MinHold)
                                    0.39, 0.83, 0.07;  ...                  % color2 (Median or Mean)
                                    1.00, 0.07, 0.65;  ...                  % color3 (MaxHold)
                                    0.93, 0.69, 0.13], ...                  % color4 (ClearWrite)
                         'Models', [],                 ...                  % Report templates (app.InspectionType)
                         'FileSize', 100e+6,           ...
                         'ColorMap', 'jet',            ...
                         'HistoryDepth', 128,          ...
                         'AverageFactor', 10,          ...
                         'SampleTimeFactor', 10,       ...
                         'logger', struct('ConnectionTime', 30, 'HandshakePause', 1, 'Timeout', 5))

        RestoreView = [];

        taskFlag = 0;
        recFlag  = 0;
        axesFlag = 0;
        axesFlagType = '';
        
        x

        timeInfo   = [];
        specInfo   = [];
        specMatrix = [];
        gpsInfo    = [];
        
        auxWin1 = [];           % winSettings
        auxWin2 = [];           % winTaskEdit
        auxWin3 = [];           % winSCPIEdit
        auxWin4 = [];           % winRFeyeEdit
        auxWin5 = [];           % winMonitor
        auxWin8 = [];           % winAnatelDB

    end


    methods (Access = private)

        function startupError(app)
            
            delete(app.fig)
            delete(app)
            
        end


        function [mainMonitor, x0] = startup_appPosition(app)

            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind] = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
                        
            if mainMonitor(3) >= 1016; x0 = 1016;
            else;                      x0 = mainMonitor(3);
            end
            
            if mainMonitor(4) > 768;   y0 = mainMonitor(4) - 696 - 30;
            else;                      y0 = 42;
            end

            app.MainApp.Position = [mainMonitor(1), mainMonitor(2)+y0, x0, 696];

        end


        function [x1, y1] = startup_figPosition(app, mainMonitor, x0)

                if mainMonitor(3) > x0 + 640; x1 = mainMonitor(1) + x0 + 6;
                else;                         x1 = mainMonitor(3) - 640;
                end
                y1 = mainMonitor(2) + mainMonitor(4) - 480 - 28 - 28; % window figure height, figure & toolbar panels

        end


        function startup_figCreation(app)

            [mainMonitor, x0] = startup_appPosition(app);
            [x1, y1] = startup_figPosition(app, mainMonitor, x0);
            
            app.fig = figure('Name', 'appColeta: Plot', 'NumberTitle', 'off', 'Position', [x1, y1, 640, 480], ...
                             'Visible', 'off', 'Color', [0.94,0.94,0.94], 'GraphicsSmoothing', 'on', 'CloseRequestFcn', @app.Plot_CloseFigure);
            delete(findall(app.fig, '-not', 'Type', 'Figure'));

            tb = uitoolbar(app.fig);            
            app.tb_refreshView =   uipushtool(tb, 'ClickedCallback', @app.toolbarCallbacks_refreshView, 'CData', startup_toolbarIcon(app, 'refreshView'));
            app.tb_interaction =   uipushtool(tb, 'ClickedCallback', @app.toolbarCallbacks_interaction, 'CData', startup_toolbarIcon(app, 'interaction_pan'),                    'Tag', 'pan');
            app.tb_layout      =   uipushtool(tb, 'ClickedCallback', @app.toolbarCallbacks_layout,      'CData', startup_toolbarIcon(app, 'layout1'),         'Separator', 'on', 'Tag', '1:1');
            app.tb_minHold     = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_minHold,     'CData', startup_toolbarIcon(app, 'minHold'),         'Separator', 'on');
            app.tb_meanHold    = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_meanHold,    'CData', startup_toolbarIcon(app, 'meanHold'));
            app.tb_maxHold     = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_maxHold,     'CData', startup_toolbarIcon(app, 'maxHold'));
            app.tb_waterfall   = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_waterfall,   'CData', startup_toolbarIcon(app, 'waterfall'),       'Separator', 'on');
            app.tb_mask        =   uipushtool(tb, 'ClickedCallback', @app.toolbarCallbacks_mask,        'CData', startup_toolbarIcon(app, 'mask'),                               'Visible', 0);
            app.tb_rec         = uitoggletool(tb, 'ClickedCallback', @app.toolbarCallbacks_rec,         'CData', startup_toolbarIcon(app, 'rec_play'),        'Separator', 'on', 'Visible', 0);
                        
            warning('off')
            jFrame = get(app.fig, 'javaframe');
            jIcon  = javax.swing.ImageIcon(fullfile(app.RootFolder, 'Icons', 'icon_48.png'));
            jFrame.setFigureIcon(jIcon);
            warning('on')
            
            app.axes1 = axes(app.fig, 'Box', 'on', 'Color', [0,0,0], 'FontName', 'Helvetica', ...
                                      'FontSize', 7, 'FontSmoothing', 'on',                   ...
                                      'XColor', [0,0,0], 'XGrid', 'on', 'XMinorGrid', 'on',   ...
                                      'YColor', [0,0,0], 'YGrid', 'on', 'YMinorGrid', 'on',   ...
                                      'XTickLabel', {}, 'GridAlpha', .25, 'GridColor', [0.94,0.94,0.94], ...
                                      'MinorGridAlpha', .2 , 'MinorGridColor', [0.94,0.94,0.94], ...
                                      'Unit',  'normalized', 'InnerPosition',  [.08 .1 .85 .82], ...
                                      'TitleHorizontalAlignment', 'right', 'Toolbar', []);
            
            app.axes2 = axes(app.fig, 'Box', 'on', 'LineWidth', 1, 'Color', [0.94,0.94,0.94],        ...
                                      'FontName', 'Helvetica', 'FontSize', 7, 'FontSmoothing', 'on', ...
                                      'XColor', [0,0,0], 'YColor', [0,0,0], 'XTickLabel', {},        ...
                                      'Unit', 'normalized', 'InnerPosition', [0 0 0 0], 'Toolbar', []);

            set(app.axes1.Title,    'FontSize', 7, 'FontWeight',   'bold', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes1.Subtitle, 'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes1.XLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes1.YLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])

            set(app.axes2.XLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            set(app.axes2.YLabel,   'FontSize', 7, 'FontWeight', 'normal', 'Color', [0,0,0], 'FontSmoothing', 'on', 'Interactions', [])
            
            hold(app.axes1, 'on')
            hold(app.axes2, 'on')

            linkaxes([app.axes1, app.axes2], 'x')
            
            app.axes1.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
            app.axes2.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
            
            enableDefaultInteractivity(app.axes1)
            enableDefaultInteractivity(app.axes2)
            
            app.cBar1 = colorbar('Location', 'manual', 'Units', 'normalized', 'Position', [0.935, 0.1, 0.01, 0.1], ...
                                 'AxisLocation', 'out', 'FontName', 'Helvetica', 'FontSize', 7, 'Color', [0,0,0]);

        end


        function icon = startup_toolbarIcon(app, Flag)

            switch Flag
                case 'refreshView';      filename = 'tb_refreshView.gif';
                case 'interaction_pan';  filename = 'tb_interaction_pan.gif';
                case 'interaction_zoom'; filename = 'tb_interaction_zoom.gif';
                case 'layout1';          filename = 'tb_layout1.gif';
                case 'layout2';          filename = 'tb_layout2.gif';
                case 'minHold';          filename = 'tb_minHold.gif';
                case 'meanHold';         filename = 'tb_meanHold.gif';
                case 'maxHold';          filename = 'tb_maxHold.gif';
                case 'waterfall';        filename = 'tb_waterfall.gif';
                case 'mask';             filename = 'tb_mask.gif';
                case 'rec_play';         filename = 'tb_rec_play.gif';
                case 'rec_stop';         filename = 'tb_rec_stop.gif';
            end
            
            [img, map] = imread(fullfile(app.RootFolder, 'Icons', filename));
            icon = ind2rgb(img, map);

        end


        function userPath = startup_userInitialFolder(app)

                if isfolder(fullfile(getenv('USERPROFILE'), 'Downloads'))
                    userPath = {fullfile(getenv('USERPROFILE'), 'Downloads')};
                else
                    userPath = {fullfile(getenv('USERPROFILE'), 'Documents')};                        
                end

        end
        
        
        function startup_userSelectedFolder(app)
            
            if isfile(fullfile(app.RootFolder, 'DataBase', 'UserPath.cfg'))
                userPath = fileread(fullfile(app.RootFolder, 'DataBase', 'UserPath.cfg'));
                
                idx = find(strcmp(app.menu_userPath.Items, userPath), 1);
                if isempty(idx)
                    app.menu_userPath.Items(end+1) = {userPath};
                    app.menu_userPath.Value        = userPath;
                else
                    app.menu_userPath.Value        = userPath;                        
                end                    
            end
        
        end


        function startup_InspectionType(app)
            
            app.General.Models = struct2table(jsondecode(fileread(fullfile(app.RootFolder, 'Template', 'html_General.cfg'))));
            
        end


        function ModeOperation(app)

            if app.Button_Home.Value
                idx = 1;

            elseif app.Button_Task.Value
                idx = 2;
                ModeOperation_Task(app)

            elseif app.Button_Logger.Value
                idx = 3;
                ModeOperation_Logger(app)

                if isempty(app.logger_NodeList.Items)
                    app.Button_Home.Value = 1;
                    Button_HomeValueChanged(app)

                    return
                end

            elseif app.Button_Misc.Value
                idx = 4;
                ModeOperation_Misc(app)
            end
            
            app.TabGroup.SelectedTab = app.TabGroup.Children(idx);
            drawnow

        end


        function ModeOperation_Task(app)

            if isempty(app.taskList)
                % taskList
                [app.taskList, msg1] = OpenFile_taskList(fullfile(app.RootFolder, 'Settings', 'taskList.json'), app.RootFolder);

                app.task_Name.Items = {};
                for ii = 1:numel(app.taskList)
                    app.task_Name.Items(end+1) = {app.taskList{ii}.Name};

                    NN = numel(app.taskList{ii}.Band);
                    for jj = NN:-1:1
                        if app.taskList{ii}.Band(jj).Enable
                            app.taskList{ii}.Band(jj).EditedFlag = 0;
                        else
                            app.taskList{ii}.Band(jj) = [];
                        end
                    end
                end

                if ~isempty(msg1)
                    Misc_uialertCreation(app, 'warning', msg1)
                end
                

                % scpiList
                [app.scpiList, msg2] = OpenFile_scpiList(fullfile(app.RootFolder, 'Settings', 'scpiList.json'), app.RootFolder);

                idx = intersect(find(app.scpiList.Family == "Receptor"), find(app.scpiList.Enable))';

                if ~isempty(idx)
                    set(app.task_Grid22.Children, 'Enable', 1)
                    set(app.task_Grid21.Children, 'Enable', 1)

                    app.task_scpiList.Items = {};
                    for ii = idx
                        Parameters = task_ParametersField(app, ii);
    
                        if ~isempty(Parameters.IP) && ~isempty(Parameters.Port)
                            Socket = sprintf('%s:%s', Parameters.IP, Parameters.Port);
                        elseif ~isempty(Parameters.IP)
                            Socket = Parameters.IP;
                        else
                            Socket = Parameters.Port;
                        end
    
                        app.task_scpiList.Items(end+1) = {sprintf('ID%d: %s - %s', ii, app.scpiList.Name{ii}, Socket)};
                    end
                end
    
                if ~isempty(msg2)
                    Misc_uialertCreation(app, 'warning', msg2)
                end
                

                % instrInfo e EB500Map
                [app.instrInfo, app.EB500Map] = task_OpenVariables(app.RootFolder);
            end

            task_TaskSelectionChanged(app)

        end


        function ModeOperation_Logger(app)

            if isempty(app.rfeyeList)
                [app.rfeyeList, msg] = OpenFile_rfeyeList(fullfile(app.RootFolder, 'Settings', 'rfeyeList.cfg'));

                if ~isempty(msg)
                    Misc_uialertCreation(app, 'warning', msg)
                    return
                end

                
                app.logger_NodeList.Items = cellstr(string(app.rfeyeList.Name(find(app.rfeyeList.Enable))) + " - " + string(app.rfeyeList.Location(find(app.rfeyeList.Enable))));
                logger_NodeListValueChanged(app)
            end

        end


        function ModeOperation_Misc(app)
        end


        function task_Layout(app)
            
            if app.Button_Task.Value
                if ismember(app.taskFlag, [1, 2])
                    set(app.menu_Grid2A.Children, 'Enable', 0)
                    app.Button_AnatelDB.Enable = 1;

                    set(findobj(groot, 'Tag', 'task_Editable'), 'Enable', 0)
                    set(app.task_Grid22.Children, 'Enable', 0)

                    set(app.task_LogFile, 'Text', '', 'URL', '')
                    if app.taskFlag == 1
                        set(app.task_Button1, 'Enable', 1, 'Icon', 'Media_StopWhite.png')
                        set(app.task_Button2, 'Enable', 0)
                        
                        app.tool_Task.Text   = 'WPT';
                        app.tool_REC.Visible = 0;

                        set(app.tb_rec, 'Visible', 1, 'State', 0, 'CData', startup_toolbarIcon(app, 'rec_play'))

                    else
                        set(app.task_Button1, 'Enable', 0)
                        set(app.task_Button2, 'Enable', 1, 'Icon', 'Media_StopWhite.png')

                        app.tool_Task.Text   = 'RT';
                        app.tool_REC.Visible = 1;

                        set(app.tb_rec, 'State', 0, 'Visible', 0)
                    end

                    app.tb_mask.Visible = 0;

                else
                    set(app.menu_Grid2A.Children, 'Enable', 1)
                    set(findobj(groot, 'Tag', 'task_Editable'), 'Enable', 1)
                    set(app.task_Grid22.Children, 'Enable', 1)

                    app.task_Tree.Enable = 1;
                    
                    task_gpsListValueChanged(app)
                    task_AntennaValueChanged(app)

                    set(app.task_Button1, 'Enable',  1, 'Icon', 'General_play.png')
                    set(app.task_Button2, 'Enable',  1, 'Icon', 'General_play.png')
                    
                    app.tool_Task.Text   = '';
                    app.tool_REC.Visible = 0;

                    if app.tb_rec.State
                        set(app.tb_rec, 'State', 0, 'Visible', 0)
                    end
                end

            elseif app.Button_Logger.Value
                if app.taskFlag == 3
                    set(app.menu_Grid2A.Children, 'Enable', 0)
                    app.Button_AnatelDB.Enable = 1;

                    % Painel 1:
                    app.logger_NodeList.Enable    = 0;
                    app.logger_Conectivity.Enable = 0;

                    set(app.logger_Panel1a_Grid.Children,      'Enable', 0)
                    set(app.logger_udpDatagrams_Grid.Children, 'Visible', 1)

                    % Painel 2:                    
                    app.logger_ThreadID.Value    = -1;
                    app.logger_Description.Value = '';
                    app.logger_FreqStart.Value   = -1;
                    app.logger_FreqStop.Value    = -1;
                    app.logger_StepWidth.Value   = -1;
                    app.logger_Resolution.Value  = -1;
                    app.logger_Operation.Value   = '';
                    app.logger_DataPoints.Value  = -1;
                    app.logger_LevelUnit.Value   = '';
                    
                    set(app.logger_Tree, 'Enable', 1, 'BackgroundColor', 'white')                
                    set(app.logger_TextInfo, 'Enable', 1, 'Value', '')

                    app.logger_EditRFeyeList.Enable = 0;
                    app.logger_Monitor.Enable = 0;
                    
                    set(app.logger_ButtonPlay, 'Icon', 'Media_StopWhite.png')
                    app.tool_Task.Text = 'LT';
                    
                    set(app.tb_rec, 'Visible', 1, 'State', 0, 'CData', startup_toolbarIcon(app, 'rec_play'))

                else
                    set(app.menu_Grid2A.Children, 'Enable', 1)

                    % Painel 1:
                    app.logger_NodeList.Enable    = 1;
                    app.logger_Conectivity.Enable = 1;

                    set(app.logger_Panel1a_Grid.Children, 'Enable', 1)
                    set(app.logger_AvailableDatagrams, 'Visible', 0, 'Value', 0)
                    app.logger_AvailableDatagramsLabel.Visible = 0;
                    app.logger_PauseLabel.Visible = 0;
                    app.logger_Pause.Visible      = 0;

                    % Painel 2:                    
                    set(app.logger_ButtonPlay, 'Icon', 'General_play.png')
                    app.tool_Task.Text = '';

                    app.logger_EditRFeyeList.Enable = 1;
                    app.logger_Monitor.Enable = 1;

                    app.task_Button1.Icon = 'General_play.png';

                    set(app.tb_rec, 'Visible', 0, 'State', 0, 'CData', startup_toolbarIcon(app, 'rec_play'))
                end
            end

        end


        function task_TreeBuilding(app, ind1)

            delete(app.task_Tree.Children);

            for ii = 1:numel(app.taskList{ind1}.Band)
                str = sprintf('ThreadID %d: %.3f - %.3f MHz',                  ...
                                app.taskList{ind1}.Band(ii).ThreadID,          ...
                                app.taskList{ind1}.Band(ii).FreqStart ./ 1e+6, ...
                                app.taskList{ind1}.Band(ii).FreqStop  ./ 1e+6);

                uitreenode(app.task_Tree, 'Text', str, 'NodeData', ii);
            end

            if ~app.taskFlag; app.task_Tree.SelectedNodes = app.task_Tree.Children(1);
            else;             app.task_Tree.SelectedNodes = app.task_Tree.Children(app.specMatrix.Index(4));
            end

        end


        function task_ParametersValues(app, ind1, ind2)

            app.task_ID.Value                = app.taskList{ind1}.Band(ind2).ThreadID;
            app.task_Description.Value       = app.taskList{ind1}.Band(ind2).Description;
            app.task_FreqStart.Value         = app.taskList{ind1}.Band(ind2).FreqStart / 1e+6;
            app.task_FreqStop.Value          = app.taskList{ind1}.Band(ind2).FreqStop  / 1e+6;
            app.task_RFMode.Value            = app.taskList{ind1}.Band(ind2).RFMode;
            app.task_StepWidth.Value         = app.taskList{ind1}.Band(ind2).StepWidth  / 1e+3;
            app.task_Resolution.Value        = app.taskList{ind1}.Band(ind2).Resolution / 1e+3;
            app.task_LevelUnit.Value         = app.taskList{ind1}.Band(ind2).LevelUnit;
            app.task_TraceMode.Value         = app.taskList{ind1}.Band(ind2).TraceMode;
            app.task_Detector.Value          = app.taskList{ind1}.Band(ind2).Detector;
            app.task_RevisitTime.Value       = app.taskList{ind1}.Band(ind2).RevisitTime;
            app.task_IntegrationFactor.Value = app.taskList{ind1}.Band(ind2).IntegrationFactor;

        end


        function task_ParametersEditables_Visibility(app)

            ind = find(app.instrInfo.Name == string(extractBetween(app.task_scpiList.Value, ': ', ' -')), 1);
            switch app.instrInfo.connectFlag{ind}
                case 1
                    app.task_Grid22.RowHeight(2:3)    = {22,0};
                    set(findobj(groot, 'Parent', app.task_Grid22, 'Tag', 'task_Set1'), 'Enable', 1)
                    set(findobj(groot, 'Parent', app.task_Grid22, 'Tag', 'task_Set2'), 'Enable', 0)

                    app.task_PreampLabel.Visible      = 1;
                    app.task_Preamp.Visible           = 1;

                    app.task_Resolution2.Layout.Row   = 2;
                    
                    app.task_SelectivityLabel.Visible = 0;
                    app.task_Selectivity.Visible      = 0;

                    app.task_IntegrationTimeLabel.Visible = 0;
                    app.task_IntegrationTime.Visible      = 0;

                case 2
                    app.task_Grid22.RowHeight(2:3)    = {0,22};
                    set(findobj(groot, 'Parent', app.task_Grid22, 'Tag', 'task_Set1'), 'Enable', 0)
                    set(findobj(groot, 'Parent', app.task_Grid22, 'Tag', 'task_Set2'), 'Enable', 1)

                    app.task_PreampLabel.Visible      = 0;
                    app.task_Preamp.Visible           = 0;

                    app.task_Resolution2.Layout.Row   = 3;

                    app.task_SelectivityLabel.Visible = 1;
                    app.task_Selectivity.Visible      = 1;

                    app.task_IntegrationTimeLabel.Visible = 1;
                    switch app.task_TraceMode.Value
                        case 'ClearWrite'
                            set(app.task_IntegrationTime, 'Visible', 1, 'Enable', 0)
                        otherwise
                            set(app.task_IntegrationTime, 'Visible', 1, 'Enable', 1)
                    end
            end

        end


        function instrSettings = task_ParametersEditables_GetValues(app, ind1, ind2)

            instrSettings = struct('StepWidth_Items',   [], 'StepWidth',       [], ...
                                   'DataPoints_Limits', [], 'DataPoints',      [], ...
                                   'Resolution_Items',  [], 'Resolution',      [], ...
                                   'Selectivity_Items', [], 'Selectivity',     [], ...
                                   'SensitivityMode',   [], 'Preamp',          [], ...
                                   'AttMode',           [], 'AttFactor_Items', [], ...
                                   'AttFactor',         [], 'LevelUnit',       [], ...
                                   'Detector_Items',    [], 'IntegrationTime', []);

            span = app.taskList{ind1}.Band(ind2).FreqStop - app.taskList{ind1}.Band(ind2).FreqStart;

            ind0 = find(app.instrInfo.Name == string(extractBetween(app.task_scpiList.Value,   ': ', ' -')), 1);
            
            instrSettings.AttMode         = 'Auto';
            instrSettings.AttFactor_Items = strsplit(app.instrInfo.Attenuation_Values{ind0}, ',');
            instrSettings.AttFactor       = instrSettings.AttFactor_Items{1};
            instrSettings.Detector_Items  = strsplit(app.instrInfo.Detector_Items{ind0}, ',');

            switch app.instrInfo.connectFlag{ind0}
                case 1
                    instrSettings.Resolution_Items = strsplit(app.instrInfo.Resolution_Values{ind0}, ',');

                    rbwValues = [];
                    for ii = 1:numel(instrSettings.Resolution_Items)
                        rbwValues = [rbwValues, str2double(extractBefore(instrSettings.Resolution_Items{ii}, ' kHz')).*1000];
                    end

                    instrSettings.DataPoints_Limits = app.instrInfo.DataPoints_Limits{ind0};

                    DataPoints = round(span/app.taskList{ind1}.Band(ind2).StepWidth + 1);
                    if     DataPoints < instrSettings.DataPoints_Limits(1); instrSettings.DataPoints = instrSettings.DataPoints_Limits(1);
                    elseif DataPoints > instrSettings.DataPoints_Limits(2); instrSettings.DataPoints = fix(instrSettings.DataPoints_Limits(2));
                    else;                                                   instrSettings.DataPoints = DataPoints;
                    end
                    instrSettings.StepWidth = span/(instrSettings.DataPoints - 1);

                    rbwIndex  = find(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution) == min(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution)));
                    instrSettings.Resolution = instrSettings.Resolution_Items{rbwIndex};

                    instrSettings.Selectivity     = '';

                    instrSettings.SensitivityMode = '0';
                    instrSettings.Preamp          = 'On';

                    instrSettings.LevelUnit       = app.taskList{ind1}.Band(ind2).LevelUnit;
                    if contains(app.task_scpiList.Value, 'N9344C')
                        instrSettings.LevelUnit   = 'dBm';
                    end

                    switch app.taskList{ind1}.Band(ind2).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode         = 'Manual';
                            instrSettings.SensitivityMode = '1';
                        case 'Low Distortion'
                            instrSettings.Preamp          = 'Off';
                    end

                case 2                                                                                              % EB500
                    instrSettings.StepWidth_Items   = strsplit(app.instrInfo.StepWidth_Values{ind0}, ',');
                    instrSettings.Selectivity_Items = {'Normal', 'Narrow', 'Sharp'};

                    stepValues = [];
                    for ii = 1:length(instrSettings.StepWidth_Items)
                        stepValues = [stepValues, str2double(extractBefore(instrSettings.StepWidth_Items{ii}, ' kHz')).*1000];
                    end

                    stepIndex  = find(abs(stepValues - app.taskList{ind1}.Band(ind2).StepWidth) == min(abs(stepValues - app.taskList{ind1}.Band(ind2).StepWidth)));
                    instrSettings.StepWidth  = instrSettings.StepWidth_Items{stepIndex};

                    instrSettings.DataPoints = span/(str2double(extractBefore(instrSettings.StepWidth, ' kHz'))*1000) + 1;

                    rbwValues = [];
                    rbwItems  = {};
                    for ii = 1:3
                        rbwValues = [rbwValues, app.EB500Map{instrSettings.StepWidth, ii}];
                        rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
                    end
                    instrSettings.Resolution_Items = rbwItems;

                    rbwIndex = find(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution) == min(abs(rbwValues - app.taskList{ind1}.Band(ind2).Resolution)));
                    instrSettings.Resolution  = instrSettings.Resolution_Items{rbwIndex};
                    instrSettings.Selectivity = app.task_Selectivity.Items{rbwIndex};

                    instrSettings.SensitivityMode = 'NORM';
                    instrSettings.Preamp          = 'Off';
                    instrSettings.LevelUnit       = 'dBμV';

                    switch app.taskList{ind1}.Band(ind2).RFMode
                        case 'High Sensitivity'
                            instrSettings.AttMode = 'Manual';
                        case 'Low Distortion'
                            instrSettings.SensitivityMode = 'LOWD';
                    end

                    switch app.taskList{ind1}.Band(ind2).TraceMode
                        case 'ClearWrite'
                            instrSettings.IntegrationTime = 0;
                        otherwise
                            instrSettings.IntegrationTime = 10 * app.taskList{ind1}.Band(ind2).IntegrationFactor;
                    end
            end

        end


        function task_ParametersEditables_SaveValues(app, ind1, ind2)

            instrSettings = task_ParametersEditables_GetValues(app, ind1, ind2);

            app.taskList{ind1}.Band(ind2).instrStepWidth_Items   = instrSettings.StepWidth_Items;
            app.taskList{ind1}.Band(ind2).instrStepWidth         = instrSettings.StepWidth;

            app.taskList{ind1}.Band(ind2).instrDataPoints_Limits = instrSettings.DataPoints_Limits;
            app.taskList{ind1}.Band(ind2).instrDataPoints        = instrSettings.DataPoints;

            app.taskList{ind1}.Band(ind2).instrResolution_Items  = instrSettings.Resolution_Items;
            app.taskList{ind1}.Band(ind2).instrResolution        = instrSettings.Resolution;
            
            app.taskList{ind1}.Band(ind2).instrSelectivity       = instrSettings.Selectivity;
            app.taskList{ind1}.Band(ind2).instrSensitivityMode   = instrSettings.SensitivityMode;
            
            app.taskList{ind1}.Band(ind2).instrPreamp            = instrSettings.Preamp;
            app.taskList{ind1}.Band(ind2).instrAttMode           = instrSettings.AttMode;
            app.taskList{ind1}.Band(ind2).instrAttFactor_Items   = instrSettings.AttFactor_Items;
            app.taskList{ind1}.Band(ind2).instrAttFactor         = instrSettings.AttFactor;

            app.taskList{ind1}.Band(ind2).instrDetector_Items    = instrSettings.Detector_Items;
            indDetector = find(strcmp(app.taskList{ind1}.Band(ind2).instrDetector_Items, app.taskList{ind1}.Band(ind2).Detector), 1);
            if isempty(indDetector)
                indDetector = 1;
            end
            app.taskList{ind1}.Band(ind2).instrDetector        = instrSettings.Detector_Items{indDetector};
            app.taskList{ind1}.Band(ind2).instrLevelUnit       = instrSettings.LevelUnit;
            app.taskList{ind1}.Band(ind2).instrIntegrationTime = instrSettings.IntegrationTime;

            app.taskList{ind1}.Band(ind2).EditedFlag = 0;
            app.task_imgRefresh.Visible              = 0;

        end


        function task_ParametersEditables_ShowValues(app, ind1, ind2)

            if ~app.taskList{ind1}.Band(ind2).EditedFlag
                task_ParametersEditables_SaveValues(app, ind1, ind2)
            else
                if ~app.taskFlag
                    app.task_imgRefresh.Visible = 1;
                end
            end

            idx = find(cellfun(@(x) contains(app.task_scpiList.Value, x), app.instrInfo.Tag), 1);
            switch app.instrInfo.connectFlag{idx}
                case 1
                    if fix(diff(app.instrInfo.DataPoints_Limits{idx}))
                        set(app.task_StepWidth1,  'Editable', 1,              ...
                                                  'BackgroundColor', [1,1,1], ...
                                                  'Value',  app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                        set(app.task_DataPoints1, 'Limits', app.taskList{ind1}.Band(ind2).instrDataPoints_Limits, ...
                                                  'Value',  app.taskList{ind1}.Band(ind2).instrDataPoints)
                    else
                        set(app.task_StepWidth1,  'Editable', 0,                    ...
                                                  'BackgroundColor', [.94,.94,.94], ...
                                                  'Value',  app.taskList{ind1}.Band(ind2).instrStepWidth / 1000);
                        set(app.task_DataPoints1, 'Limits', app.taskList{ind1}.Band(ind2).instrDataPoints_Limits, ...
                                                  'Value',  app.instrInfo.DataPoints_Limits{idx}(1))
                    end

                case 2
                    set(app.task_StepWidth2, 'Items', app.taskList{ind1}.Band(ind2).instrStepWidth_Items, ...
                                             'Value', app.taskList{ind1}.Band(ind2).instrStepWidth)
                    app.task_DataPoints2.Value     = app.taskList{ind1}.Band(ind2).instrDataPoints;
                    app.task_Selectivity.Value     = app.taskList{ind1}.Band(ind2).instrSelectivity;
                    app.task_IntegrationTime.Value = app.taskList{ind1}.Band(ind2).instrIntegrationTime;
            end

            set(app.task_Resolution2, 'Items', app.taskList{ind1}.Band(ind2).instrResolution_Items, ...
                                      'Value', app.taskList{ind1}.Band(ind2).instrResolution)

            app.task_Preamp.Value     = app.taskList{ind1}.Band(ind2).instrPreamp;
            app.task_attMode.Value    = app.taskList{ind1}.Band(ind2).instrAttMode;
            app.task_LevelUnit2.Value = app.taskList{ind1}.Band(ind2).instrLevelUnit;

            set(app.task_attFactor, 'Items', app.taskList{ind1}.Band(ind2).instrAttFactor_Items, ...
                                    'Value', app.taskList{ind1}.Band(ind2).instrAttFactor)

            set(app.task_Detector2, 'Items', app.taskList{ind1}.Band(ind2).instrDetector_Items, ...
                                    'Value', app.taskList{ind1}.Band(ind2).instrDetector)

            if strcmp(app.task_attMode.Value, 'Auto')
                app.task_attFactorLabel.Visible = 0;
                app.task_attFactor.Visible      = 0;
            else
                app.task_attFactorLabel.Visible = 1;
                app.task_attFactor.Visible      = 1;
            end

        end


        function [ind1, ind2] = task_WarnVisibility(app)

            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
            ind2 = app.task_Tree.SelectedNodes.NodeData;

            app.taskList{ind1}.Band(ind2).EditedFlag = 1;
            app.task_imgRefresh.Visible = 1;

            app.specInfo = [];

        end


        function Parameters = task_ParametersField(app, idx)

            Parameters = jsondecode(app.scpiList.Parameters{idx});

            if ~isfield(Parameters, 'IP')
                Parameters.IP = '';
            end

            if ~isfield(Parameters, 'Port')
                Parameters.Port = '';
            end

        end


        function task_startup(app, Type)
            
            if ~isempty(app.Instrument)
                delete(app.Instrument)
                clear app.Instrument
            end
            
            cla(app.axes1)
            cla(app.axes2)
            
            ind1 = str2double(extractBetween(app.task_scpiList.Value, 'ID', ':'));        % app.scpiList(ind1, :)
            ind3 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);             % app.taskList{ind3}
            ind4 = app.task_Tree.SelectedNodes.NodeData;                                  % app.taskList{ind3}.Band(ind4)

            % Garantir que o Anritsu não será utilizado numa tarefa com
            % mais de uma faixa...
            if contains(app.task_scpiList.Value, 'MS2720T') && (numel(app.taskList{ind3}.Band) > 1)
                error(['O analisador de espectro fabricado pela Anritsu, modelo MS2720T, somente pode ser usado em tarefas ' ...
                       'que contemplem a monitoração de  uma única faixa de frequência, tendo em vista que a sua resposta '  ...
                       'à configuração dos parâmetros demora cerca de noventa segundos.'])
            end
            
            Parameters = task_ParametersField(app, ind1);

            if Type == "InitialConnection"
                app.specInfo     = [];
                app.instrTimeout = Parameters.Timeout;
            end
            
            instrSelected  = struct('Name',        char(extractBetween(app.task_scpiList.Value, ': ', ' -')), ...
                                    'Type',        app.scpiList.Type{ind1},     ...
                                    'IP',          Parameters.IP,               ...
                                    'Port',        str2double(Parameters.Port), ...
                                    'Timeout',     app.instrTimeout,            ...
                                    'ConnectType', 'Task',                      ...
                                    'ResetCmd',    app.Task_ResetCommand.Value, ...
                                    'SyncType',    app.Task_SyncRef.Value);

            app.Instrument = connect_scpi(instrSelected, app.instrInfo, app.taskList{ind3}.Band);

            if strcmp(app.task_Type.Value, 'Monitoração espectral - Rompimento de máscara espectral')
                maskInfo = mask_FileRead(app, splitlines(fileread(app.task_maskFile.Tooltip{1})));

                for ii = 1:height(app.specInfo)
                    app.specInfo.maskArray{ii} = mask_Array(app, ii, maskInfo);
                end
            end

            ind2 = find(cellfun(@(x) contains(app.Instrument.UserData.IDN, x), app.instrInfo.Tag), 1);

            if app.instrInfo.connectFlag{ind2} == 2
                [app.udpPortArray, udpIndex] = connect_udpSockets(app.udpPortArray, 24001, app.instrTimeout);            
                if ~isempty(udpIndex)
                    app.udpPort = app.udpPortArray{udpIndex};
                    flush(app.udpPort)
                end
            end
            
            app.traceInfo.Index = [ind1, ind2, ind3, ind4];
            
            Plot_TraceInfo(app)
            
            if ~isempty(app.specInfo) && (app.taskFlag == 1 || numel(app.taskList{ind3}.Band) == 1)
                fprintf(app.Instrument, app.specInfo.scpiGeneral{ind4});
                
                if ~isempty(app.instrInfo.scpiAttenuation{ind2})
                    fprintf(app.Instrument, replace(app.instrInfo.scpiAttenuation{ind2}, '%AttenuationValue%', num2str(app.specInfo.AttenuationValue(ind4))));
                end
            end

        end


        function WaterfallPlotTask(app)

            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Interpreter', 'html');
            d.Message = '<font style="font-size:12;">Em andamento...</font>';

            try
                task_startup(app, 'InitialConnection');
                app.specInfo = struct2table(app.Instrument.UserData.SpecInfo, 'AsArray', true);

%               ind1 = app.traceInfo.Index(1);
                ind2 = app.traceInfo.Index(2);
                ind3 = app.traceInfo.Index(3);
                ind4 = app.traceInfo.Index(4);

                flushinput(app.Instrument)
                lastwarn('')

                % EB500 training...
                if app.instrInfo.connectFlag{ind2} == 2
                    while true
                        RegularTask_EB500_1(app, d)
                        break
                    end
                end
            
            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
                task_Button1Pushed(app)

                return
            end

            delete(d)
            Plot_CloseFigure(app, [], struct('EventName', 'Open'))
            
            while app.taskFlag
                try
                    if app.axesFlag
                        ind4 = app.task_Tree.SelectedNodes.NodeData;
                        Plot_axesFlagValueChanged(app)                        
                    end

                    switch app.instrInfo.connectFlag{ind2}
                        case 1
                            fprintf(app.Instrument, app.instrInfo.scpiTraceData{ind2});
                            newArray = binblockread(app.Instrument, 'single');

                            if app.Instrument.BytesAvailable
                                flushinput(app.Instrument)
                            end

                            if isempty(newArray) || (numel(newArray) ~= app.specInfo.DataPoints(ind4)) || (sum(newArray) == app.specInfo.SyncRef(ind4))
                                continue
                            else
                                app.specInfo.SyncRef(ind4) = sum(newArray);
                            end

                        case 2
                            newArray = RegularTask_EB500_2(app, ind4);
                    end

                    if ~isempty(app.axes1.Children); Plot_Update(app, newArray')
                    else;                            Plot_Creation(app, newArray', ind3, ind4)
                    end
                    drawnow

                    % Recording...
                    if app.recFlag
                        if app.specMatrix.RecordSamples == app.specMatrix.HistoryDepth
                            WaterfallPlotTask_REC(app)
                        end
                    end

                catch ME
                    Misc_uialertCreation(app, 'error', getReport(ME))
                    task_Button1Pushed(app)
                end
            end

            if app.instrInfo.connectFlag{ind2} == 2
                try
                    fprintf(app.Instrument, 'ABORT');
                catch 
                end
            end

            delete(app.Instrument)
            clear app.Instrument

            delete(app.gpsInstrument)
            clear app.gpsInstrument

        end


        function WaterfallPlotTask_REC(app)

            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Interpreter', 'html'); 
            d.Message = '<font style="font-size:12;">Em andamento...</font>';
                
            if ~app.specMatrix.gps.Status
                pause(.1)
                gps = WaterfallPlotTask_gps(app);

                if gps.Status
                    app.specMatrix.gps.Status    = gps.Status;
                    app.specMatrix.gps.Latitude  = gps.Latitude;
                    app.specMatrix.gps.Longitude = gps.Longitude;
                    app.specMatrix.gps.Count     = 1;
                    app.specMatrix.gps.Matrix    = [gps.Latitude, gps.Longitude];
                end
            end

            Task_FileWriter_MAT73(app.specMatrix, app.menu_userPath.Value)

            app.specMatrix.RecordSamples = 0;
            drawnow

            delete(d)

        end


        function gps = WaterfallPlotTask_gps(app)

            try
                if strcmp(app.task_scpiList.Value, app.task_gpsList.Value)
                    gps = Task_gpsDecode(app.Instrument);

                elseif strcmp(app.task_gpsList.Value, 'ID 0: Manual')
                    gps = struct('Status', -1, 'Latitude', app.task_manualLatitude.Value, 'Longitude', app.task_manualLongitude.Value);

                else
                    RegularTask_gps_Aux1(app)
                    gps = app.gpsInstrument.UserData;
                    RegularTask_gps_Aux2(app)
                end
            catch
            end

        end
        
        
        function RegularTask(app, Duration_sec)

            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Interpreter', 'html');
            d.Message = '<font style="font-size:12;">Em andamento...</font>';
            
            % VALIDAÇÕES DIVERSAS
            if isempty(app.task_Antenna.Value)
                Misc_uialertCreation(app, 'error', 'Pendente indicação da antena...')
                task_Button2Pushed(app)
                return

            elseif strcmp(app.task_Type.Value, 'Monitoração espectral - Rompimento de máscara espectral') && isempty(app.task_maskFile.Tooltip{1})
                Misc_uialertCreation(app, 'error', 'Pendente indicação do arquivo de máscara espectral...')
                task_Button2Pushed(app)
                return
            end
            

            try
                % SPECTRUM ANALYZER/RECEIVER CONNECTION
                task_startup(app, 'InitialConnection');
                app.specInfo = struct2table(app.Instrument.UserData.SpecInfo, 'AsArray', true);
                
                baseName = sprintf('appColeta_%s', datestr(now, 'yymmdd_THHMMSS'));
                fileLog  = fopen(fullfile(app.menu_userPath.Value, [baseName '_LogFile.json']), 'wt');
                fprintf(fileLog, sprintf('[\n'));                
                app.task_LogFile.URL = ['file:///' fullfile(app.menu_userPath.Value, [baseName '_LogFile.json'])];
           
                Parameters = task_ParametersField(app, app.traceInfo.Index(1));

                Write_logFile(app, 'Body', fileLog, 'Equipament',  struct('Name',         app.Instrument.UserData.IDN,               ...
                                                                          'ConnectType',  app.scpiList.Type{app.traceInfo.Index(1)}, ...
                                                                          'IP',           Parameters.IP,               ...
                                                                          'Port',         str2double(Parameters.Port), ...
                                                                          'Timeout',      app.instrTimeout,            ...
                                                                          'ResetCommand', app.Task_ResetCommand.Value, ...
                                                                          'SyncMode',     app.Task_SyncRef.Value))

%               ind1 = app.traceInfo.Index(1);
                ind2 = app.traceInfo.Index(2);
                ind3 = app.traceInfo.Index(3);
%               ind4 = app.traceInfo.Index(4);


                % REVISIT TIME ISSUE (mainRevisitTime and RevisitFactor)
                app.timeInfo = task_timeIssue(app.task_Type.Value, app.task_gpsRevisitTime.Value, app.taskList{ind3}, Duration_sec);
                Write_logFile(app, 'Body', fileLog, 'TimeIssue', struct('DesiredObservationTime', Duration_sec,                          ...
                                                                        'DesiredRevisitTime',     [app.taskList{ind3}.Band.RevisitTime], ...
                                                                        'GlobalRevisitTime',      app.timeInfo.RevisitTime,              ...
                                                                        'RevisitFactors',         app.timeInfo.RevisitFactor(2:end),     ...
                                                                        'EstimatedLoops',         max(app.timeInfo.EstimatedLoops)))


                % TIMEOUT RISK
                SampleTime_Max = ceil(max(app.specInfo.SampleTimeValue .* app.specInfo.AverageCount));
                if app.instrTimeout < 2*SampleTime_Max
                    app.instrTimeout = 2*SampleTime_Max;
                    task_startup(app, 'ErrorHandle');
                end


                % GPS Data
                RegularTask_gps(app)
                Write_logFile(app, 'Body', fileLog, 'gpsInfo', struct('Type',      app.gpsInfo.Type,     ...
                                                                      'Status',    app.gpsInfo.Status,   ...
                                                                      'Latitude',  app.gpsInfo.Latitude, ...
                                                                      'Longitude', app.gpsInfo.Longitude))


                % appColeta training (EB500 only)
                if contains(app.Instrument.UserData.IDN, 'EB500')
                    while true
                        RegularTask_EB500_1(app, d)
                        break
                    end
                end
                
            catch ME
                if exist('fileLog', 'var')
                    RegularTask_ErrorHandle1(app, fileLog, ME)
                end

                Misc_uialertCreation(app, 'error', getReport(ME))
                task_Button2Pushed(app)
                return
            end

            delete(d)
            Plot_CloseFigure(app, [], struct('EventName', 'Close'))

            RegularTask_FileCreation(app, fileLog, baseName)
            RegularTask_spec(app, fileLog, Duration_sec)
            RegularTask_FilePostProcessing(app)

            if app.instrInfo.connectFlag{ind2} == 2
                try
                    fprintf(app.Instrument, 'ABORT');
                catch 
                end
            end

            delete(app.Instrument)
            clear app.Instrument

            delete(app.gpsInstrument)
            clear app.gpsInstrument            

            app.taskFlag = 0;
            app.axesFlag = 0;
            app.recFlag  = 0;            
            
            task_Layout(app)

        end


        function RegularTask_FilePostProcessing(app)

            for ii = 1:height(app.specInfo)
                for jj = 1:app.specInfo.FileCount(ii)
                    filename = sprintf('~%s_%.0f.bin', app.specInfo.Filename{ii}, jj);

                    if jj ~= app.specInfo.FileCount(ii)
                        system(sprintf('rename "%s" "%s"', fullfile(app.menu_userPath.Value, filename), replace(filename, '~', '')));

                    else
                        if app.specInfo.WritedSamples{ii}.Data.Value < app.specInfo.AlocatedSamples{ii}.Data.Value
                            try
                                fileID_temp = fopen(fullfile(app.menu_userPath.Value, filename), 'r');
                                tempData    = fread(fileID_temp, [1, inf], 'uint8=>uint8');
                                fclose(fileID_temp);
    
                                BitsPerPoint  = double(tempData(16));
                                WritedSamples = double(typecast(tempData(21:24), 'uint32'));
                                DataPoints    = double(typecast(tempData(37:38), 'uint16'));
    
                                Offset2_old   = double(typecast(tempData(73:76), 'uint32'));
                                Offset3_old   = double(typecast(tempData(77:80), 'uint32'));
    
                                Offset2 = 80 + 20*WritedSamples;
                                Offset3 = Offset2 + (BitsPerPoint/8) * DataPoints * WritedSamples;
    
                                tempData(17:20) = typecast(uint32(WritedSamples), 'uint8');
                                tempData(73:76) = typecast(uint32(Offset2), 'uint8');
                                tempData(77:80) = typecast(uint32(Offset3), 'uint8');
    
                                trimIdx1 = Offset2_old + (BitsPerPoint/8) * DataPoints * WritedSamples + 1;
                                trimIdx2 = Offset2 + 1;
                                
                                tempData(trimIdx1:Offset3_old) = [];
                                tempData(trimIdx2:Offset2_old) = [];
    
                                fileID_new = fopen(fullfile(app.menu_userPath.Value, replace(filename, '~', '')), 'w');
                                fwrite(fileID_new, tempData);
                                fclose(fileID_new);

                                app.specInfo.AlocatedSamples{ii} = '';
                                app.specInfo.WritedSamples{ii}   = '';
                                app.specInfo.memmapData{ii}      = '';
    
                                system(sprintf('del /f "%s"', fullfile(app.menu_userPath.Value, filename)));

                            catch ME
                                Misc_uialertCreation(app, 'error', getReport(ME))
                            end

                        else
                            system(sprintf('rename "%s" "%s"', fullfile(app.menu_userPath.Value, filename), replace(filename, '~', '')));
                        end
                    end
                end
            end

        end


        function RegularTask_FileCreation(app, fileLog, baseName)

            ind3 = app.traceInfo.Index(3);
            
            for ii = 1:numel(app.taskList{ind3}.Band)
                app.specInfo.Filename{ii}  = sprintf('%s_ThreadID%d', baseName, app.taskList{ind3}.Band(ii).ThreadID);
                app.specInfo.FileCount(ii) = 1;

                RegularTask_FileCreationAux(app, fileLog, ii, 'InitialConnection')
            end

        end


        function RegularTask_FileCreationAux(app, fileLog, ii, Type)

            ind2 = app.traceInfo.Index(2);
            ind3 = app.traceInfo.Index(3);

            if     strcmp(app.task_BitsPerPoint.Value,  '8 bits'); dataFormat = 'uint8';
            elseif strcmp(app.task_BitsPerPoint.Value, '16 bits'); dataFormat = 'int16';
            elseif strcmp(app.task_BitsPerPoint.Value, '32 bits'); dataFormat = 'single';
            end

            fileName = fullfile(app.menu_userPath.Value, sprintf('~%s_%.0f.bin', app.specInfo.Filename{ii}, app.specInfo.FileCount(ii)));
            fileID   = fopen(fileName, 'w');

            switch Type
                case 'InitialConnection'
                    Write_logFile(app, 'Body', fileLog, sprintf('ThreadID%d', app.taskList{ind3}.Band(ii).ThreadID), ...
                                                        struct('File',           replace(fileName, {'\', '~'}, {'/', ''}),        ...
                                                               'StartUp',        app.instrInfo.StartUp{ind2},        ...
                                                               'MainSet',        app.specInfo.scpiGeneral{ii},       ...
                                                               'MetaDataQuery',  app.instrInfo.scpiQuery{ind2},      ...
                                                               'TraceDataQuery', app.instrInfo.scpiTraceData{ind2},  ...
                                                               'MetaDataValues', jsonencode(app.specInfo(ii,2:19))))
                otherwise
                    Write_logFile(app, 'Body', fileLog, sprintf('ThreadID%d', app.taskList{ind3}.Band(ii).ThreadID), ...
                                                        struct('File',           replace(fileName, {'\', '~'}, {'/', ''})))
            end

            % MaxFileSize issue
            AlocatedSamples = ceil(app.General.FileSize ./ (app.taskList{ind3}.BitsPerSample/8 * app.taskList{ind3}.Band(ii).instrDataPoints));
            if AlocatedSamples > app.timeInfo.EstimatedLoops(ii+1)
                AlocatedSamples = app.timeInfo.EstimatedLoops(ii+1);
            end
            
            Write_formatHeader(app, fileID, AlocatedSamples)
            Write_metaHeader(app, fileID, app.specInfo(ii,:))
            Write_gpsHeader(app, fileID)

            [Offset1, Offset2] = Write_Body(app, fileID,                 ...
                                            AlocatedSamples,             ...
                                            app.specInfo.DataPoints(ii), ...
                                            app.taskList{ind3}.Name,     ...
                                            app.taskList{ind3}.Band(ii), ...
                                            app.specInfo.Node{ii});
            fclose(fileID);

            % Memory Map File
            app.specInfo.AlocatedSamples{ii} = memmapfile(fileName, 'Writable', false, ...
                                                                    'Offset', 16,      ...
                                                                    'Format', {'uint32', 1, 'Value'}, ...
                                                                    'Repeat', 1);

            app.specInfo.WritedSamples{ii}   = memmapfile(fileName, 'Writable', true, ...
                                                                    'Offset', 20,     ...
                                                                    'Format', {'uint32', 1, 'Value'}, ...
                                                                    'Repeat', 1);

            app.specInfo.memmapData{ii}{1}   = memmapfile(fileName, 'Writable', true,  ...
                                                                    'Offset', Offset1, ...
                                                                    'Format', {'int8',   [1  6], 'localTimeStamp';    ...
                                                                               'int16',  [1  1], 'localTimeStamp_ms'; ...
                                                                               'int16',  [1  1], 'RefLevel';          ...
                                                                               'uint8',  [1  1], 'attFactor';         ...
                                                                               'uint8',  [1  1], 'gpsStatus';         ...
                                                                               'single', [1  1], 'Latitude';          ...
                                                                               'single', [1  1], 'Longitude'},        ...
                                                                    'Repeat', AlocatedSamples);

            app.specInfo.memmapData{ii}{2}   = memmapfile(fileName, 'Writable', true,  ...
                                                                    'Offset', Offset2, ...
                                                                    'Format', {dataFormat, [app.specInfo.DataPoints(ii) AlocatedSamples], 'Array'}, ...
                                                                    'Repeat', 1);

        end


        function RegularTask_ErrorHandle1(app, fileLog, ME)

            Write_logFile(app, 'Trailer', fileLog, replace(ME.identifier, {'"', ',', newline}, {'', '', ''}), ...
                                                   replace(ME.message,    {'"', ',', newline}, {'', '', ''}))
            Misc_uialertCreation(app, 'error', getReport(ME))

        end
        

        function RegularTask_ErrorHandle2(app, fileLog, ME)

            Write_logFile(app, 'Body', fileLog, 'Equipament', replace(ME.message, {'"', ',', newline}, {'', '', ''}))

            while true
                lastwarn('')
                if ~app.taskFlag; return
                end

                try
                    task_startup(app, 'ErrorHandle');
                    break
                catch
                end
            end

        end


        function RegularTask_gps(app)

            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Interpreter', 'html');
            d.Message = '<font style="font-size:12;">Em andamento...</font>';

            lastwarn('')
            try
                if strcmp(app.task_gpsList.Value, 'ID 0: Manual')
                    app.gpsInfo.Status    = -1;
                    app.gpsInfo.Latitude  = app.task_manualLatitude.Value;
                    app.gpsInfo.Longitude = app.task_manualLongitude.Value;
                    app.gpsInfo.TimeStamp = [];
                    app.gpsInfo.Type      =  0;

                elseif strcmp(app.task_scpiList.Value, app.task_gpsList.Value)
                    set(d, 'Cancelable', 'on', 'CancelText', 'Cancelar', 'Title', 'appColeta', 'Message', '<font style="font-size:12;">Tentando extrair informação válida do GPS embarcado no instrumento...</font>')

                    while true
                        app.gpsInfo = Task_gpsDecode(app.Instrument);
                        app.gpsInfo.Type = 1;
                        
                        if app.gpsInfo.Status || d.CancelRequested
                            break
                        else
                            pause(3)
                        end
                    end

                else
                    RegularTask_gps_Aux1(app)

                    app.gpsInfo       = app.gpsInstrument.UserData;
                    app.gpsInfo.Type  = 2;
                end

            catch ME
                error(struct('message', ME.message, 'identifier', ME.identifier))
            end

            delete(d)

        end


        function RegularTask_gps_Aux1(app)

            idx = str2double(char(extractBetween(app.task_gpsList.Value, 'ID', ':')));
            Parameters = task_ParametersField(app, idx);

            switch app.scpiList.Type{idx}
                case 'Serial'
                    instrSelected = struct('Type', app.scpiList.Type{idx}, 'Port', Parameters.Port, 'BaudRate', 9600);
                case 'TCPIP Socket'
                    instrSelected = struct('Type', app.scpiList.Type{idx}, 'Port', Parameters.Port, 'IP', Parameters.IP);
            end
            
            app.gpsInstrument = connect_gps(instrSelected);

        end


        function RegularTask_gps_Aux2(app)

            delete(app.gpsInstrument)
            clear app.gpsInstrument
            
        end


        function RegularTask_spec(app, fileLog, Duration_sec)
            
%           ind1 = app.traceInfo.Index(1);
            ind2 = app.traceInfo.Index(2);
            ind3 = app.traceInfo.Index(3);
            ind4 = app.traceInfo.Index(4);
            Band = numel(app.taskList{ind3}.Band);

            flushinput(app.Instrument)
            lastwarn('')

            TimeFinish     = datetime('now') + seconds(Duration_sec);
            EstimatedLoops = max(app.timeInfo.EstimatedLoops);
                        
            app.task_LogFile.Text = sprintf('Loop %d de %d (%.0f%%)', 0, EstimatedLoops, 0);
            Write_logFile(app, 'Body', fileLog, 'WritedSamples', sprintf('%d of %d', 0, EstimatedLoops))
            
            statusFlag = 1;
            if isempty(app.instrInfo.scpiStatus{ind2})
                statusFlag = 0;
            end

            ii = 0;
            while app.taskFlag && (datetime('now') < TimeFinish)
                if statusFlag
                    while true
                        errorMsg = deblank(query(app.Instrument, app.instrInfo.scpiStatus{ind2}));
                        
                        if isempty(errorMsg) || contains(errorMsg, '0,"No error"')
                            break
                        else
                            Write_logFile(app, 'Body', fileLog, 'Equipament', errorMsg)
                        end
                    end
                end

                tic
                for jj = 0:Band
                    if mod(ii, app.timeInfo.RevisitFactor(jj+1))
                        continue
                    end

                    if jj == 0
                        try
                            switch app.gpsInfo.Type
                                case 0; gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1);
                                case 1; gpsData = Task_gpsDecode(app.Instrument);
                                case 2; gpsData = Task_gpsReader(app.gpsInstrument, 1);
                            end
                        catch ME
                            gpsData = struct('Status', 0, 'Latitude', -1, 'Longitude', -1);
                            Write_logFile(app, 'Body', fileLog, 'gpsInfo', replace(ME.message, {'"', ',', newline}, {'', '', ''}))
                        end
                    else
                        while true
                            try
                                if Band > 1
                                    fprintf(app.Instrument, app.specInfo.scpiGeneral{jj});
                                    pause(0.001)
                                    
                                    if ~isempty(app.instrInfo.scpiAttenuation{ind2})
                                        fprintf(app.Instrument, replace(app.instrInfo.scpiAttenuation{ind2}, {'%AttenuationValue%'}, {num2str(app.specInfo.AttenuationValue(jj))}));
                                    end
                                end
                                
                                switch app.instrInfo.connectFlag{ind2}
                                    case 1
                                        fprintf(app.Instrument, app.instrInfo.scpiTraceData{ind2});
                                        newArray = binblockread(app.Instrument, 'single');                            

                                        if app.Instrument.BytesAvailable
                                            flushinput(app.Instrument)
                                        end

                                        if isempty(newArray) || (numel(newArray) ~= app.specInfo.DataPoints(jj)) || (sum(newArray) == app.specInfo.SyncRef(jj))
                                            continue
                                        else
                                            app.specInfo.SyncRef(jj) = sum(newArray);
                                        end
            
                                    case 2
                                        newArray = RegularTask_EB500_2(app, jj);
                                end
                                
                                if app.taskFlag && (jj == ind4)
                                    if app.axesFlag
                                        ind4 = app.task_Tree.SelectedNodes.NodeData;
                                        Plot_axesFlagValueChanged(app)
                                        
                                    else
                                        if ~isempty(app.axes1.Children)
                                            Plot_Update(app, newArray')
                                        else
                                            Plot_Creation(app, newArray', ind3, ind4)
                                        end
                                    end
                                    drawnow
                                end
                                
                                attFactor = str2double(query(app.Instrument, app.instrInfo.scpiQuery_Attenuation{ind2}));
                                Offset    = max(newArray);

                                if isempty(lastwarn)
                                    if strcmp(app.task_Type.Value, 'Monitoração espectral - Rompimento de máscara espectral')
                                        MaskBroken = any(newArray - app.specInfo.maskArray{jj} > 0, 'all');
                                        
                                        if ~MaskBroken
                                            break
                                        end                                        
                                    end

                                    Write_specData(app, jj, Offset, attFactor, gpsData, newArray, fileLog)
                                    break
                                else
                                    [msg, warnID] = lastwarn;
                                    error(struct('message', msg, 'identifier', warnID))
                                end

                            catch ME
                                RegularTask_ErrorHandle2(app, fileLog, ME)
                            end
                        end
                    end
                end
                ii = ii+1;

                app.task_LogFile.Text = sprintf('Loop %d de %d (%.0f%%)', ii, EstimatedLoops, ceil(100*ii/EstimatedLoops));
                drawnow limitrate
                
                pause(app.timeInfo.RevisitTime - toc)
            end
            Write_logFile(app, 'Trailer', fileLog, 'WritedSamples', sprintf('%d of %d', ii, EstimatedLoops))

        end


        function RegularTask_EB500_1(app, d)

            Localhost_publicIP = app.Instrument.UserData.ClientIP;
            lastwarn('')

            for ii = 1:numel(app.taskList{app.traceInfo.Index(3)}.Band)
                set(d, 'Cancelable', 'on', 'Indeterminate', 'off', 'Value', 0, ...
                       'Title', sprintf('ThreadID %.0f: %.3f - %.3f MHz', app.taskList{app.traceInfo.Index(3)}.Band(ii).ThreadID, ...
                                                                          app.taskList{app.traceInfo.Index(3)}.Band(ii).FreqStart/1e+6, ...
                                                                          app.taskList{app.traceInfo.Index(3)}.Band(ii).FreqStop /1e+6), ...
                       'Message', 'Em andamento a estimativa do número de datagramas que representam um único traço...', ...
                       'CancelRequested', 0, 'CancelText', 'Cancelar')

                fprintf(app.Instrument, app.specInfo.scpiGeneral{ii});
                fprintf(app.Instrument, sprintf('TRACE:UDP:TAG:ON "%s", 24001, PSCAN',                Localhost_publicIP));
                fprintf(app.Instrument, sprintf('TRACE:UDP:FLAG:ON "%s", 24001, "VOLTage:AC", "OPT"', Localhost_publicIP));
                
                pause(1)
                while true
                    if d.CancelRequested
                        error('Cancelado pelo usuário.')
                    end

                    value = app.udpPort.NumDatagramsAvailable/100;
                    if value > 1; value = 1;
                    end
                    set(d, 'Value', value)

                    if app.udpPort.NumDatagramsAvailable > 100
                        break
                    else
                        pause(1)
                    end
                end
                specDatagrama = read(app.udpPort, app.udpPort.NumDatagramsAvailable);

                [msg, warnID] = lastwarn;
                if strcmp(warnID, 'instrument:interface:udpport:ReadWarning')
                    error(struct('message', msg, 'identifier', warnID))
                end

                Terminator = [];
                for jj = 1:numel(specDatagrama)
                    specDatagrama(jj).Data = uint8(specDatagrama(jj).Data);

                    MagicNumber = typecast(specDatagrama(jj).Data( 4:-1: 1), 'uint32');
                    DataSize    = typecast(specDatagrama(jj).Data(16:-1:13), 'uint32');

                    F0_low    = double(typecast(specDatagrama(jj).Data(32:-1:29), 'uint32'));
                    F0_high   = double(typecast(specDatagrama(jj).Data(44:-1:41), 'uint32')).*2^32;
                    StepWidth = typecast(specDatagrama(jj).Data(40:-1:37), 'uint32');

                    F0 = F0_low + F0_high;

                    if (MagicNumber ~= 963072) || (DataSize ~= length(specDatagrama(jj).Data)) || (app.specInfo.FreqStart(ii) ~= F0) || (app.specInfo.StepWidth(ii) ~= StepWidth)
                        continue
                    end

                    Terminator(end+1, 1) = typecast(specDatagrama(jj).Data(end:-1:end-1), 'uint16');
                end

                if isempty(Terminator)
                    app.specInfo.DatagramasPerTrace(ii) = 1;
                else
                    app.specInfo.DatagramasPerTrace(ii) = round(numel(specDatagrama)./numel(find(Terminator(:,1) == 2000)));
                end
                
                fprintf(app.Instrument, sprintf('TRACE:UDP:TAG:OFF "%s", 24001, PSCAN',                Localhost_publicIP));
                fprintf(app.Instrument, sprintf('TRACE:UDP:FLAG:OFF "%s", 24001, "VOLTage:AC", "OPT"', Localhost_publicIP));
                fprintf(app.Instrument, sprintf('TRACE:UDP:DEL "%s", 24001',                           Localhost_publicIP));
                
                flush(app.udpPort)
            end
            set(d, 'Cancelable', 'off', 'Title', '', 'Value', 0, 'Indeterminate', 'on')

        end


        function specData = RegularTask_EB500_2(app, ind)

            Localhost_publicIP = app.Instrument.UserData.ClientIP;
            lastwarn('')

            specData = zeros(app.specInfo.DataPoints(ind), 1);
            N = 0;
            
            fprintf(app.Instrument, sprintf('TRACE:UDP:TAG:ON "%s", 24001, PSCAN',                Localhost_publicIP));
            fprintf(app.Instrument, sprintf('TRACE:UDP:FLAG:ON "%s", 24001, "VOLTage:AC", "OPT"', Localhost_publicIP));

            while true
                NumberOfDatagrams = (2+N) .* app.specInfo.DatagramasPerTrace(ind) - 1;
                specDatagrama     = read(app.udpPort, NumberOfDatagrams);

                [msg, warnID] = lastwarn;
                if strcmp(warnID, 'instrument:interface:udpport:ReadWarning')
                    error(struct('message', msg, 'identifier', warnID))
                end

                udpFlag         = 0;
                Points          = 0;
                DatagramaNumber = 0;

                for ii = 1:numel(specDatagrama)
                    specDatagrama(ii).Data = uint8(specDatagrama(ii).Data);

                    SequenceNumber_low  = double(typecast(specDatagrama(ii).Data(10:-1: 9), 'uint16'));
                    SequenceNumber_high = double(typecast(specDatagrama(ii).Data(12:-1:11), 'uint16')).*2^16;
                    SequenceNumber      = SequenceNumber_low + SequenceNumber_high;

                    if udpFlag & (SequenceNumber ~= DatagramaNumber+1)
                        udpFlag         = 0;
                        Points          = 0;
                        DatagramaNumber = 0;

                        if ii ~= numel(specDatagrama)
                            continue
                        else
                            N = N+1;
                            break
                        end
                    end

                    MagicNumber = typecast(specDatagrama(ii).Data( 4:-1: 1), 'uint32');
                    DataSize    = typecast(specDatagrama(ii).Data(16:-1:13), 'uint32');
                    F0_low      = double(typecast(specDatagrama(ii).Data(32:-1:29), 'uint32'));
                    F0_high     = double(typecast(specDatagrama(ii).Data(44:-1:41), 'uint32')).*2^32;
                    StepWidth   = typecast(specDatagrama(ii).Data(40:-1:37), 'uint32');

                    F0 = F0_low + F0_high;

                    if (MagicNumber ~= 963072) || (DataSize ~= length(specDatagrama(ii).Data)) || (app.specInfo.FreqStart(ind) ~= F0) || (app.specInfo.StepWidth(ind) ~= StepWidth)
                        udpFlag         = 0;
                        Points          = 0;
                        DatagramaNumber = 0;

                        continue
                    end
                    Terminator = typecast(specDatagrama(ii).Data(end:-1:end-1), 'uint16');

                    auxIndex1 = Points + 1;
                    Points    = Points + typecast(specDatagrama(ii).Data(22:-1:21), 'uint16');
                    auxIndex2 = Points;

                    if Terminator == 2000
                        if Points == app.specInfo.DataPoints(ind)+1
                            specData(auxIndex1:auxIndex2-1) = (double(flip(typecast(specDatagrama(ii).Data((end-2):-1:81),'int16')))./10)';

                            N       = 0;
                            udpFlag = 2;
                            break

                        else
                            udpFlag = 0;
                        end
                        Points = 0;

                    else
                        udpFlag = 1;
                        DatagramaNumber = SequenceNumber;
                        specData(auxIndex1:auxIndex2) = (double(flip(typecast(specDatagrama(ii).Data(end:-1:81),'int16')))./10)';
                    end
                end

                if udpFlag == 2
                    break
                end
            end
            
            fprintf(app.Instrument, sprintf('TRACE:UDP:TAG:OFF "%s", 24001, PSCAN',                Localhost_publicIP));
            fprintf(app.Instrument, sprintf('TRACE:UDP:FLAG:OFF "%s", 24001, "VOLTage:AC", "OPT"', Localhost_publicIP));
            fprintf(app.Instrument, sprintf('TRACE:UDP:DEL "%s", 24001',                           Localhost_publicIP));
            
            flush(app.udpPort)

        end


        function MaskInfo = mask_FileRead(app, maskText)
                
            MaskInfo.Table           = struct2table(regexp(strjoin(maskText, '\n'), '(?<FreqStart>\d*),(?<FreqStop>\d*),(?<THR>[-]{0,1}\d*)', 'names'));
            MaskInfo.Table.FreqStart = str2double(MaskInfo.Table.FreqStart) / 1e+3;
            MaskInfo.Table.FreqStop  = str2double(MaskInfo.Table.FreqStop)  / 1e+3;
            MaskInfo.Table.THR       = str2double(MaskInfo.Table.THR);

            MaskInfo.THR       = MaskInfo.Table.THR;
            MaskInfo.FreqStart = MaskInfo.Table.FreqStart(1);
            MaskInfo.FreqStop  = MaskInfo.Table.FreqStop(end);

            MaskInfo.unmasked  = table('Size', [height(MaskInfo.Table)-1, 3],         ...
                                       'VariableTypes', {'double', 'double', 'cell'}, ...
                                       'VariableNames', {'Frequency', 'BW', 'Source'});

            MaskInfo.unmasked.BW(:)        = MaskInfo.Table.FreqStart(2:end)  - MaskInfo.Table.FreqStop(1:end-1);
            MaskInfo.unmasked.Frequency(:) = MaskInfo.Table.FreqStop(1:end-1) + MaskInfo.unmasked.BW/2;
            MaskInfo.unmasked.Source(:)    = {'refMask'};

        end


        function MaskArray = mask_Array(app, idx, MaskInfo)
            
            % Limiar igual a 1000 (inatingível)
            MaskArray = ones(1, app.specInfo.DataPoints(idx)) * 1e+3;

            freq = linspace(app.specInfo.FreqStart(idx) /1e+6,  ...
                            app.specInfo.FreqStop(idx)  / 1e+6, ...
                            app.specInfo.DataPoints(idx));
            
            MaskTable = mask_Table(app, MaskInfo);
            
            % Subtituição do limiar inatingível pelo threshold da faixa
            for ii = 1:height(MaskTable)
                MaskArray(freq >= MaskTable(ii,1) & freq <= MaskTable(ii,2)) = MaskTable(ii,3);
            end             
             
        end


        function MaskTable = mask_Table(app, MaskInfo)
            
            if isempty(MaskInfo.unmasked)
                if isempty(MaskInfo.THR)
                    MaskTable = [];
                else
                    MaskTable = [MaskInfo.FreqStart, MaskInfo.FreqStop, MaskInfo.THR];
                end
                
            else
                FreqStart = [MaskInfo.FreqStart; MaskInfo.unmasked.Frequency + ceil(MaskInfo.unmasked.BW/2)];
                FreqStop  = [MaskInfo.unmasked.Frequency - ceil(MaskInfo.unmasked.BW/2); MaskInfo.FreqStop];
                MaskTable = [FreqStart, FreqStop, MaskInfo.THR];
            end
            
        end


        function Plot_TraceInfo(app)

            if app.tb_minHold.State
                app.traceInfo.Mode(1) = 1;
            else
                app.traceInfo.Mode(1) = 0;
                
                app.line_minHold = [];
                delete(findobj('Tag', 'MinHold'))
            end
            
            if app.tb_meanHold.State
                app.traceInfo.Mode(2) = 2;
            else
                app.traceInfo.Mode(2) = 0;
                
                app.line_Average = [];
                delete(findobj('Tag', 'Average'))
            end
            
            if app.tb_maxHold.State
                app.traceInfo.Mode(3) = 3;
            else
                app.traceInfo.Mode(3) = 0;
                
                app.line_maxHold = [];
                delete(findobj('Tag', 'MaxHold'))
            end

            if app.tb_waterfall.State
                app.traceInfo.plotLayout = 1;
            else
                app.traceInfo.plotLayout = 0;
                
                app.surface_wFall = [];
                delete(findobj('Tag', 'wFall'))
            end

        end

        function Plot_SpecMatrix(app, Node, newArray)

            app.specMatrix = struct('Node',        '', 'ThreadID',     [], 'MetaData',        [], 'Samples',       [], ...
                                    'Data',        {}, 'statsData',    [], 'FileFormat',      '', 'TaskName',      '', ...
                                    'Description', '', 'gps',          [], 'ObservationTime', '', 'Blocks',        [], ...
                                    'Index',       [], 'HistoryDepth', [], 'RecordSamples',   [], 'WritedSamples', [], 'SampleTime', []);

            app.specMatrix(1).Node  = Node;
            app.specMatrix.MetaData = struct('DataType',   [], 'ThreadID',   [], 'FreqStart',  [], 'FreqStop',   [], 'LevelUnit',  [], 'DataPoints', [], ...
                                             'Resolution', [], 'SampleTime', [], 'Threshold',  [], 'TraceMode',  [], 'Detector',   [], 'metaString', []);

            app.specMatrix.MetaData(1).DataType = 1;

            if app.Button_Task.Value
                ind1 = app.traceInfo.Index(1);
                ind2 = app.traceInfo.Index(2);
                ind3 = app.traceInfo.Index(3);
                ind4 = app.traceInfo.Index(4);
                
                app.specMatrix(1).Index = [ind1, ind2, ind3, ind4];
                
                if app.taskFlag == 1
                    Samples = fix(app.General.FileSize/(4*app.specInfo.DataPoints(ind4)));
                    if Samples < app.General.HistoryDepth; Samples = app.General.HistoryDepth;
                    end
                    
                    app.specMatrix.FileFormat  = 'RFlookMAT v.1/1';
                    app.specMatrix.TaskName    = app.taskList{ind3}.Name;
                    app.specMatrix.Description = app.taskList{ind3}.Band(ind4).Description;

                    app.specMatrix.gps = struct('Status', 0, 'Latitude', -1, 'Longitude', -1, 'Count', 0, 'Location', '', 'Matrix', []);                   
                else
                    Samples = app.General.HistoryDepth;
                end
                
                app.specMatrix.ThreadID = app.taskList{ind3}.Band(ind4).ThreadID;

                app.specMatrix.MetaData.ThreadID   = app.specMatrix.ThreadID;
                app.specMatrix.MetaData.FreqStart  = app.specInfo.FreqStart(1);
                app.specMatrix.MetaData.FreqStop   = app.specInfo.FreqStop(1);

                app.specMatrix.MetaData.DataPoints = app.taskList{ind3}.Band(ind4).instrDataPoints;
                app.specMatrix.MetaData.Resolution = app.specInfo.ResolutionValue(1);
                app.specMatrix.MetaData.SampleTime = app.specInfo.SampleTimeValue(1);
                
                switch app.taskList{ind3}.Band(ind4).instrLevelUnit
                    case 'dBm';    app.specMatrix.MetaData.LevelUnit = 1;
                    case 'dBμV';   app.specMatrix.MetaData.LevelUnit = 2;
                    case 'dBμV/m'; app.specMatrix.MetaData.LevelUnit = 3;
                end
                app.specMatrix.MetaData.metaString    = {app.taskList{ind3}.Band(ind4).instrLevelUnit};
                
                app.specMatrix.MetaData.metaString{2} = app.taskList{ind3}.Band(ind4).instrResolution;
                
                switch app.taskList{ind3}.Band(ind4).TraceMode
                    case 'ClearWrite'; app.specMatrix.MetaData.TraceMode = 1;
                    case 'Average';    app.specMatrix.MetaData.TraceMode = 2;
                    case 'MaxHold';    app.specMatrix.MetaData.TraceMode = 3;
                    case 'MinHold';    app.specMatrix.MetaData.TraceMode = 4;
                end
                app.specMatrix.MetaData.metaString{3} = app.taskList{ind3}.Band(ind4).TraceMode;

                switch app.taskList{ind3}.Band(ind4).Detector
                    case 'Sample';        app.specMatrix.MetaData.Detector = 1;
                    case 'Average/RMS';   app.specMatrix.MetaData.Detector = 2;
                    case 'Positive Peak'; app.specMatrix.MetaData.Detector = 3;
                    case 'Negative Peak'; app.specMatrix.MetaData.Detector = 4;
                end
                app.specMatrix.MetaData.metaString{4} = app.taskList{ind3}.Band(ind4).Detector;
                app.specMatrix.MetaData.metaString{5} = app.task_Antenna.Value;

                DataPoints = app.specInfo.DataPoints(ind4);

            elseif app.Button_Logger.Value
                ind = app.traceInfo.Index;
                
                app.specMatrix.Index    = ind;
                app.specMatrix.ThreadID = app.bandList(ind).ThreadID;

                app.specMatrix.MetaData.ThreadID  = app.bandList(ind).ThreadID;
                app.specMatrix.MetaData.FreqStart = app.bandList(ind).FreqStart;
                app.specMatrix.MetaData.FreqStop  = app.bandList(ind).FreqStop;

                switch app.bandList(ind).LevelUnit
                    case 'dBm';    app.specMatrix.MetaData.LevelUnit = 1;
                    case 'dBμV';   app.specMatrix.MetaData.LevelUnit = 2;
                    case 'dBμV/m'; app.specMatrix.MetaData.LevelUnit = 3;
                end
                
                app.specMatrix.MetaData.DataPoints    = app.bandList(ind).DataPoints;
                app.specMatrix.MetaData.Resolution    = app.bandList(ind).Resolution;
                
                app.specMatrix.MetaData.metaString    = {app.bandList(ind).LevelUnit};
                app.specMatrix.MetaData.metaString{2} = sprintf('%.3f kHz', app.bandList(ind).Resolution / 1000);
                app.specMatrix.MetaData.metaString{3} = app.bandList(ind).Operation;
                app.specMatrix.MetaData.metaString{4} = '';
                app.specMatrix.MetaData.metaString{5} = app.task_Antenna.Value;
                
                DataPoints = app.bandList(ind).DataPoints;
                Samples    = app.General.HistoryDepth;
            end
            
            app.specMatrix.Data         = {repmat(datetime([0 0 0 0 0 0], 'Format', 'dd/MM/yyyy HH:mm:ss'), 1, Samples), ...
                                           min(newArray) .* ones(Samples, DataPoints, 'single')};
            app.specMatrix.Data{1}(1)   = datetime('now');
            app.specMatrix.Data{2}(1,:) = newArray;

            app.specMatrix.HistoryDepth  = Samples;
            app.specMatrix.RecordSamples = 1;
            app.specMatrix.WritedSamples = 1;
            app.specMatrix.SampleTime    = 0;

        end


        function Plot_Creation(app, newArray, ind3,  ind4)

            Plot_StartUp(app)
            Plot_Layout1(app)
            Plot_Layout2(app, newArray, ind3, ind4)

            if app.Button_Task.Value
                app.x = round(linspace(app.specInfo.FreqStart(ind4) ./ 1e+6, ...
                                       app.specInfo.FreqStop(ind4)  ./ 1e+6, ...
                                       app.specInfo.DataPoints(ind4)), 3);
            else
                app.x = round(linspace(app.bandList(ind4).FreqStart ./ 1e+6, ...
                                       app.bandList(ind4).FreqStop  ./ 1e+6, ...
                                       app.bandList(ind4).DataPoints), 3);
            end


            % %         Axes 1: main lines
            app.line_ClrWrite = plot(app.axes1, app.x, newArray, 'Tag', 'ClearWrite', 'Color', app.General.Colors(4,:));
            Misc_PlotDataTip(app.line_ClrWrite, app.task_LevelUnit2.Value)

            if app.traceInfo.Mode(1)
                app.line_minHold = plot(app.axes1, app.x, newArray, 'Tag', 'MinHold', 'Color', app.General.Colors(1,:));
                Misc_PlotDataTip(app.line_minHold, app.task_LevelUnit2.Value)
            end

            if app.traceInfo.Mode(2)
                app.line_Average = plot(app.axes1, app.x, newArray, 'Tag', 'Average', 'Color', app.General.Colors(2,:));
                Misc_PlotDataTip(app.line_Average, app.task_LevelUnit2.Value)
            end

            if app.traceInfo.Mode(3)
                app.line_maxHold = plot(app.axes1, app.x, newArray, 'Tag', 'MaxHold', 'Color', app.General.Colors(3,:));
                Misc_PlotDataTip(app.line_maxHold, app.task_LevelUnit2.Value)
            end


            % %         Axes2: Waterfall
            if app.traceInfo.plotLayout; Plot_Creation_wFall(app)
            end

        end


        function Plot_Creation_wFall(app)

            ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);
            if ~ind; ind = app.specMatrix.HistoryDepth;
            end

            indMatrix = ind-app.General.HistoryDepth+1:ind;
            indFind   = find(indMatrix <= 0);
            if ~isempty(indFind); indMatrix = [app.specMatrix.HistoryDepth-numel(indFind)+1:app.specMatrix.HistoryDepth, 1:ind];
            end

            app.surface_wFall = image(app.axes2, app.x, 1:app.General.HistoryDepth, app.specMatrix.Data{2}(indMatrix,:), 'CDataMapping', 'scaled', 'Tag', 'wFall');
            set(app.axes2, 'XLim', app.RestoreView{1}, 'View', [0, 90]);
            drawnow nocallbacks

        end


        function Plot_StartUp(app)

            Plot_CloseFigure(app, [], struct('EventName', 'Open'))
            figure(app.fig)

            app.line_ClrWrite = [];
            app.line_minHold  = [];
            app.line_Average  = [];
            app.line_maxHold  = [];
            app.surface_wFall = [];

        end


        function Plot_Layout1(app)

            switch app.traceInfo.plotLayout
                case 0
                    app.axes1.InnerPosition = [.08 .08 .85 .8];
                    app.axes2.InnerPosition = [0 0 0 0];
                    app.cBar1.Position      = [0 0 0 0];

                    app.axes1.XTickLabelMode = 'auto';

                    xlabel(app.axes1, 'Frequência (MHz)')

                case 1
                    if app.tb_layout.Tag == "1:1"
                        app.axes1.InnerPosition = [.08 .48 .85 .40];
                        app.axes2.InnerPosition = [.08 .08 .85 .38];
                    else
                        app.axes1.InnerPosition = [.08 .72 .85 .16];
                        app.axes2.InnerPosition = [.08 .08 .85 .62];
                    end
                    app.cBar1.Position      = [.9405 .08 .0145 .10];

                    app.axes1.XTickLabel = {};
                    app.axes2.XTickLabelMode = 'auto';

                    xlabel(app.axes1, '')
                    xlabel(app.axes2, 'Frequência (MHz)')
            end

        end


        function Plot_Layout2(app, newArray, ind3, ind4)
            
            if app.Button_Task.Value
                Node       = app.Instrument.UserData.IDN;

                LevelUnit  = app.specInfo.LevelUnit{ind4};
                FreqStart  = app.specInfo.FreqStart(ind4) ./ 1e+6;
                FreqStop   = app.specInfo.FreqStop(ind4)  ./ 1e+6;
                TraceMode  = app.taskList{ind3}.Band(ind4).TraceMode;
                Detector   = app.taskList{ind3}.Band(ind4).instrDetector;
                Resolution = app.taskList{ind3}.Band(ind4).instrResolution;
                DataPoints = app.taskList{ind3}.Band(ind4).instrDataPoints;

            else
                Node       = app.logger_NodeList.Value(1:11);

                LevelUnit  = app.bandList(ind4).LevelUnit;
                FreqStart  = app.bandList(ind4).FreqStart ./ 1e+6;
                FreqStop   = app.bandList(ind4).FreqStop  ./ 1e+6;
                TraceMode  = app.bandList(ind4).Operation;
                Detector   = '';
                Resolution = sprintf('%.3f kHz', app.bandList(ind4).Resolution  ./ 1000);
                DataPoints = app.bandList(ind4).DataPoints;
            end

            % %         SpecMatrix creation
            Plot_SpecMatrix(app, Node, newArray)

            switch LevelUnit
                case {'dBm',  'DBM'};  upYLim = -20; strUnit = 'dBm';
                case {'dBµV', 'DBUV'}; upYLim =  87; strUnit = 'dBµV';
                case 'dBµV/m';         upYLim = 100; strUnit = 'dBµV/m';
            end
            downYLim = min(newArray) - mod(min(newArray), 10);
            app.RestoreView = {[FreqStart, FreqStop], [downYLim, upYLim]};

            % % Axes1 (Spectrum plot): Title and subtitle.
            if app.Button_Task.Value
                titleStr = sprintf('%s\nModo: %s. Detector: %s. Resolução: %s. %d pontos por traço.', Node, TraceMode, Detector, Resolution, DataPoints);
            else
                titleStr = sprintf('%s\nOperação: %s. Resolução: %s. %d pontos por traço.',           Node, TraceMode,           Resolution, DataPoints);
            end         
            subtitleStr = sprintf('%s (%d de %d)', datestr(app.specMatrix.Data{1}(1), 'dd/mm/yyyy HH:MM:SS'), ...
                                                   1, app.specMatrix.HistoryDepth);

            title(app.axes1, titleStr, subtitleStr);

            set(app.axes1, XLim=app.RestoreView{1}, YLim=app.RestoreView{2})
            ylabel(app.axes1, sprintf('Nível (%s)', strUnit));

            % %          Axes 2 (WaterFall plot)
            if app.tb_waterfall.State; Plot_Layout2_wFall(app)
            end

        end


        function Plot_Layout2_wFall(app)

            if diff(app.axes1.YLim) > 100
                Li = (app.axes1.YLim(1)+diff(app.axes1.YLim)-100);
                Ls = app.axes1.YLim(2);
            else
                Li = app.axes1.YLim(1);
                Ls = app.axes1.YLim(2);
            end
            caxis(app.axes2, [Li Ls])
            colormap(app.axes2, app.General.ColorMap);

            set(app.cBar1, Limits=[Li, Ls], Ticks=[Li, Ls], TickLabels={num2str(Li), num2str(Ls)})
            set(app.axes2, XLim=app.axes1.XLim, YLim=[1, app.General.HistoryDepth], YTick=round(linspace(1,app.General.HistoryDepth, 6)))
            ylabel(app.axes2, 'Amostras');

        end


        function Plot_Update(app, newArray)

            app.specMatrix.WritedSamples = app.specMatrix.WritedSamples+1;
            app.specMatrix.RecordSamples = app.specMatrix.RecordSamples+1;
            if app.specMatrix.RecordSamples > app.specMatrix.HistoryDepth; app.specMatrix.RecordSamples = app.specMatrix.HistoryDepth;
            end

            ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);
            if ~ind; ind = app.specMatrix.HistoryDepth;
            end

            indMatrix = ind-app.General.HistoryDepth+1:ind;
            indFind   = find(indMatrix <= 0);
            if ~isempty(indFind); indMatrix = [app.specMatrix.HistoryDepth-numel(indFind)+1:app.specMatrix.HistoryDepth, 1:ind];
            end

            app.specMatrix.Data{1}(ind)   = datetime('now');
            app.specMatrix.Data{2}(ind,:) = newArray;

            app.specMatrix.SampleTime  = ((app.General.SampleTimeFactor-1)*app.specMatrix.SampleTime + str2double(datestr(app.specMatrix.Data{1}(indMatrix(end))-app.specMatrix.Data{1}(indMatrix(end-1)), 'ss.FFF'))*1000) / app.General.SampleTimeFactor;

            % %         Axes 1: main lines
            app.line_ClrWrite.YData = app.specMatrix.Data{2}(ind,:);

            if ~isempty(app.line_minHold); app.line_minHold.YData = min(app.line_minHold.YData, app.specMatrix.Data{2}(ind,:));
            end
            if ~isempty(app.line_Average); app.line_Average.YData = ((app.General.AverageFactor-1)*app.line_Average.YData + app.specMatrix.Data{2}(ind,:)) / app.General.AverageFactor;
            end            
            if ~isempty(app.line_maxHold); app.line_maxHold.YData = max(app.line_maxHold.YData, app.specMatrix.Data{2}(ind,:));
            end
            
            % %         Axes2: Waterfall
            if ~isempty(app.surface_wFall); app.surface_wFall.CData = app.specMatrix.Data{2}(indMatrix,:);
            end

            % %         Subtitle
            app.axes1.Subtitle.String = sprintf('%s (%d de %d). Tempo de escrita: %.0f ms.',                 ...
                                                datestr(app.specMatrix.Data{1}(ind), 'dd/mm/yyyy HH:MM:SS'), ...
                                                app.specMatrix.RecordSamples, app.specMatrix.HistoryDepth, app.specMatrix.SampleTime);

        end


        function Plot_axesFlagValueChanged(app)
            
            switch app.axesFlagType
                case 'SelectedNode'
                    cla(app.axes1)
                    cla(app.axes2)
                    
                    if app.Button_Task.Value
                        ind4 = app.task_Tree.SelectedNodes.NodeData;
                        app.traceInfo.Index(4) = ind4;
                        
                        if app.taskFlag == 1
                            fprintf(app.Instrument, app.specInfo.scpiGeneral{ind4});
                            
                            if ~isempty(app.instrInfo.scpiAttenuation{app.traceInfo.Index(2)})
                                fprintf(app.Instrument, replace(app.instrInfo.scpiAttenuation{app.traceInfo.Index(2)}, '%AttenuationValue%', num2str(app.specInfo.AttenuationValue(ind4))));
                            end
                        end
                        
                    else
                        app.traceInfo.Index = app.logger_Tree.SelectedNodes.NodeData;
                    end
                    
                case 'TraceProcessing'
                    Plot_TraceInfo(app)
                    
                case 'Waterfall'
                    Plot_TraceInfo(app)
                    Plot_Layout1(app)
                    Plot_Layout2_wFall(app)
            end
            
            app.axesFlag = 0;
            drawnow nocallbacks

        end


        function Plot_CloseFigure(app, src, event)

            switch event.EventName
                case 'Close'
                    app.fig.Visible = 0;
                    app.task_PlotVisibility.ImageSource   = 'play_Unview.png';
                    app.logger_PlotVisibility.ImageSource = 'play_Unview.png';

                case 'Open'
                    app.fig.Visible = 1;
                    app.task_PlotVisibility.ImageSource   = 'play_View.png';
                    app.logger_PlotVisibility.ImageSource = 'play_View.png';
            end
            
        end


        function Write_logFile(app, Type, fileLog, identifier, message)

            strPattern = '%s,\n';
            if strcmp(Type, 'Trailer'); strPattern = '%s\n]';
            end

            fprintf(fileLog, sprintf(strPattern, jsonencode(struct('time', datestr(now, 'dd/mm/yyyy HH:MM:ss.FFF'), ...
                                                                   'identifier', identifier,                        ...
                                                                   'message',    message), 'PrettyPrint', true)));

            if strcmp(Type, 'Trailer'); fclose(fileLog);
            end

        end


        function Write_specData(app, ind1, RefLevel, attFactor, gpsData, specData, fileLog)

            TimeStamp = datetime('now');

            if app.specInfo.WritedSamples{ind1}.Data.Value == app.specInfo.AlocatedSamples{ind1}.Data.Value
                filename = sprintf('~%s_%.0f.bin', app.specInfo.Filename{ind1}, app.specInfo.FileCount(ind1));
                system(sprintf('rename "%s" "%s"', fullfile(app.menu_userPath.Value, filename), replace(filename, '~', '')));

                app.specInfo.FileCount(ind1) = app.specInfo.FileCount(ind1)+1;
                RegularTask_FileCreationAux(app, fileLog, ind1, 'NewFile')
            end

            ind2 = app.specInfo.WritedSamples{ind1}.Data.Value + 1;

            app.specInfo.WritedSamples{ind1}.Data.Value = ind2;
            app.specInfo.memmapData{ind1}{1}.Data(ind2).localTimeStamp    = int8([year(TimeStamp)-2000, month(TimeStamp), day(TimeStamp), hour(TimeStamp), minute(TimeStamp), fix(second(TimeStamp))]);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).localTimeStamp_ms = int16((second(TimeStamp) - fix(second(TimeStamp))).*1000);

            app.specInfo.memmapData{ind1}{1}.Data(ind2).RefLevel  = int16(RefLevel);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).attFactor = uint8(attFactor);

            app.specInfo.memmapData{ind1}{1}.Data(ind2).gpsStatus = uint8(gpsData.Status);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).Latitude  = single(gpsData.Latitude);
            app.specInfo.memmapData{ind1}{1}.Data(ind2).Longitude = single(gpsData.Longitude);

            % Codificação
            switch app.task_BitsPerPoint.Value
                case '8 bits'
                    value = -2*RefLevel+255;
                    app.specInfo.memmapData{ind1}{2}.Data.Array(:,ind2) = uint8(2.*specData + value); % 0.5 dB

                case '16 bits'
                    app.specInfo.memmapData{ind1}{2}.Data.Array(:,ind2) = int16(100.*specData);       % 0.01 dB

                case '32 bits'
                    app.specInfo.memmapData{ind1}{2}.Data.Array(:,ind2) = single(specData);           % 0.0000000001 dB
            end

        end


        % %     Task Data
        function Write_formatHeader(app, fileID, AlocatedSamples)

            fwrite(fileID, 'RFlookBin v.1/1', 'char*1');
            fwrite(fileID, str2double(extractBefore(app.task_BitsPerPoint.Value, 'bits')));         % 8 | 16 | 32 bits per point
            fwrite(fileID, AlocatedSamples, 'uint32');                                              % AlocatedSamples
            fwrite(fileID, 0,               'uint32');                                              % WritedSamples

        end


        % %     Spectrum analyzer (or receiver) MetaData
        function Write_metaHeader(app, fileID, MetaData)

            fwrite(fileID, MetaData.FreqStart,       'single');
            fwrite(fileID, MetaData.FreqStop,        'single');
            fwrite(fileID, MetaData.ResolutionValue, 'single');
            fwrite(fileID, MetaData.DataPoints,      'uint16');                  % Sweep Points (0-65535 pontos)
            fwrite(fileID, MetaData.Trace_ID,        'int8');
            fwrite(fileID, MetaData.Detector_ID,     'int8');
            fwrite(fileID, MetaData.LevelUnit_ID,    'int8');                    % LevelUnit (1: dBm)
            fwrite(fileID, MetaData.Preamp,          'int8');
            fwrite(fileID, MetaData.AttenuationMode, 'int8');

            if MetaData.AttenuationMode; attFactor = -1;
            else;                        attFactor = MetaData.AttenuationValue;
            end
            fwrite(fileID, attFactor,                'int8');

            fwrite(fileID, MetaData.SampleTimeValue, 'single');
            fwrite(fileID, zeros(1, 2));                                    % Alignment

        end


        % %     GPS Data
        function Write_gpsHeader(app, fileID)
        
            fwrite(fileID, app.gpsInfo.Type);
            
            if app.gpsInfo.Type
                fwrite(fileID, app.gpsInfo.Status,    'int8');
        
                fwrite(fileID, app.gpsInfo.Latitude,  'single');
                fwrite(fileID, app.gpsInfo.Longitude, 'single');
                
                if ~isempty(app.gpsInfo.TimeStamp)
                    fwrite(fileID, year(app.gpsInfo.TimeStamp)-2000, 'int8');
                    fwrite(fileID, month(app.gpsInfo.TimeStamp),     'int8');
                    fwrite(fileID, day(app.gpsInfo.TimeStamp),       'int8');
        
                    fwrite(fileID, hour(app.gpsInfo.TimeStamp),      'int8');
                    fwrite(fileID, minute(app.gpsInfo.TimeStamp),    'int8');
                    fwrite(fileID, second(app.gpsInfo.TimeStamp),    'int8');
                    fwrite(fileID, (second(app.gpsInfo.TimeStamp) - ...
                                fix(second(app.gpsInfo.TimeStamp))).*1000, 'int16');
                else
                    fwrite(fileID, -ones(6, 1), 'int8');
                    fwrite(fileID, -1,          'int16');
                end
        
            else
                fwrite(fileID, -1,                    'int8');
                fwrite(fileID, app.gpsInfo.Latitude,  'single');
                fwrite(fileID, app.gpsInfo.Longitude, 'single');
                fwrite(fileID, -ones(6, 1),           'int8');
                fwrite(fileID, -1,                    'int16');
            end
        
        end


        % %     Body Block
        function [Offset1, Offset2] = Write_Body(app, fileID, AlocatedSamples, DataPoints, TaskName, Task, Node)

            Offset1 = ftell(fileID) + 12;
            Offset2 = Offset1 + 20*AlocatedSamples;
            Offset3 = Offset2 + (str2double(extractBefore(app.task_BitsPerPoint.Value, 'bits'))/8)*DataPoints.*AlocatedSamples;

            fwrite(fileID, Offset1, 'uint32');
            fwrite(fileID, Offset2, 'uint32');
            fwrite(fileID, Offset3, 'uint32');

            fwrite(fileID, zeros(1, (20 + (str2double(extractBefore(app.task_BitsPerPoint.Value, 'bits'))/8)*DataPoints).*AlocatedSamples, 'uint8'));
            
            oldStr = {'"', ',', newline};

            if ~strcmp(app.task_Antenna.Value, 'Unlisted (Directional)')
                fwrite(fileID, jsonencode(struct('TaskName',          replace(TaskName, oldStr, ''),         ...
                                                 'ThreadID',          Task.ThreadID,                         ...
                                                 'Description',       replace(Task.Description, oldStr, ''), ...
                                                 'Node',              Node,                                  ...
                                                 'Antenna',           app.task_Antenna.Value,                ...
                                                 'AntennaHeight',     app.task_Height.Value,                 ...
                                                 'IntegrationFactor', Task.IntegrationFactor,                ...
                                                 'RevisitTime',       Task.RevisitTime)), 'char*1');

            else
                fwrite(fileID, jsonencode(struct('TaskName',          replace(TaskName, oldStr, ''),         ...
                                                 'ThreadID',          Task.ThreadID,                         ...
                                                 'Description',       replace(Task.Description, oldStr, ''), ...
                                                 'Node',              Node,                                  ...
                                                 'Antenna',           app.task_Antenna.Value,                ...
                                                 'AntennaHeight',     app.task_Height.Value,                 ...
                                                 'AntennaAzimuth',    app.task_Azimuth.Value,                ...
                                                 'AntennaElevation',  app.task_Elevation.Value,              ...
                                                 'IntegrationFactor', Task.IntegrationFactor,                ...
                                                 'RevisitTime',       Task.RevisitTime)), 'char*1');
            end

        end


        function LoggerTask(app)

            try
                [app.udpPortArray, udpIndex] = connect_udpSockets(app.udpPortArray, app.logger_Port.Value, app.General.logger.Timeout);
                if ~isempty(udpIndex)
                    app.udpPort = app.udpPortArray{udpIndex};
                    flush(app.udpPort)
                end
                
                logger_Startup(app)
                d = uiprogressdlg(app.MainApp, 'Title', 'appColeta', 'Indeterminate', 'on', 'Interpreter', 'html', 'Cancelable', 'on', 'CancelText', 'Cancelar');
                d.Message = '<font style="font-size:12;">Tentando se conectar ao instrumento...</font>';

                while ~app.udpPort.NumDatagramsAvailable
                    if d.CancelRequested
                        logger_ButtonPlayValueChanged(app)
                        return
                    end
                end
                task_Layout(app)

                delete(d)
                
                cla(app.axes1)
                cla(app.axes2) 
                drawnow nocallbacks
                
                app.traceInfo.Index = 1;
                Plot_TraceInfo(app)
                
            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
                return
            end
            
            fileID = [];

            tic
            t1 = toc;
            while app.taskFlag
                try
                    if t1 > app.General.logger.ConnectionTime
                        tic
                        logger_Startup(app)
    
                        if ~app.udpPort.NumDatagramsAvailable
                            error('<b>Não foi recebido datagrama do sensor.</b>')
                        end
    
                    elseif app.udpPort.NumDatagramsAvailable
                        app.logger_AvailableDatagrams.Value = app.udpPort.NumDatagramsAvailable;

                        SpecInfo = read(app.udpPort, 1, 'uint8');
                        for ii = 1:numel(SpecInfo)
                            if ~strcmp(SpecInfo(ii).SenderAddress, app.logger_IP.Value)
                                continue
                            end

                            try
                                MagicNumber = typecast(uint8((SpecInfo(ii).Data(1:4))), 'uint32');
                                ThreadID    = typecast(uint8(SpecInfo(ii).Data(9:12)),  'uint32');
                                DataType    = typecast(uint8(SpecInfo(ii).Data(17:20)), 'int32');
                                EOFMarker   = char(SpecInfo(ii).Data(end-3:end));
                                
                                if (MagicNumber == 1193046) && strcmp(EOFMarker, 'UUUU')
                                    switch DataType
                                        case {67, 68}
                                            outInfo  = logger_DatagramRead(DataType, SpecInfo(ii).Data, ThreadID);
                                            Bin      = outInfo{1};
                                            newArray = outInfo{2};
    
                                            indPlot  = logger_BandListUpdate(app, Bin);
                                            logger_Plot(app, indPlot, newArray)
    
                                        otherwise
                                            switch DataType
                                                case 40
                                                    flagInfo = 'GPS'; 
                                                    outInfo  = logger_DatagramRead(DataType, SpecInfo(ii).Data, ThreadID);
                                                case 42
                                                    flagInfo = 'TEXT'; 
                                                    outInfo  = logger_DatagramRead(DataType, SpecInfo(ii).Data);
                                                otherwise
                                                    flagInfo = 'OTHERWISE';
                                                    outInfo  = sprintf('Não implementado leitor do DataType %d.', DataType);
                                            end                                        
                                            LoggerTask_TextAreaUpdate(app, flagInfo, outInfo)
                                    end
                                    
                                    if app.recFlag
                                        if isempty(fileID)
                                            fileID = logger_Write_FileHeader(app);
                                        end
        
                                        if ismember(ThreadID, [0, 1, [app.bandList.REC]])
                                            fwrite(fileID, uint8((SpecInfo(ii).Data(9:end))));
                                        end
                                    else
                                        if ~isempty(fileID)
                                            fclose(fileID);
                                            fileID = [];
                                        end
                                    end
                                end

                            catch ME
                                LoggerTask_TextAreaUpdate(app, 'ERROR', ME.message)
                            end
                        end
                    end
                    t1 = toc;
                    pause(app.logger_Pause.Value/1000)
                    
                catch ME
                    Selection = uiconfirm(app.MainApp, sprintf('<font style="font-size:12;">%s\n\n<b>Deseja tentar extrair dados do instrumento mais uma vez?</b></font>', getReport(ME)), 'appColeta', 'Options', {'Sim', 'Não'}, 'Interpreter', 'html');
                    
                    switch Selection
                        case 'Sim'; continue
                        case 'Não'; logger_ButtonPlayValueChanged(app)
                    end
                end
            end

            if ~isempty(fileID)
                fclose(fileID);
            end

        end


        function LoggerTask_TextAreaUpdate(app, Type, Info)

            switch Type
                case 'GPS'
                    GPS = Info;

                    if isempty(app.logger_TextInfo.Value{1})
                        app.logger_TextInfo.Value        = sprintf('%s, GPS, "%s"', GPS.TimeStamp, jsonencode(rmfield(GPS, 'TimeStamp')));
                    else
                        app.logger_TextInfo.Value{end+1} = sprintf('%s, GPS, "%s"', GPS.TimeStamp, jsonencode(rmfield(GPS, 'TimeStamp')));
                    end

                case 'TEXT'
                    TimeStamp  = Info{1};
                    Identifier = Info{2};
                    FreeText   = Info{3};
                
                    if isempty(app.logger_TextInfo.Value{1})
                        app.logger_TextInfo.Value        = sprintf('%s, %s, "%s"', TimeStamp, Identifier, FreeText);
                    else
                        app.logger_TextInfo.Value{end+1} = sprintf('%s, %s, "%s"', TimeStamp, Identifier, FreeText);
                    end

                case 'OTHERWISE'
                    TimeStamp  = datestr(now, 'dd/mm/yyyy HH:MM:ss');

                    if isempty(app.logger_TextInfo.Value{1})
                        app.logger_TextInfo.Value        = sprintf('%s, %s, "%s"', TimeStamp, 'DATA TYPE', Info);
                    else
                        app.logger_TextInfo.Value{end+1} = sprintf('%s, %s, "%s"', TimeStamp, 'DATA TYPE', Info);
                    end

                case 'ERROR'
                    TimeStamp  = datestr(now, 'dd/mm/yyyy HH:MM:ss');

                    if isempty(app.logger_TextInfo.Value{1})
                        app.logger_TextInfo.Value        = sprintf('%s, %s, "%s"', TimeStamp, 'ERROR', Info);
                    else
                        app.logger_TextInfo.Value{end+1} = sprintf('%s, %s, "%s"', TimeStamp, 'ERROR', Info);
                    end
            end
            scroll(app.logger_TextInfo, 'bottom')

        end


        function fileID = logger_Write_FileHeader(app)

            % Leitura de informações e escrita em arquivo.
            filename = sprintf('appColeta_%s.bin', datestr(now,'yymmdd_THHMMSS'));
            fileID = fopen(fullfile(app.menu_userPath.Value, filename), 'w+');

            % File Format Header
            fwrite(fileID, 23, 'int32');
            fwrite(fileID, ['CRFS DATA FILE V023' zeros(1, 13)], 'char*1');

            % Unit Information
            Node = '';
            if ~isempty(app.logger_TextInfo.Value)
                for ii = 1:numel(app.logger_TextInfo.Value)
                    Node = char(regexpi(app.logger_TextInfo.Value{ii}, 'rfeye[0-9]{6}', 'match'));
                    if ~isempty(Node); break
                    end
                end
            end

            if isempty(Node); Node = app.logger_NodeList.Value(1:11);
            end

            unitInfo = 'Stationary';
            method   = sprintf('appColeta (%s:%.0f)', app.logger_IP.Value, app.logger_Port.Value);

            logger_FileWriter_CRFSBin(21, fileID, {Node, unitInfo, method})

        end


        function logger_TreeBuilding(app)
            
            SelectedNode = [];
            if ~isempty(app.logger_Tree.SelectedNodes)
                SelectedNode = app.bandList(app.logger_Tree.SelectedNodes.NodeData);
            end
            
            delete(app.logger_Tree.Children);

            if ~issorted([app.bandList.ThreadID])
                [~, ind]     = sort([app.bandList.ThreadID]);
                app.bandList = app.bandList(ind);
            end

            for ii = 1:numel(app.bandList)
                str = sprintf('ThreadID %d: %.3f - %.3f MHz', app.bandList(ii).ThreadID,         ...
                                                              app.bandList(ii).FreqStart / 1e+6, ...
                                                              app.bandList(ii).FreqStop  / 1e+6);

                treeChild = uitreenode(app.logger_Tree, 'Text', str, 'NodeData', ii);
                if ~isempty(app.bandList(ii).REC)
                    app.logger_Tree.CheckedNodes(end+1) = treeChild;
                end
            end
            
            if isempty(SelectedNode)
                ind = 1;
            else
                for jj = 1:numel(app.bandList)
                    if isequal(app.bandList(jj), SelectedNode)
                        ind = jj;
                        break
                    end
                end
            end
            app.logger_Tree.SelectedNodes = app.logger_Tree.Children(ind);
            
            if isempty([app.bandList.REC]); app.tb_rec.Visible = 0;
            else;                           app.tb_rec.Visible = 1;
            end
            
        end

    end
    
    methods (Access = public)

        function logger_Startup(app)
            
            msg = sprintf('open %.0f', app.General.logger.ConnectionTime);
        
            write(app.udpPort, msg, 'uint8', app.logger_IP.Value, app.logger_Port.Value);
            pause(app.General.logger.HandshakePause)
        
        end


        function idx = logger_BandListUpdate(app, Bin)

            if isempty(app.bandList)
                app.bandList = Bin;
                
                logger_TreeBuilding(app)
                logger_TreeSelectionChanged(app)
                                
                idx = 1;                
            else
                indFind = find([app.bandList.ThreadID] == Bin.ThreadID);
                
                Flag = 0;
                if ~isempty(indFind)
                    for ii = indFind
                        if isequal(rmfield(app.bandList(ii), 'REC'), rmfield(Bin, 'REC'))
                            Flag = 1;                            
                            idx  = ii;
                            break
                        end
                    end
                end

                if ~Flag
                    app.bandList(end+1) = Bin;
                    logger_TreeBuilding(app)
                    
                    idx = numel(app.bandList);
                end
            end

        end


        function logger_Plot(app, indPlot, newArray)
            
            if app.taskFlag && (app.traceInfo.Index == indPlot)
                if app.axesFlag
                    Plot_axesFlagValueChanged(app)
                    app.traceInfo.Index = app.logger_Tree.SelectedNodes.NodeData;
                                        
                else
                    if ~isempty(app.axes1.Children)
                        Plot_Update(app, newArray')
                    else
                        Plot_Creation(app, newArray', [], app.traceInfo.Index)
                    end
                end
                drawnow
            end

        end

        
        function logger_ftpInfo(app, status, info)

            app.logger_ftpStatus.Text   = info;
            app.logger_ScriptName.Value = '';
            app.logger_MaskName.Items   = {};
                    
            switch status
                case 'sucess'
                    set(app.logger_Panel3_Grid.Children, 'Enable', 1)

                case 'error'
                    set(app.logger_Panel3_Grid.Children, 'Enable', 0)
                    app.logger_ScriptNameLabel.Enable = 1;
                    app.logger_MaskNameLabel.Enable   = 1;

                    app.tb_mask.Visible = 0;
            end
            
        end


        function [maskName, methodField] = logger_ScriptRead(app, filename)

            maskName    = {};
            methodField = '';
            
            ii  = 0;
            fid = fopen(filename);
            while ~feof(fid)
                tline = fgetl(fid);

                if ~isempty(regexpi(tline,'method\s*=', 'match'))
                   methodField = extractAfter(tline, '=');
                   methodField = replace(methodField,{'"',' '}, '');
                end

                if ~isempty(regexpi(tline, 'mask\s*=', 'match'))
                    ii = ii+1;

                    tlineSplit   = split(tline, ',');
                    maskName{ii} = replace(tlineSplit{5}, ' ', '');
                end
            end
            fclose(fid); 

            maskName = unique(maskName);

        end


        function Misc_uialertCreation(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.MainApp, msg, 'appColeta', 'Interpreter', 'html');
                case 'warning'; uialert(app.MainApp, msg, 'appColeta', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.MainApp, msg, 'appColeta', 'Interpreter', 'html', 'Icon', fullfile(app.RootFolder, 'Icons', 'Toolbar_Info.png'))
                case 'startup'; uialert(app.MainApp, msg, 'appColeta', 'Interpreter', 'html', 'CloseFcn', @(~,~)startupError(app));
            end

        end


        function gpsStruct = Misc_winAux(app, Type)

            gpsStruct = '';

            switch Type
                case 'winTaskEdit'
                    Button_TaskValueChanged(app)

                case 'winRFeyeEdit'
                    Button_LoggerValueChanged(app)

                case 'winAnatelDB'
                    if ~isempty(app.logger_TextInfo.Value)
                        idx = find(contains(app.logger_TextInfo.Value, '{"type": 3'));
                        
                        try
                            for ii = idx'
                                gpsTemp = jsondecode(char(extractBetween(app.logger_TextInfo.Value{ii}, '{"type": 3', '}', 'Boundaries', 'inclusive')));

                                if gpsTemp.gpsStatus
                                    gpsTemp.gpsLatitude  = gpsTemp.gpsLatitude  ./ 1e+6;
                                    gpsTemp.gpsLongitude = gpsTemp.gpsLongitude ./ 1e+6;

                                    gpsStruct = struct('Status', 1, 'Latitude', gpsTemp.gpsLatitude, 'Longitude', gpsTemp.gpsLongitude);

                                    break
                                end
                            end                            
                        catch
                        end
                    end
            end

        end


        % TOOLBAR CALLBACKS
        function toolbarCallbacks_refreshView(app, src, event)

            if ~isempty(app.RestoreView)
                set(app.axes1, 'XLim', app.RestoreView{1}, 'YLim', app.RestoreView{2})
                set(app.axes2, 'YLim', [0,app.General.HistoryDepth])
            end

        end


        function toolbarCallbacks_interaction(app, src, event)

            if app.tb_interaction.Tag == "zoom"
                app.axes1.Interactions = [regionZoomInteraction, zoomInteraction, dataTipInteraction];
                app.axes2.Interactions = [regionZoomInteraction, zoomInteraction];

                set(app.tb_interaction, 'CData', startup_toolbarIcon(app, 'interaction_pan'), 'Tag', 'pan')
            else
                app.axes1.Interactions = [panInteraction, zoomInteraction, dataTipInteraction];
                app.axes2.Interactions = [panInteraction, zoomInteraction];

                set(app.tb_interaction, 'CData', startup_toolbarIcon(app, 'interaction_zoom'), 'Tag', 'zoom')
            end

        end


        function toolbarCallbacks_layout(app, src, event)

            if app.tb_layout.Tag == "1:1"
                set(app.tb_layout, 'CData', startup_toolbarIcon(app, 'layout2'), 'Tag', "1:3")
            else
                set(app.tb_layout, 'CData', startup_toolbarIcon(app, 'layout1'), 'Tag', "1:1")
            end

            Plot_Layout1(app)

        end


        function toolbarCallbacks_minHold(app, src, event)

            if ~isempty(app.specMatrix)
                if app.tb_minHold.State
                    app.traceInfo.Mode(1) = 1;
    
                    ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);
    
                    app.line_minHold = plot(app.axes1, app.x, app.specMatrix.Data{2}(ind,:), 'Tag', 'MinHold', 'Color', app.General.Colors(1,:));
                    Misc_PlotDataTip(app.line_minHold, app.task_LevelUnit2.Value)
    
                else
                    Plot_TraceInfo(app)
                end
                drawnow nocallbacks
            end

        end


        function toolbarCallbacks_meanHold(app, src, event)

            if ~isempty(app.specMatrix)
                if app.tb_meanHold.State
                    app.traceInfo.Mode(2) = 2;
                    
                    ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);
    
                    app.line_Average = plot(app.axes1, app.x, app.specMatrix.Data{2}(ind,:), 'Tag', 'Average', 'Color', app.General.Colors(2,:));
                    Misc_PlotDataTip(app.line_Average, app.task_LevelUnit2.Value)
    
                else
                    Plot_TraceInfo(app)
                end
                drawnow nocallbacks
            end

        end


        function toolbarCallbacks_maxHold(app, src, event)

            if ~isempty(app.specMatrix)
                if app.tb_maxHold.State
                    app.traceInfo.Mode(3) = 3;
                    
                    ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);
    
                    app.line_maxHold = plot(app.axes1, app.x, app.specMatrix.Data{2}(ind,:), 'Tag', 'MaxHold', 'Color', app.General.Colors(3,:));
                    Misc_PlotDataTip(app.line_maxHold, app.task_LevelUnit2.Value)
    
                else
                    Plot_TraceInfo(app)
                end
                drawnow nocallbacks
            end

        end


        function toolbarCallbacks_waterfall(app, src, event)

            if ~isempty(app.specMatrix)
                app.axesFlagType = 'Waterfall';
                app.axesFlag     = 1;
    
                if app.tb_waterfall.State
                    app.traceInfo.plotLayout = 1;
                    
                    ind = mod(app.specMatrix.WritedSamples, app.specMatrix.HistoryDepth);
                    if ~ind; ind = app.specMatrix.HistoryDepth;
                    end
    
                    indMatrix = ind-app.General.HistoryDepth+1:ind;
                    indFind   = find(indMatrix <= 0);
                    if ~isempty(indFind); indMatrix = [app.specMatrix.HistoryDepth-numel(indFind)+1:app.specMatrix.HistoryDepth, 1:ind];
                    end
    
                    app.surface_wFall = image(app.axes2, app.x, 1:app.General.HistoryDepth, app.specMatrix.Data{2}(indMatrix,:), 'CDataMapping', 'scaled', 'Tag', 'wFall');
                    set(app.axes2, 'XLim', app.RestoreView{1}, 'View', [0, 90]);
    
                end
    
                if ~app.taskFlag
                    Plot_axesFlagValueChanged(app)
                end
                drawnow nocallbacks
            end

        end


        function toolbarCallbacks_mask(app, srd, event)

            if ~isempty(app.logger_MaskName.Value)
                obj = findobj('Tag', 'LoggerMask');
                if ~isempty(obj)
                    delete(obj)
                end
    
                maskTable = readtable(fullfile(app.RootFolder, 'Temp', app.logger_MaskName.Value), 'VariableNamingRule', 'preserve');
                maskTable.Properties.VariableNames = {'FreqStart', 'FreqStop', 'Level'};
    
                for ii = 1:height(maskTable)
                    newObj = plot(app.axes1, [maskTable.FreqStart(ii)/1000, maskTable.FreqStop(ii)/1000], [maskTable.Level(ii), maskTable.Level(ii)], 'red', ...
                                  'Marker', 'o', 'MarkerEdgeColor', 'red', 'MarkerFaceColor', 'red', 'MarkerSize', 4, 'Tag', 'LoggerMask');
    
                    Misc_PlotDataTip(newObj, 'dBm')
                end
            end

        end


        function toolbarCallbacks_rec(app, srd, event)

            % Não aplicável na "Tarefa regular" pois o botão fica
            % invisível.
            if app.tb_rec.State
                if app.taskFlag
                    set(app.tb_rec, 'CData', startup_toolbarIcon(app, 'rec_stop'))
                    app.recFlag = 1;
                    app.tool_REC.Visible = 1;
                    
                    if app.Button_Task.Value
                        app.task_Tree.Enable = 0;

                    else
                        Node = '';
                        for ii = 1:numel(app.logger_TextInfo.Value)
                            Node = char(regexpi(app.logger_TextInfo.Value{ii}, 'rfeye[0-9]{6}', 'match'));
                            if ~isempty(Node); break
                            end
                        end

                        if isempty(Node)
                            Selection = uiconfirm(app.MainApp, '<font style="font-size:12;">Ainda não foi recebida informação que possibilite identificar o <i>hostname</i> do receptor. Continuar assim mesmo?</font>', 'appColeta', 'Options', {'Sim', 'Não'}, 'Interpreter', 'html');
                            
                            if Selection == "Não"
                                app.tb_rec.State = 0;
                                toolbarCallbacks_rec(app)
                            end
                        end
                    end

                else
                    if app.Button_Task.Value && app.specMatrix.RecordSamples && (app.specMatrix.RecordSamples < app.specMatrix.HistoryDepth)
                        WaterfallPlotTask_REC(app)
                        task_Layout(app)
                    end
                end

            else
                if app.taskFlag
                    if app.Button_Task.Value && app.specMatrix.RecordSamples && (app.specMatrix.RecordSamples < app.specMatrix.HistoryDepth)
                        WaterfallPlotTask_REC(app)

                        app.axesFlagType = 'SelectedNode';
                        app.axesFlag     = 1;
                    end
                end

                set(app.tb_rec, 'CData', startup_toolbarIcon(app, 'rec_play'))
                app.recFlag = 0;
                app.tool_REC.Visible = 0;
                
                if app.Button_Task.Value
                    app.task_Tree.Enable = 1;
                end                
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)

            app.MainApp.Visible = 0;
            
            Flag = 1;
            if isdeployed
                [~, result]    = system('path');
                app.RootFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));

                if ~isfile(fullfile(app.RootFolder, 'appColeta.exe'))
                    Flag = 0;
                    
                    msg = 'O appColeta não identificou o seu local de instalação (PATH). Independente disso, ele pode ser iniciado clicando diretamente no seu executável (e não em algum atalho). Favor reportar o <i>bug</i>.';
                    Misc_uialertCreation(app, 'startup', msg)
                end
                app.menu_userPath.Items = startup_userInitialFolder(app);
                
            else
                prjPath = matlab.project.rootProject;
                appPath = fullfile(char(com.mathworks.appmanagement.MlappinstallUtil.getAppInstallationFolder), 'appColeta');
                
                if ~isempty(prjPath) & strcmp(prjPath.Name, 'appColeta')
                    app.RootFolder          = char(prjPath.RootFolder);
                    app.menu_userPath.Items = {fullfile(app.RootFolder, 'Temp')};
                    
                elseif isfolder(appPath)
                    app.RootFolder          = appPath;
                    app.menu_userPath.Items = startup_userInitialFolder(app);
                    
                else
                    Flag = 0;
                    
                    msg = 'O appColeta somente poderá ser executado se o projeto "appColeta.prj" estiver aberto ou instalado no Matlab.';
                    Misc_uialertCreation(app, 'startup', msg)
                end
            end
            
            if Flag
                % Temp folder creation...
                if ~isfolder(fullfile(app.RootFolder, 'Temp'))
                    mkdir(fullfile(app.RootFolder, 'Temp'))
                end

                % appColeta, Fiscaliza and AnatelDB versions.
                [app.General.Version, msgConsole] = appVersion(app.RootFolder);

                % Console message...
                if isdeployed
                    fprintf('\n%s\n', msgConsole);
                end

                % UserPath, InspectionType & FindPeaksDefaultValues
                startup_userSelectedFolder(app)
                startup_InspectionType(app)
                Misc_FindPeaksDefaultValues(app.RootFolder)
                
                % App position & figure/axes creation
                startup_figCreation(app)

                app.MainApp.Visible = 1;
                drawnow nocallbacks
            end

        end

        % Close request function: MainApp
        function closeRequest(app, event)

            if app.taskFlag
                msg = 'Existe uma tarefa em execução...';
                Misc_uialertCreation(app, 'warning', msg)

                return
            end
            
            instrreset            
            for ii = 1:numel(app.udpPortArray)
                delete(app.udpPortArray{ii})
            end

            delete(app.auxWin1)
            delete(app.auxWin2)
            delete(app.auxWin3)
            delete(app.auxWin4)
            delete(app.auxWin5)

            delete(app.fig)
            delete(app)

        end

        % Value changed function: Button_Home
        function Button_HomeValueChanged(app, event)

            if app.Button_Home.Value
                set(findall(groot, 'Parent', app.menu_Grid2A, 'Type', 'uistatebutton', '-not', 'Tag', 'HOME'), 'Value', 0)
            else
                app.Button_Home.Value = 1;
                return
            end
            
            ModeOperation(app)

        end

        % Value changed function: Button_Task
        function Button_TaskValueChanged(app, event)

            if app.Button_Task.Value
                set(findall(groot, 'Parent', app.menu_Grid2A, 'Type', 'uistatebutton', '-not', 'Tag', 'TASK'), 'Value', 0)
            else
                app.Button_Task.Value = 1;
                return
            end
            
            ModeOperation(app)

        end

        % Value changed function: Button_Logger
        function Button_LoggerValueChanged(app, event)

            if app.Button_Logger.Value
                set(findall(groot, 'Parent', app.menu_Grid2A, 'Type', 'uistatebutton', '-not', 'Tag', 'LOGGER'), 'Value', 0)
            else
                app.Button_Logger.Value = 1;
                return
            end
            
            ModeOperation(app)

        end

        % Callback function
        function Button_MiscValueChanged(app, event)
            
            if app.Button_Misc.Value
                set(findall(groot, 'Parent', app.menu_Grid2A, 'Type', 'uistatebutton', '-not', 'Tag', 'MISC'), 'Value', 0)
            else
                app.Button_Misc.Value = 1;
                return
            end
            
            ModeOperation(app)
            
        end

        % Value changed function: menu_userPath
        function tool_userPathValueChanged(app, event)
            
            fileID = fopen(fullfile(app.RootFolder, 'DataBase', 'UserPath.cfg'), 'wt');
            fwrite(fileID, app.menu_userPath.Value, 'char');
            fclose(fileID);
            
        end

        % Image clicked function: menu_searchPath
        function tool_userPathImageClicked(app, event)

            newUserPath = uigetdir(app.menu_userPath.Value, 'Escolha a pasta de trabalho');
            figure(app.MainApp)

            if newUserPath
                ind = find(strcmp(app.menu_userPath.Items, newUserPath), 1);

                if isempty(ind)
                    app.menu_userPath.Items(end+1) = {newUserPath};
                    app.menu_userPath.Value        = newUserPath;
                    
                    tool_userPathValueChanged(app)
                else
                    app.menu_userPath.Value        = app.menu_userPath.Items(ind);
                end
            end

        end

        % Button pushed function: tool_Button1
        function tool_Button1Pushed(app, event)

            [mainMonitor, x0] = startup_appPosition(app);
            
            if ~isempty(app.fig)
                [x1, y1] = startup_figPosition(app, mainMonitor, x0);
            
                app.fig.Position = [x1, y1, 640, 480];
                if app.fig.Visible
                    figure(app.fig)
                end
            end

        end

        % Button pushed function: tool_Button2
        function tool_Button2Pushed(app, event)

            if isempty(app.auxWin1)
                app.auxWin1 = winSettings(app);
            else
                figure(app.auxWin1.UIFigure)
            end

        end

        % Button pushed function: logger_info, task_info, tool_Button3
        function tool_Button3Pushed(app, event)

            info = fileread(fullfile(app.RootFolder, 'Settings', 'info.txt'));

            switch event.Source
                case app.tool_Button3; flag = 'winMain_General';
                case app.task_info;    flag = 'winMain_task';
                case app.logger_info;  flag = 'winMain_logger';
                case app.misc_info;    flag = 'winMain_misc';
            end

            msg = strtrim(char(extractBetween(info, sprintf('<%s>', flag), sprintf('</%s>', flag))));
            Misc_uialertCreation(app, 'info', msg)

        end

        % Button pushed function: home_img1, home_img2, home_img3, 
        % home_img4, home_img5
        function tool_HomeSelection(app, event)
            
            switch event.Source
                case app.home_img1
                    app.Button_Task.Value = 1;
                    Button_TaskValueChanged(app)
                
                case app.home_img2
                    task_TaskEditButtonPushed(app)              

                case app.home_img3
                    app.Button_Logger.Value = 1;
                    Button_LoggerValueChanged(app)

                case app.home_img4
                    Button_AnatelDBPushed(app)

                case app.home_img5
                    tool_Button2Pushed(app)
            end

        end

        % Image clicked function: logger_PlotVisibility, 
        % task_PlotVisibility
        function tool_VisibilityClicked(app, event)
            
            if app.fig.Visible
                Plot_CloseFigure(app, [], struct('EventName', 'Close'))
            else
                Plot_CloseFigure(app, [], struct('EventName', 'Open'))
            end

        end

        % Value changed function: task_Name
        function task_TaskSelectionChanged(app, event)

            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);           

            Duration_sec = app.taskList{ind1}.Duration;
            if Duration_sec >= 3600
                app.task_Duration.Value     = Duration_sec ./ 3600;
                app.task_DurationUnit.Value = 'hr';
            else
                app.task_Duration.Value     = Duration_sec ./ 60;
                app.task_DurationUnit.Value = 'min';
            end

            app.task_BitsPerPoint.Value     = sprintf('%d bits', app.taskList{ind1}.BitsPerSample);
            
            task_TreeBuilding(app, ind1)
            task_TreeSelectionChanged(app)
            
            if ~isempty(app.task_scpiList.Items)
                app.task_Button1.Enable     = 1;
                app.task_Button2.Enable     = 1;
                
            else
                app.task_Button1.Enable     = 0;
                app.task_Button2.Enable     = 0;
            end

            app.specInfo = [];
            task_scpiListValueChanged(app)

        end

        % Button pushed function: task_NameEdit
        function task_TaskEditButtonPushed(app, event)
            
            if isempty(app.auxWin2)
                app.auxWin2 = winTaskEdit(app);
            else
                figure(app.auxWin2.UIFigure)
            end

        end

        % Value changed function: task_Type
        function task_TypeValueChanged(app, event)

            switch app.task_Type.Value
                case "Monitoração espectral - Rompimento de máscara espectral"
                    app.task_GridA2.ColumnWidth(2:3) = {22, 22};
                    if ~isempty(app.task_maskFile.Tooltip{1})
                        app.task_maskFile.Enable = 1;
                    end

                otherwise
                    app.task_GridA2.ColumnWidth(2:3) = {0, 0};
                    app.task_maskFile.Enable = 0;
            end
            
        end

        % Button pushed function: task_addMaskFile
        function task_addMaskFileButtonPushed(app, event)
            
            [Filename, Filepath] = uigetfile({'*.csv', '(*.csv)'}, ...
                                              'Selecione um arquivo de máscara espectral', 'MultiSelect', 'off');
            figure(app.MainApp)
            
            if Filename
                try
                    mask_FileRead(app, splitlines(fileread(fullfile(Filepath, Filename))));
                    set(app.task_maskFile, 'Enable', 1, 'Tooltip', {fullfile(Filepath, Filename)})

                catch ME
                    set(app.task_maskFile, 'Enable', 0, 'Tooltip', {''})
                    Misc_uialertCreation(app, 'error', getReport(ME))
                end
            end

        end

        % Selection changed function: task_Tree
        function task_TreeSelectionChanged(app, event)

            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
            ind2 = app.task_Tree.SelectedNodes.NodeData;

            task_ParametersValues(app, ind1, ind2)

            if ~isempty(app.task_scpiList.Items)
                task_ParametersEditables_ShowValues(app, ind1, ind2)
            end

            if app.taskFlag
                app.axesFlagType = 'SelectedNode';
                app.axesFlag     = 1;
            end

        end

        % Value changed function: task_scpiList
        function task_scpiListValueChanged(app, event)
            
            InitialGPS             = app.task_gpsList.Value;
            app.task_gpsList.Items = {'ID 0: Manual'};

            indGPSs = intersect(find(strcmp(app.scpiList.Family, 'GPS')), find(app.scpiList.Enable));
            for ii = 1:numel(indGPSs)
                Parameters = task_ParametersField(app, ii);

                switch app.scpiList.Type{ii}
                    case 'Serial'
                        Socket = Parameters.Port;

                    case 'TCPIP Socket'
                        Socket = sprintf('%s:%s', Parameters.IP, Parameters.Port);
                end

                app.task_gpsList.Items = [app.task_gpsList.Items, sprintf('ID %d: %s - %s', indGPSs(ii), app.scpiList.Name{indGPSs(ii)}, Socket)];
            end

            % Lista de GPSs (incluso instrumento que possua embarcado nele um GPS).
            indFind1 = find(app.instrInfo.Name == string(extractBetween(app.task_scpiList.Value, ': ', ' -')), 1);

            app.Task_SyncRef.Items = strsplit(app.instrInfo.SyncOptions{indFind1}, ',');

            if ~isempty(app.instrInfo.scpiGPS{indFind1})
                app.task_gpsList.Items = sort([app.task_gpsList.Items, app.task_scpiList.Value]);
                app.task_gpsList.Value = app.task_scpiList.Value;
                
            elseif find(strcmp(app.task_gpsList.Items, InitialGPS), 1)
                app.task_gpsList.Value = InitialGPS;
            end
            task_gpsListValueChanged(app)

            % Peculiaridades dos receptores.
            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
            for ii = 1:numel(app.taskList{ind1}.Band)
                task_ParametersEditables_SaveValues(app, ind1, ii)
            end

            if isempty(app.task_Tree.SelectedNodes); ind2 = 1;
            else;                                    ind2 = app.task_Tree.SelectedNodes.NodeData;
            end

            % Controla visibilidade dos elementos passíveis de alteração
            % das faixas a monitorar.
            task_ParametersEditables_Visibility(app)
            task_ParametersEditables_ShowValues(app, ind1, ind2)

        end

        % Button pushed function: task_scpiConnectivity
        function task_scpiConnectivityButtonPushed(app, event)
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Interpreter', 'html'); 
            d.Message = '<font style="font-size:12;">Em andamento...</font>';

            try
                idx1 = str2double(char(extractBetween(app.task_scpiList.Value, 'ID', ':')));
                
                Parameters    = task_ParametersField(app, idx1);
                instrSelected = struct('Type', app.scpiList.Type{idx1}, 'IP', Parameters.IP, 'Port', str2double(Parameters.Port));
                scpiNode      = connect_scpi(instrSelected, []);

                if ~isempty(scpiNode.UserData.IDN)
                    scpiName = string(extractBetween(app.task_scpiList.Value, ': ', ' -'));
                    idx2 = find(app.instrInfo.Name == scpiName, 1);

                    if contains(scpiNode.UserData.IDN, app.instrInfo.Tag{idx2})
                        msg = sprintf('Conectado ao <b>%s</b>.', scpiNode.UserData.IDN);
                        Misc_uialertCreation(app, 'info', msg)
                    else
                        msg = sprintf('Esperava-se instrumento da família <b>%s</b>, mas identificado <b>%s</b>.', scpiName, scpiNode.UserData.IDN);
                        Misc_uialertCreation(app, 'error', msg)
                    end
                else
                    msg = 'Não recebida resposta ao comando "*IDN?".';
                    Misc_uialertCreation(app, 'error', msg)
                end

                delete(scpiNode)
                clear scpiNode

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

            delete(d)

        end

        % Button pushed function: task_scpiListEdit
        function task_scpiListEditButtonPushed(app, event)
            
            if isempty(app.auxWin3)
                app.auxWin3 = winScpiEdit(app);
            else
                figure(app.auxWin3.UIFigure)
            end

        end

        % Value changed function: task_gpsList
        function task_gpsListValueChanged(app, event)

            if strcmp(app.task_gpsList.Value, 'ID 0: Manual')
                set(app.task_gpsGrid.Children, 'Enable', 1)                
                app.task_gpsRevisitTime.Enable = 0;

            else
                set(app.task_gpsGrid.Children, 'Enable', 0)                                
                app.task_gpsRevisitTime.Enable = 1;
                
                app.task_gpsRevisitTimeLabel.Enable  = 1;
                app.task_manualLatitudeLabel.Enable  = 1;
                app.task_manualLongitudeLabel.Enable = 1;
            end

        end

        % Button pushed function: task_gpsConnectivity
        function task_gpsConnectivityButtonPushed(app, event)
            
            d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Interpreter', 'html'); 
            d.Message = '<font style="font-size:12;">Em andamento...</font>';

            try
                if strcmp(app.task_scpiList.Value, app.task_gpsList.Value)
                    idx = str2double(char(extractBetween(app.task_scpiList.Value, 'ID', ':')));

                    Parameters    = task_ParametersField(app, idx);
                    instrSelected = struct('IP', Parameters.IP, 'Port', str2double(Parameters.Port));
                    scpiNode      = connect_scpi(instrSelected, []);
                    gps           = Task_gpsDecode(scpiNode);

                    delete(scpiNode)
                    clear scpiNode

                elseif strcmp(app.task_gpsList.Value, 'ID 0: Manual')
                    gps = struct('Status', 1, 'Latitude', app.task_manualLatitude.Value, 'Longitude', app.task_manualLongitude.Value, 'TimeStamp', []);

                else
                    RegularTask_gps_Aux1(app)
                    gps = app.gpsInstrument.UserData;
                    RegularTask_gps_Aux2(app)
                end
    
                if gps.Status
                    [City, Distance] = geo_FindCity(gps);

                    msg = sprintf('Coordenadas geográficas distam <b>%.1f km</b> da sede do município <b>%s</b>.', Distance, City);
                    
                    if ~isempty(gps.TimeStamp)
                        msg = sprintf('%s\n\nHorário: %s (UTC)', msg, datestr(gps.TimeStamp, 'dd/mm/yyyy HH:MM:SS'));
                    end
                    Misc_uialertCreation(app, 'info', msg)
                    
                    app.task_manualLatitude.Value  = round(gps.Latitude,  6);
                    app.task_manualLongitude.Value = round(gps.Longitude, 6);

                else
                    error('<b>Não recebida informação válida do instrumento acerca das coordenadas geográficas do local de monitoração.</b>\n%s', jsonencode(gps))
                end

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

            delete(d)

        end

        % Value changed function: task_Antenna
        function task_AntennaValueChanged(app, event)

            if strcmp(app.task_Antenna.Value, 'Unlisted (Directional)')
                app.task_Azimuth.Enable   = 1;
                app.task_Elevation.Enable = 1;
            else
                app.task_Azimuth.Enable   = 0;
                app.task_Elevation.Enable = 0;
            end

        end

        % Image clicked function: task_imgRefresh
        function task_RefreshButtonPushed(app, event)

            ind1 = find(strcmp(app.task_Name.Items, app.task_Name.Value), 1);
            ind2 = app.task_Tree.SelectedNodes.NodeData;

            app.taskList{ind1}.Band(ind2).EditedFlag = 0;
            task_ParametersEditables_ShowValues(app, ind1, ind2)

        end

        % Value changed function: task_StepWidth1
        function task_StepWidth1ValueChanged(app, event)

            span = (app.task_FreqStop.Value - app.task_FreqStart.Value)*1e+6;

            DataPoints = round(span/(1000*app.task_StepWidth1.Value) + 1);
            if DataPoints < app.task_DataPoints1.Limits(1)
                app.task_DataPoints1.Value = app.task_DataPoints1.Limits(1);
            elseif DataPoints > app.task_DataPoints1.Limits(2)
                app.task_DataPoints1.Value = fix(app.task_DataPoints1.Limits(2));
            else
                app.task_DataPoints1.Value = DataPoints;
            end

            app.task_StepWidth1.Value = (span/(app.task_DataPoints1.Value-1)) / 1000;

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrStepWidth  = 1000*app.task_StepWidth1.Value;
            app.taskList{ind1}.Band(ind2).instrDataPoints = app.task_DataPoints1.Value;

        end

        % Value changed function: task_StepWidth2
        function task_StepWidth2ValueChanged(app, event)

            app.task_imgRefresh.Visible = 1;

            span      = (app.task_FreqStop.Value-app.task_FreqStart.Value)*1e+6;
            stepValue = extractBefore(app.task_StepWidth2.Value, ' kHz');

            % DataPoints
            app.task_DataPoints2.Value = span/(1000*str2double(stepValue)) + 1;

            % Resolution, Selectivity
            rbwValues = [];
            rbwItems  = {};
            for ii = 1:3
                rbwValues = [rbwValues, app.EB500Map{app.task_StepWidth2.Value,ii}];
                rbwItems  = [rbwItems,  sprintf('%.3f kHz', rbwValues(ii)/1000)];
            end
            app.task_Resolution2.Items = rbwItems;

            rbwIndex = find(abs(rbwValues - app.task_Resolution.Value*1000) == min(abs(rbwValues - app.task_Resolution.Value*1000)));
            app.task_Resolution2.Value = app.task_Resolution2.Items{rbwIndex};
            app.task_Selectivity.Value = app.task_Selectivity.Items{rbwIndex};

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrStepWidth   = app.task_StepWidth2.Value;
            app.taskList{ind1}.Band(ind2).instrDataPoints  = app.task_DataPoints2.Value;
            app.taskList{ind1}.Band(ind2).instrSelectivity = app.task_Selectivity.Value;

            app.taskList{ind1}.Band(ind2).instrResolution_Items = app.task_Resolution2.Items;
            app.taskList{ind1}.Band(ind2).instrResolution       = app.task_Resolution2.Value;

        end

        % Value changed function: task_DataPoints1
        function task_DataPointsValueChanged(app, event)

            if ~fix(diff(app.task_DataPoints1.Limits))
                return
            end

            span = (app.task_FreqStop.Value - app.task_FreqStart.Value)*1e+6;
            app.task_StepWidth1.Value = (span/(app.task_DataPoints1.Value-1)) / 1000;

            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrDataPoints = app.task_DataPoints1.Value;
            app.taskList{ind1}.Band(ind2).instrStepWidth  = app.task_StepWidth1.Value*1000;

        end

        % Value changed function: task_Selectivity
        function task_SelectivityValueChanged(app, event)

            app.task_imgRefresh.Visible    = 1;
            app.task_Resolution2.Value = sprintf('%.3f kHz', app.EB500Map{app.task_StepWidth2.Value, app.task_Selectivity.Value}/1000);

            % Registra edição dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrSelectivity = app.task_Selectivity.Value;
            app.taskList{ind1}.Band(ind2).instrResolution  = app.task_Resolution2.Value;

        end

        % Value changed function: task_Resolution2
        function task_ResolutionValueChanged(app, event)

            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrResolution  = app.task_Resolution2.Value;

            if contains(app.task_scpiList.Value, 'EB500')
                if app.task_Selectivity.Visible
                    ind3 = find(strcmp(app.task_Resolution2.Items, app.task_Resolution2.Value));
                    app.task_Selectivity.Value = app.task_Selectivity.Items{ind3};
                end
                app.taskList{ind1}.Band(ind2).instrSelectivity = app.task_Selectivity.Value;
            end

        end

        % Value changed function: task_attMode
        function task_attModeValueChanged(app, event)

            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrAttMode = app.task_attMode.Value;

            % Visibilidade do valor do atenuador.
            if strcmp(app.task_attMode.Value, 'Auto')
                app.task_attFactorLabel.Visible = 0;
                app.task_attFactor.Visible      = 0;
            else
                app.task_attFactorLabel.Visible = 1;
                app.task_attFactor.Visible      = 1;
            end

        end

        % Value changed function: task_attFactor
        function task_attFactorValueChanged(app, event)

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrAttFactor = app.task_attFactor.Value;

        end

        % Value changed function: task_Preamp
        function task_PreampValueChanged(app, event)

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrPreamp = app.task_Preamp.Value;

        end

        % Value changed function: task_Detector2
        function task_DetectorValueChanged(app, event)

            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrDetector = app.task_Detector2.Value;

        end

        % Callback function: task_LogFile
        function task_InfoHyperlinkClicked(app, event)
            
        end

        % Button pushed function: task_Button1
        function task_Button1Pushed(app, event)
            
            switch app.taskFlag
                case 0                                                                  % Off (0) >> "Waterfall plot task" (1)
                    app.taskFlag = 1;
                    app.recFlag  = 0;
                    
                    app.axesFlagType = 'SelectedNode';
                    app.axesFlag     = 1;
                    
                    task_Layout(app)    
                    WaterfallPlotTask(app)

                case 1                                                                  % "Waterfall plot task" (1) >> Off (0)
                    if app.recFlag
                        if app.specMatrix.RecordSamples && (app.specMatrix.RecordSamples < app.specMatrix.HistoryDepth)
                            WaterfallPlotTask_REC(app)
                        end
                    end
                    app.taskFlag = 0;
                    app.axesFlag = 0;
                    app.recFlag  = 0;
                    
                    task_Layout(app)
            end

        end

        % Button pushed function: task_Button2
        function task_Button2Pushed(app, event)
            
            switch app.taskFlag
                case 0                                                                  % Off (0) >> "Regular task" (2)
                    app.taskFlag = 2;
                    app.axesFlag = 0;
                    app.recFlag  = 1;
                    
                    switch app.task_DurationUnit.Value
                        case 'min'; Duration = duration(0, app.task_Duration.Value, 0);
                        case 'hr';  Duration = duration(app.task_Duration.Value, 0, 0);
                    end
                    
                    task_Layout(app)
                    RegularTask(app, seconds(Duration))

                case 2                                                              % "Regular task" (2) >> Off (0)
                    app.taskFlag = 0;
                    app.axesFlag = 0;
                    app.recFlag  = 0;
                    
                    task_Layout(app)
            end

        end

        % Selection changed function: logger_Tree
        function logger_TreeSelectionChanged(app, event)

            try
                ind = app.logger_Tree.SelectedNodes.NodeData;
    
                app.logger_ThreadID.Value    = double(app.bandList(ind).ThreadID);
                app.logger_FreqStart.Value   = app.bandList(ind).FreqStart ./ 1e+6;
                app.logger_FreqStop.Value    = app.bandList(ind).FreqStop  ./ 1e+6;
                app.logger_Description.Value = app.bandList(ind).Description;
                app.logger_DataPoints.Value  = app.bandList(ind).DataPoints;
                app.logger_StepWidth.Value   = app.bandList(ind).StepWidth  ./ 1e+3;
                app.logger_Resolution.Value  = app.bandList(ind).Resolution ./ 1e+3;
                app.logger_LevelUnit.Value   = app.bandList(ind).LevelUnit;
                app.logger_Operation.Value   = app.bandList(ind).Operation;
                
                if app.taskFlag
                    app.axesFlagType = 'SelectedNode';
                    app.axesFlag     = 1;
                end
                drawnow
            catch
            end

        end

        % Callback function: logger_Tree
        function logger_TreeCheckedNodesChanged(app, event)
            
            if isempty(app.logger_Tree.CheckedNodes)
                app.tb_rec.Visible = 0;

                if app.recFlag
                    app.tb_rec.State = 0;
                    toolbarCallbacks_rec(app)
                end

            else
                CheckedNodes = [app.logger_Tree.CheckedNodes.NodeData];                
                for ii = 1:numel(app.bandList)
                    if ismember(ii, CheckedNodes)
                        app.bandList(ii).REC = app.bandList(ii).ThreadID;
                    else
                        app.bandList(ii).REC = [];
                    end
                end

                if app.taskFlag
                    app.tb_rec.Visible = 1;
                end
            end

        end

        % Button pushed function: logger_ButtonPlay
        function logger_ButtonPlayValueChanged(app, event)

            switch app.taskFlag
                case 0                                                                  % Off (0) >> "Logger task" (3)
                    app.taskFlag = 3;
                    app.axesFlag = 0;
                    app.recFlag  = 0;
                    
                    app.bandList = [];
                    delete(app.logger_Tree.Children)
                    
                    Plot_CloseFigure(app, [], struct('EventName', 'Close'))
                    LoggerTask(app)

                case 3                                                                  % "Logger task" (3) >> Off (0)
                    app.taskFlag = 0;
                    app.axesFlag = 0;
                    
                    if app.recFlag
                        app.tb_rec.State = 0;
                        toolbarCallbacks_rec(app)
                    end
                    drawnow nocallbacks
                    
                    task_Layout(app)
            end

        end

        % Value changed function: logger_NodeList
        function logger_NodeListValueChanged(app, event)

            idx = find(strcmp(app.rfeyeList.Name, app.logger_NodeList.Value(1:11)), 1);

            IPs = {app.rfeyeList.IP1{idx} app.rfeyeList.IP2{idx}};
            IPs(cellfun(@(x) isempty(x), IPs)) = [];

            app.logger_IP.Items   = IPs;

            if ~isempty(app.ftpObj)
                close(app.ftpObj)
                app.ftpObj = [];
                logger_ftpInfo(app, 'error', '')
                
                drawnow nocallbacks
            end

        end

        % Button pushed function: logger_winSCP
        function logger_winSCPValueChanged(app, event)
            
            idx  = find(strcmp(app.rfeyeList.Name, app.logger_NodeList.Value(1:11)), 1);
            Node = app.rfeyeList.rootPass{idx};

            winRFeyeCredential(app, app.logger_IP.Value, Node);
            drawnow nocallbacks

        end

        % Button pushed function: logger_Mask, logger_Script
        function logger_ScriptButtonPushed(app, event)
            
            try
                if isempty(app.logger_ScriptName.Value)
                    d = uiprogressdlg(app.MainApp, 'Indeterminate', 'on', 'Interpreter', 'html');
                    d.Message = '<font style="font-size:12;">Em andamento...</font>';

                    cd(app.ftpObj,   '/etc/drops/');
                    mget(app.ftpObj, 'rfeyed.cfg');

                    movefile('rfeyed.cfg', fullfile(app.RootFolder, 'Temp'));
                    app.logger_ScriptName.Value = 'rfeyed.cfg';

                    app.logger_MaskName.Items = logger_ScriptRead(app, fullfile(app.RootFolder, 'Temp', 'rfeyed.cfg'));
                    for ii = 1:numel(app.logger_MaskName.Items)
                        cd(app.ftpObj,   '/etc/drops/logger_masks/');
                        mget(app.ftpObj, app.logger_MaskName.Items{ii});
    
                        movefile(app.logger_MaskName.Items{ii}, fullfile(app.RootFolder, 'Temp'));
                    end

                    if ~isempty(app.logger_MaskName.Value)
                        app.tb_mask.Visible = 1;
                    end

                    delete(d)
                else
                    switch event.Source
                        case app.logger_Script
                            system(sprintf('notepad "%s" &', fullfile(app.RootFolder, 'Temp', 'rfeyed.cfg')));

                        case app.logger_Mask
                            system(sprintf('notepad "%s" &', fullfile(app.RootFolder, 'Temp', app.logger_MaskName.Value)));
                    end
                end

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

        end

        % Button pushed function: logger_EditRFeyeList
        function logger_rfeyeListEditButtonPushed(app, event)
            
            if isempty(app.auxWin4)
                app.auxWin4 = winRFeyeEdit(app);
            else
                figure(app.auxWin4.UIFigure)
            end

        end

        % Value changed function: task_IntegrationTime
        function task_IntegrationTimeValueChanged(app, event)

            % Registra edição de ao menos um dos valores que se aproximariam o máximo
            % possível dos parâmetros da tarefa.
            [ind1, ind2] = task_WarnVisibility(app);

            app.taskList{ind1}.Band(ind2).instrIntegrationTime = app.task_IntegrationTime.Value;
            
        end

        % Button pushed function: logger_Monitor
        function logger_MonitorButtonPushed(app, event)
            
            if isempty(app.auxWin5)
                app.auxWin5 = winMonitor(app);
            else
                figure(app.auxWin5.UIFigure)
            end

        end

        % Button pushed function: task_maskFile
        function task_maskFileButtonPushed(app, event)
            
            try
                msg = fileread(app.task_maskFile.Tooltip{1});
                Misc_uialertCreation(app, 'info', msg)

            catch ME
                Misc_uialertCreation(app, 'error', getReport(ME))
            end

        end

        % Button pushed function: logger_Conectivity
        function logger_ConectivityButtonPushed(app, event)
            
            d = uiprogressdlg(app.MainApp, "Message", '<font style="font-size:12;">Em andamento...</font>', "Indeterminate", "on", "Interpreter", "html");

            idx = find(strcmp(app.rfeyeList.Name, app.logger_NodeList.Value(1:11)), 1);
            LOG = connect_RFeye(app.logger_IP.Value, app.rfeyeList.webPass{idx});

            delete(d)

            if isempty(LOG.cgi.error)
                LOG = sprintf('%s\n\n<b>cgi-versions</b>\n%s\n\n<b>cgi-ifconfig</b>\n%s<b>cgi-apps</b>\n%s\n\n<b>cgi-status</b>\n%s', ...
                                  jsonencode(rmfield(LOG, 'cgi'), "PrettyPrint", true), ...
                                  LOG.cgi.versions, ...
                                  LOG.cgi.ifconfig, ...
                                  LOG.cgi.apps, ...
                                  LOG.cgi.status);

                Misc_uialertCreation(app, 'info', LOG)
            else                
                Misc_uialertCreation(app, 'error', sprintf('Identificado erro na tentativa de leitura de informações do sensor no seu ambiente <i>web</i>.\n\n%s', jsonencode(LOG.cgi.error, "PrettyPrint", true)))
            end

        end

        % Button pushed function: Button_AnatelDB
        function Button_AnatelDBPushed(app, event)
            
            if isempty(app.auxWin8)
                app.auxWin8 = winAnatelDB(app);
            else
                figure(app.auxWin8.UIFigure)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainApp = uifigure('Visible', 'off');
            app.MainApp.Name = 'appColeta R2022a';
            app.MainApp.Icon = 'home_Puzzle.png';
            app.MainApp.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.Grid1 = uigridlayout(app.MainApp);
            app.TabGroup = uitabgroup(app.Grid1);
            app.Tab1_home = uitab(app.TabGroup);
            app.home_Grid = uigridlayout(app.Tab1_home);
            app.home_img1 = uibutton(app.home_Grid, 'push');
            app.home_img1.ButtonPushedFcn = createCallbackFcn(app, @tool_HomeSelection, true);
            app.home_img1.Icon = 'Menu_Task2.png';
            app.home_img1.Text = {'Controle de analisadores de espectro,'; 'receptores e GPS'};
            app.home_img2 = uibutton(app.home_Grid, 'push');
            app.home_img2.ButtonPushedFcn = createCallbackFcn(app, @tool_HomeSelection, true);
            app.home_img2.Icon = 'edit_lr.png';
            app.home_img2.Text = {'Parametrização'; 'Tarefa SCPI'};
            app.home_img3 = uibutton(app.home_Grid, 'push');
            app.home_img3.ButtonPushedFcn = createCallbackFcn(app, @tool_HomeSelection, true);
            app.home_img3.Icon = 'Instr_CRFS_LR.png';
            app.home_img3.Text = {'Leitura de fluxo UDP'; 'gerado por Logger'};
            app.home_img4 = uibutton(app.home_Grid, 'push');
            app.home_img4.ButtonPushedFcn = createCallbackFcn(app, @tool_HomeSelection, true);
            app.home_img4.Icon = 'Home_icon4.png';
            app.home_img4.Text = {'API'; 'anateldb'};
            app.home_img5 = uibutton(app.home_Grid, 'push');
            app.home_img5.ButtonPushedFcn = createCallbackFcn(app, @tool_HomeSelection, true);
            app.home_img5.Icon = 'Home_icon5.png';
            app.home_img5.Text = {'Configurações'; 'gerais'};
            app.home_label1 = uilabel(app.home_Grid);
            app.home_label1.Interpreter = 'html';
            app.home_label1.Text = {'Conectividade, máscara espectral, monitorações '; 'Modo SCPI '};
            app.home_label2 = uilabel(app.home_Grid);
            app.home_label2.Interpreter = 'html';
            app.home_label2.Text = {'Criação/edição de <i>scripts</i> '; 'de tarefas regulares '};
            app.home_label3 = uilabel(app.home_Grid);
            app.home_label3.Interpreter = 'html';
            app.home_label3.Text = {'<i>Plot</i>, gravação, emissões '; 'Modo LOGGER '};
            app.home_label4 = uilabel(app.home_Grid);
            app.home_label4.Text = {'Consulta à base de dados com registros de estações '; '(Mosaico, STEL, SRD, AISWEB, GEOAIS e ICAO) '};
            app.home_label5 = uilabel(app.home_Grid);
            app.home_label5.Text = {'Atualização de componentes, '; 'resolução de imagens etc '};
            app.appColetaR2022av110Label = uilabel(app.home_Grid);
            app.appColetaR2022av110Label.Text = {'appColeta R2022a'; 'v. 1.10'};
            app.Tab2_task = uitab(app.TabGroup);
            app.task_Grid = uigridlayout(app.Tab2_task);
            app.task_LabelGrid = uigridlayout(app.task_Grid);
            app.task_Label1 = uilabel(app.task_LabelGrid);
            app.task_Label1.Text = 'SCPI';
            app.task_PlotVisibility = uiimage(app.task_LabelGrid);
            app.task_PlotVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_VisibilityClicked, true);
            app.task_PlotVisibility.ImageSource = 'play_Unview.png';
            app.task_Panel1 = uipanel(app.task_Grid);
            app.task_GridA = uigridlayout(app.task_Panel1);
            app.task_PanelA1 = uipanel(app.task_GridA);
            app.task_GridA4 = uigridlayout(app.task_PanelA1);
            app.task_ID = uieditfield(app.task_GridA4, 'numeric');
            app.task_ID.Limits = [1 255];
            app.task_ID.RoundFractionalValues = 'on';
            app.task_ID.ValueDisplayFormat = '%.0f';
            app.task_ID.Editable = 'off';
            app.task_ID.Value = 1;
            app.task_Description = uieditfield(app.task_GridA4, 'text');
            app.task_Description.Editable = 'off';
            app.task_FreqStart = uieditfield(app.task_GridA4, 'numeric');
            app.task_FreqStart.Limits = [-1 Inf];
            app.task_FreqStart.ValueDisplayFormat = '%.6f';
            app.task_FreqStart.Editable = 'off';
            app.task_FreqStart.Value = -1;
            app.task_FreqStop = uieditfield(app.task_GridA4, 'numeric');
            app.task_FreqStop.Limits = [-1 Inf];
            app.task_FreqStop.ValueDisplayFormat = '%.6f';
            app.task_FreqStop.Editable = 'off';
            app.task_FreqStop.Value = -1;
            app.task_DescriptionLabel = uilabel(app.task_GridA4);
            app.task_DescriptionLabel.Text = 'Descrição:';
            app.task_StepWidthLabel = uilabel(app.task_GridA4);
            app.task_StepWidthLabel.Text = 'Passo varredura (kHz):';
            app.task_StepWidth = uieditfield(app.task_GridA4, 'numeric');
            app.task_StepWidth.Limits = [0 Inf];
            app.task_StepWidth.ValueDisplayFormat = '%.3f';
            app.task_StepWidth.Editable = 'off';
            app.task_ResolutionLabel = uilabel(app.task_GridA4);
            app.task_ResolutionLabel.Text = 'Resolução (kHz):';
            app.task_Resolution = uieditfield(app.task_GridA4, 'numeric');
            app.task_Resolution.Limits = [0 Inf];
            app.task_Resolution.ValueDisplayFormat = '%.3f';
            app.task_Resolution.Editable = 'off';
            app.task_RevisitTimeLabel = uilabel(app.task_GridA4);
            app.task_RevisitTimeLabel.Text = 'Tempo revisita (seg):';
            app.task_RevisitTime = uieditfield(app.task_GridA4, 'numeric');
            app.task_RevisitTime.Limits = [0.001 Inf];
            app.task_RevisitTime.ValueDisplayFormat = '%.3f';
            app.task_RevisitTime.Editable = 'off';
            app.task_RevisitTime.Value = 1;
            app.task_IDLabel = uilabel(app.task_GridA4);
            app.task_IDLabel.Text = 'ThreadID:';
            app.task_FreqStartLabel = uilabel(app.task_GridA4);
            app.task_FreqStartLabel.Text = 'Frequência inicial (MHz):';
            app.task_FreqStopLabel = uilabel(app.task_GridA4);
            app.task_FreqStopLabel.Text = 'Frequência final (MHz):';
            app.task_IntegrationFactor = uieditfield(app.task_GridA4, 'numeric');
            app.task_IntegrationFactor.Limits = [1 Inf];
            app.task_IntegrationFactor.RoundFractionalValues = 'on';
            app.task_IntegrationFactor.ValueDisplayFormat = '%.0f';
            app.task_IntegrationFactor.Editable = 'off';
            app.task_IntegrationFactor.Value = 1;
            app.task_IntegrationFactorLabel = uilabel(app.task_GridA4);
            app.task_IntegrationFactorLabel.Text = 'Fator de integração:';
            app.task_TraceMode = uieditfield(app.task_GridA4, 'text');
            app.task_TraceMode.Editable = 'off';
            app.task_TraceMode.Value = 'ClearWrite';
            app.task_TraceModeLabel = uilabel(app.task_GridA4);
            app.task_TraceModeLabel.Text = 'Modo do traço:';
            app.task_Detector = uieditfield(app.task_GridA4, 'text');
            app.task_Detector.Editable = 'off';
            app.task_Detector.Value = 'Sample';
            app.task_DetectorLabel = uilabel(app.task_GridA4);
            app.task_DetectorLabel.Text = 'Detector:';
            app.task_RFMode = uieditfield(app.task_GridA4, 'text');
            app.task_RFMode.Editable = 'off';
            app.task_RFMode.Value = 'High Sensitivity';
            app.task_RFModeLabel = uilabel(app.task_GridA4);
            app.task_RFModeLabel.Text = 'Modo de RF:';
            app.task_LevelUnit = uieditfield(app.task_GridA4, 'text');
            app.task_LevelUnit.Editable = 'off';
            app.task_LevelUnit.Value = 'dBm';
            app.task_LevelUnitLabel = uilabel(app.task_GridA4);
            app.task_LevelUnitLabel.Text = 'Unidade:';
            app.task_BandLabel = uilabel(app.task_GridA);
            app.task_BandLabel.Text = 'Faixa de frequência selecionada:';
            app.task_GridA2 = uigridlayout(app.task_GridA);
            app.task_Type = uidropdown(app.task_GridA2);
            app.task_Type.Items = {'Monitoração espectral', 'Monitoração espectral - Drive-test', 'Monitoração espectral - Rompimento de máscara espectral'};
            app.task_Type.ValueChangedFcn = createCallbackFcn(app, @task_TypeValueChanged, true);
            app.task_Type.Tag = 'task_Editable';
            app.task_Type.Value = 'Monitoração espectral';
            app.task_TypeLabel = uilabel(app.task_GridA2);
            app.task_TypeLabel.Text = 'Tipo:';
            app.task_addMaskFile = uibutton(app.task_GridA2, 'push');
            app.task_addMaskFile.ButtonPushedFcn = createCallbackFcn(app, @task_addMaskFileButtonPushed, true);
            app.task_addMaskFile.Tag = 'task_Editable';
            app.task_addMaskFile.Icon = 'winFiscaliza_OpenFile.png';
            app.task_addMaskFile.Tooltip = {'Máscara espectral'};
            app.task_addMaskFile.Text = '';
            app.task_maskFile = uibutton(app.task_GridA2, 'push');
            app.task_maskFile.ButtonPushedFcn = createCallbackFcn(app, @task_maskFileButtonPushed, true);
            app.task_maskFile.Icon = 'winMaskDraw.png';
            app.task_maskFile.Enable = 'off';
            app.task_maskFile.Tooltip = {''};
            app.task_maskFile.Text = '';
            app.task_TreeLabel = uilabel(app.task_GridA);
            app.task_TreeLabel.Text = 'Faixa(s) de frequência a monitorar:';
            app.task_Tree = uitree(app.task_GridA);
            app.task_Tree.SelectionChangedFcn = createCallbackFcn(app, @task_TreeSelectionChanged, true);
            app.task_GridA3 = uigridlayout(app.task_GridA);
            app.task_Duration = uieditfield(app.task_GridA3, 'numeric');
            app.task_Duration.Limits = [0 Inf];
            app.task_Duration.ValueDisplayFormat = '%.3f';
            app.task_Duration.Tag = 'task_Editable';
            app.task_Duration.Value = 10;
            app.task_DurationLabel = uilabel(app.task_GridA3);
            app.task_DurationLabel.Text = 'Duração:';
            app.task_DurationUnit = uidropdown(app.task_GridA3);
            app.task_DurationUnit.Items = {'min', 'hr'};
            app.task_DurationUnit.Tag = 'task_Editable';
            app.task_DurationUnit.Value = 'min';
            app.task_BitsPerPoint = uieditfield(app.task_GridA3, 'text');
            app.task_BitsPerPoint.Editable = 'off';
            app.task_BitsPerPoint.Value = '8 bits';
            app.task_BitsPerPointLabel = uilabel(app.task_GridA3);
            app.task_BitsPerPointLabel.Text = 'Codificação:';
            app.task_Name = uidropdown(app.task_GridA);
            app.task_Name.Items = {};
            app.task_Name.ValueChangedFcn = createCallbackFcn(app, @task_TaskSelectionChanged, true);
            app.task_Name.Tag = 'task_Editable';
            app.task_Name.Value = {};
            app.task_NameLabel = uilabel(app.task_GridA);
            app.task_NameLabel.Text = 'Nome:';
            app.task_Label2 = uilabel(app.task_Grid);
            app.task_Label2.Text = 'ASPECTOS GERAIS';
            app.task_Panel2 = uipanel(app.task_Grid);
            app.task_GridB = uigridlayout(app.task_Panel2);
            app.task_scpiListGrid = uigridlayout(app.task_GridB);
            app.task_scpiList = uidropdown(app.task_scpiListGrid);
            app.task_scpiList.Items = {};
            app.task_scpiList.ValueChangedFcn = createCallbackFcn(app, @task_scpiListValueChanged, true);
            app.task_scpiList.Tag = 'task_Editable';
            app.task_scpiList.Value = {};
            app.task_scpiListLabel = uilabel(app.task_scpiListGrid);
            app.task_scpiListLabel.Text = 'Receptor:';
            app.task_scpiConnectivity = uibutton(app.task_scpiListGrid, 'push');
            app.task_scpiConnectivity.ButtonPushedFcn = createCallbackFcn(app, @task_scpiConnectivityButtonPushed, true);
            app.task_scpiConnectivity.Tag = 'task_Editable';
            app.task_scpiConnectivity.Icon = 'connectivity.png';
            app.task_scpiConnectivity.Tooltip = {'Teste de conectividade'};
            app.task_scpiConnectivity.Text = '';
            app.task_gpsListGrid = uigridlayout(app.task_GridB);
            app.task_gpsListLabel = uilabel(app.task_gpsListGrid);
            app.task_gpsListLabel.Text = 'GPS:';
            app.task_gpsList = uidropdown(app.task_gpsListGrid);
            app.task_gpsList.Items = {'ID 0: Manual'};
            app.task_gpsList.ValueChangedFcn = createCallbackFcn(app, @task_gpsListValueChanged, true);
            app.task_gpsList.Tag = 'task_Editable';
            app.task_gpsList.Value = 'ID 0: Manual';
            app.task_gpsConnectivity = uibutton(app.task_gpsListGrid, 'push');
            app.task_gpsConnectivity.ButtonPushedFcn = createCallbackFcn(app, @task_gpsConnectivityButtonPushed, true);
            app.task_gpsConnectivity.Tag = 'task_Editable';
            app.task_gpsConnectivity.Icon = 'connectivity.png';
            app.task_gpsConnectivity.Tooltip = {'Teste de conectividade'};
            app.task_gpsConnectivity.Text = '';
            app.task_gpsPanel = uipanel(app.task_GridB);
            app.task_gpsGrid = uigridlayout(app.task_gpsPanel);
            app.task_gpsRevisitTimeLabel = uilabel(app.task_gpsGrid);
            app.task_gpsRevisitTimeLabel.Text = 'Tempo revisita (seg):';
            app.task_gpsRevisitTime = uieditfield(app.task_gpsGrid, 'numeric');
            app.task_gpsRevisitTime.Limits = [1 Inf];
            app.task_gpsRevisitTime.RoundFractionalValues = 'on';
            app.task_gpsRevisitTime.ValueDisplayFormat = '%.0f';
            app.task_gpsRevisitTime.Tag = 'task_Editable';
            app.task_gpsRevisitTime.Value = 10;
            app.task_manualLatitudeLabel = uilabel(app.task_gpsGrid);
            app.task_manualLatitudeLabel.Text = 'Latitude (graus):';
            app.task_manualLatitude = uieditfield(app.task_gpsGrid, 'numeric');
            app.task_manualLatitude.ValueDisplayFormat = '%.6f';
            app.task_manualLatitude.Tag = 'task_Editable';
            app.task_manualLatitude.Value = -1;
            app.task_manualLongitudeLabel = uilabel(app.task_gpsGrid);
            app.task_manualLongitudeLabel.Text = 'Longitude (graus):';
            app.task_manualLongitude = uieditfield(app.task_gpsGrid, 'numeric');
            app.task_manualLongitude.ValueDisplayFormat = '%.6f';
            app.task_manualLongitude.Tag = 'task_Editable';
            app.task_manualLongitude.Value = -1;
            app.task_AntennaLabel = uilabel(app.task_GridB);
            app.task_AntennaLabel.Text = 'Antena:';
            app.task_Antenna = uidropdown(app.task_GridB);
            app.task_Antenna.Items = {'', 'CRFS Low Band (10 MHz - 1.2 GHz)', 'CRFS High Band (750 MHz - 6 GHz)', 'Rohde & Schwarz ADDx07 (EB500 GUI Auto)', 'Rohde & Schwarz ADD107 (20 MHz - 1.3 GHz)', 'Rohde & Schwarz ADD207 (600 MHz - 6 GHz)', 'Telescopic', 'Unlisted (Omni)', 'Unlisted (Directional)'};
            app.task_Antenna.ValueChangedFcn = createCallbackFcn(app, @task_AntennaValueChanged, true);
            app.task_Antenna.Tag = 'task_Editable';
            app.task_Antenna.Value = '';
            app.task_antennaPanel = uipanel(app.task_GridB);
            app.task_antennaGrid = uigridlayout(app.task_antennaPanel);
            app.task_Height = uieditfield(app.task_antennaGrid, 'numeric');
            app.task_Height.Limits = [0 127];
            app.task_Height.RoundFractionalValues = 'on';
            app.task_Height.ValueDisplayFormat = '%.0f';
            app.task_Height.Tag = 'task_Editable';
            app.task_Height.Value = 2;
            app.task_HeightLabel = uilabel(app.task_antennaGrid);
            app.task_HeightLabel.Text = 'Altura instalação (m):';
            app.task_Azimuth = uieditfield(app.task_antennaGrid, 'numeric');
            app.task_Azimuth.Limits = [0 359];
            app.task_Azimuth.RoundFractionalValues = 'on';
            app.task_Azimuth.ValueDisplayFormat = '%.0f';
            app.task_Azimuth.Tag = 'task_Editable';
            app.task_Azimuth.Enable = 'off';
            app.task_AzimuthLabel = uilabel(app.task_antennaGrid);
            app.task_AzimuthLabel.Text = 'Azimute (graus):';
            app.task_Elevation = uieditfield(app.task_antennaGrid, 'numeric');
            app.task_Elevation.Limits = [0 90];
            app.task_Elevation.RoundFractionalValues = 'on';
            app.task_Elevation.ValueDisplayFormat = '%.0f';
            app.task_Elevation.Tag = 'task_Editable';
            app.task_Elevation.Enable = 'off';
            app.task_ElevationLabel = uilabel(app.task_antennaGrid);
            app.task_ElevationLabel.Text = 'Elevação (graus):';
            app.task_GridB2 = uigridlayout(app.task_GridB);
            app.task_PanelLabel = uilabel(app.task_GridB2);
            app.task_PanelLabel.Text = 'Parâmetros editáveis relacionados à faixa de frequência e ao receptor selecionados:';
            app.task_imgRefresh = uiimage(app.task_GridB2);
            app.task_imgRefresh.ImageClickedFcn = createCallbackFcn(app, @task_RefreshButtonPushed, true);
            app.task_imgRefresh.Visible = 'off';
            app.task_imgRefresh.ImageSource = 'General_Refresh.png';
            app.task_Panel_22 = uipanel(app.task_GridB);
            app.task_Grid22 = uigridlayout(app.task_Panel_22);
            app.task_StepWidth1 = uieditfield(app.task_Grid22, 'numeric');
            app.task_StepWidth1.Limits = [0 Inf];
            app.task_StepWidth1.ValueDisplayFormat = '%.3f';
            app.task_StepWidth1.ValueChangedFcn = createCallbackFcn(app, @task_StepWidth1ValueChanged, true);
            app.task_StepWidth1.Tag = 'task_Set1';
            app.task_StepWidth1.Editable = 'off';
            app.task_StepWidth1.Enable = 'off';
            app.task_StepWidth2 = uidropdown(app.task_Grid22);
            app.task_StepWidth2.Items = {};
            app.task_StepWidth2.ValueChangedFcn = createCallbackFcn(app, @task_StepWidth2ValueChanged, true);
            app.task_StepWidth2.Tag = 'task_Set2';
            app.task_StepWidth2.Enable = 'off';
            app.task_StepWidth2.Value = {};
            app.task_StepWidth2Label = uilabel(app.task_Grid22);
            app.task_StepWidth2Label.Enable = 'off';
            app.task_StepWidth2Label.Text = 'Passo varredura (kHz):';
            app.task_DataPoints1 = uispinner(app.task_Grid22);
            app.task_DataPoints1.Step = 100;
            app.task_DataPoints1.Limits = [101 32001];
            app.task_DataPoints1.RoundFractionalValues = 'on';
            app.task_DataPoints1.ValueDisplayFormat = '%.0f';
            app.task_DataPoints1.ValueChangedFcn = createCallbackFcn(app, @task_DataPointsValueChanged, true);
            app.task_DataPoints1.Tag = 'task_Set1';
            app.task_DataPoints1.Enable = 'off';
            app.task_DataPoints1.Value = 101;
            app.task_DataPoints2 = uieditfield(app.task_Grid22, 'numeric');
            app.task_DataPoints2.ValueDisplayFormat = '%.0f';
            app.task_DataPoints2.Tag = 'task_Set2';
            app.task_DataPoints2.Editable = 'off';
            app.task_DataPoints2.Enable = 'off';
            app.task_DataPointsLabel = uilabel(app.task_Grid22);
            app.task_DataPointsLabel.Enable = 'off';
            app.task_DataPointsLabel.Text = 'Pontos por traço:';
            app.task_Selectivity = uidropdown(app.task_Grid22);
            app.task_Selectivity.Items = {'Normal', 'Narrow', 'Sharp'};
            app.task_Selectivity.ValueChangedFcn = createCallbackFcn(app, @task_SelectivityValueChanged, true);
            app.task_Selectivity.Tag = 'task_Set2';
            app.task_Selectivity.Enable = 'off';
            app.task_Selectivity.Visible = 'off';
            app.task_Selectivity.Value = 'Normal';
            app.task_SelectivityLabel = uilabel(app.task_Grid22);
            app.task_SelectivityLabel.Enable = 'off';
            app.task_SelectivityLabel.Visible = 'off';
            app.task_SelectivityLabel.Text = 'Seletividade FFT:';
            app.task_Resolution2 = uidropdown(app.task_Grid22);
            app.task_Resolution2.Items = {};
            app.task_Resolution2.ValueChangedFcn = createCallbackFcn(app, @task_ResolutionValueChanged, true);
            app.task_Resolution2.Enable = 'off';
            app.task_Resolution2.Value = {};
            app.task_Resolution2Label = uilabel(app.task_Grid22);
            app.task_Resolution2Label.Enable = 'off';
            app.task_Resolution2Label.Text = 'Resolução (kHz):';
            app.task_attMode = uidropdown(app.task_Grid22);
            app.task_attMode.Items = {'Manual', 'Auto'};
            app.task_attMode.ValueChangedFcn = createCallbackFcn(app, @task_attModeValueChanged, true);
            app.task_attMode.Enable = 'off';
            app.task_attMode.Value = 'Manual';
            app.task_attModeLabel = uilabel(app.task_Grid22);
            app.task_attModeLabel.Enable = 'off';
            app.task_attModeLabel.Text = 'Modo do atenuador:';
            app.task_attFactor = uidropdown(app.task_Grid22);
            app.task_attFactor.Items = {};
            app.task_attFactor.ValueChangedFcn = createCallbackFcn(app, @task_attFactorValueChanged, true);
            app.task_attFactor.Enable = 'off';
            app.task_attFactor.Value = {};
            app.task_attFactorLabel = uilabel(app.task_Grid22);
            app.task_attFactorLabel.Enable = 'off';
            app.task_attFactorLabel.Text = 'Atenuador:';
            app.task_Preamp = uidropdown(app.task_Grid22);
            app.task_Preamp.Items = {'On', 'Off'};
            app.task_Preamp.ValueChangedFcn = createCallbackFcn(app, @task_PreampValueChanged, true);
            app.task_Preamp.Enable = 'off';
            app.task_Preamp.Value = 'Off';
            app.task_PreampLabel = uilabel(app.task_Grid22);
            app.task_PreampLabel.Enable = 'off';
            app.task_PreampLabel.Text = 'Pré-amplificador:';
            app.task_Detector2 = uidropdown(app.task_Grid22);
            app.task_Detector2.Items = {'Auto Peak', 'Average', 'Negative Peak', 'Positive Peak', 'Quasi Peak', 'RMS', 'Sample'};
            app.task_Detector2.ValueChangedFcn = createCallbackFcn(app, @task_DetectorValueChanged, true);
            app.task_Detector2.Enable = 'off';
            app.task_Detector2.Value = 'Positive Peak';
            app.task_Detector2Label = uilabel(app.task_Grid22);
            app.task_Detector2Label.Enable = 'off';
            app.task_Detector2Label.Text = 'Detector:';
            app.task_LevelUnit2 = uieditfield(app.task_Grid22, 'text');
            app.task_LevelUnit2.Editable = 'off';
            app.task_LevelUnit2.Enable = 'off';
            app.task_LevelUnit2.Value = 'dBm';
            app.task_LevelUnit2Label = uilabel(app.task_Grid22);
            app.task_LevelUnit2Label.Enable = 'off';
            app.task_LevelUnit2Label.Text = 'Unidade:';
            app.task_IntegrationTime = uieditfield(app.task_Grid22, 'numeric');
            app.task_IntegrationTime.Limits = [0 10000];
            app.task_IntegrationTime.RoundFractionalValues = 'on';
            app.task_IntegrationTime.ValueDisplayFormat = '%.0f';
            app.task_IntegrationTime.ValueChangedFcn = createCallbackFcn(app, @task_IntegrationTimeValueChanged, true);
            app.task_IntegrationTime.Enable = 'off';
            app.task_IntegrationTime.Visible = 'off';
            app.task_IntegrationTime.Value = 100;
            app.task_IntegrationTimeLabel = uilabel(app.task_Grid22);
            app.task_IntegrationTimeLabel.Enable = 'off';
            app.task_IntegrationTimeLabel.Visible = 'off';
            app.task_IntegrationTimeLabel.Text = 'Tempo integração (ms):';
            app.Task_Label9_6 = uilabel(app.task_GridB);
            app.Task_Label9_6.Text = 'Outros aspectos:';
            app.task_Panel21 = uipanel(app.task_GridB);
            app.task_Grid21 = uigridlayout(app.task_Panel21);
            app.Task_ResetCommandLabel = uilabel(app.task_Grid21);
            app.Task_ResetCommandLabel.Enable = 'off';
            app.Task_ResetCommandLabel.Text = 'Reset:';
            app.Task_ResetCommand = uidropdown(app.task_Grid21);
            app.Task_ResetCommand.Items = {'On', 'Off'};
            app.Task_ResetCommand.Tag = 'task_Editable';
            app.Task_ResetCommand.Enable = 'off';
            app.Task_ResetCommand.Value = 'On';
            app.Task_SyncRefLabel = uilabel(app.task_Grid21);
            app.Task_SyncRefLabel.Enable = 'off';
            app.Task_SyncRefLabel.Text = 'Sincronismo:';
            app.Task_SyncRef = uidropdown(app.task_Grid21);
            app.Task_SyncRef.Items = {'Single Sweep', 'Continuous Sweep', 'Streaming'};
            app.Task_SyncRef.Tag = 'task_Editable';
            app.Task_SyncRef.Enable = 'off';
            app.Task_SyncRef.Value = 'Single Sweep';
            app.task_ButtonGrid = uigridlayout(app.task_Grid);
            app.task_info = uibutton(app.task_ButtonGrid, 'push');
            app.task_info.ButtonPushedFcn = createCallbackFcn(app, @tool_Button3Pushed, true);
            app.task_info.Icon = 'toolbar_info.png';
            app.task_info.Text = '';
            app.Separator1 = uiimage(app.task_ButtonGrid);
            app.Separator1.ImageSource = 'Line.png';
            app.task_NameEdit = uibutton(app.task_ButtonGrid, 'push');
            app.task_NameEdit.ButtonPushedFcn = createCallbackFcn(app, @task_TaskEditButtonPushed, true);
            app.task_NameEdit.Tag = 'task_Editable';
            app.task_NameEdit.Icon = 'General_edit.png';
            app.task_NameEdit.Tooltip = {'Parametrização'; 'Tarefa SCPI'};
            app.task_NameEdit.Text = '';
            app.task_scpiListEdit = uibutton(app.task_ButtonGrid, 'push');
            app.task_scpiListEdit.ButtonPushedFcn = createCallbackFcn(app, @task_scpiListEditButtonPushed, true);
            app.task_scpiListEdit.Tag = 'task_Editable';
            app.task_scpiListEdit.Icon = 'Menu_Connect.png';
            app.task_scpiListEdit.Tooltip = {'Editar lista de instrumentos '; '(Analisador de espectro, receptor e GPS)'};
            app.task_scpiListEdit.Text = '';
            app.task_LogFile = uihyperlink(app.task_ButtonGrid);
            app.task_LogFile.HyperlinkClickedFcn = createCallbackFcn(app, @task_InfoHyperlinkClicked, true);
            app.task_LogFile.Text = '';
            app.task_Button1 = uibutton(app.task_ButtonGrid, 'push');
            app.task_Button1.ButtonPushedFcn = createCallbackFcn(app, @task_Button1Pushed, true);
            app.task_Button1.Icon = 'General_play.png';
            app.task_Button1.Enable = 'off';
            app.task_Button1.Text = 'Tarefa plot';
            app.task_Button2 = uibutton(app.task_ButtonGrid, 'push');
            app.task_Button2.ButtonPushedFcn = createCallbackFcn(app, @task_Button2Pushed, true);
            app.task_Button2.Icon = 'General_play.png';
            app.task_Button2.Enable = 'off';
            app.task_Button2.Text = 'Tarefa regular';
            app.Tab3_logger = uitab(app.TabGroup);
            app.logger_Grid1 = uigridlayout(app.Tab3_logger);
            app.logger_Grid2 = uigridlayout(app.logger_Grid1);
            app.logger_Label1 = uilabel(app.logger_Grid2);
            app.logger_Label1.Text = 'LOGGER';
            app.logger_PlotVisibility = uiimage(app.logger_Grid2);
            app.logger_PlotVisibility.ImageClickedFcn = createCallbackFcn(app, @tool_VisibilityClicked, true);
            app.logger_PlotVisibility.ImageSource = 'play_Unview.png';
            app.logger_Panel1 = uipanel(app.logger_Grid1);
            app.logger_Panel1_Grid = uigridlayout(app.logger_Panel1);
            app.logger_Receiver_Grid = uigridlayout(app.logger_Panel1_Grid);
            app.logger_NodeListLabel = uilabel(app.logger_Receiver_Grid);
            app.logger_NodeListLabel.Text = 'Receptor:';
            app.logger_NodeList = uidropdown(app.logger_Receiver_Grid);
            app.logger_NodeList.Items = {};
            app.logger_NodeList.ValueChangedFcn = createCallbackFcn(app, @logger_NodeListValueChanged, true);
            app.logger_NodeList.Value = {};
            app.logger_Conectivity = uibutton(app.logger_Receiver_Grid, 'push');
            app.logger_Conectivity.ButtonPushedFcn = createCallbackFcn(app, @logger_ConectivityButtonPushed, true);
            app.logger_Conectivity.Icon = 'connectivity.png';
            app.logger_Conectivity.Tooltip = {'Teste de conectividade'};
            app.logger_Conectivity.Text = '';
            app.logger_Panel1a = uipanel(app.logger_Panel1_Grid);
            app.logger_Panel1a_Grid = uigridlayout(app.logger_Panel1a);
            app.logger_IPLabel = uilabel(app.logger_Panel1a_Grid);
            app.logger_IPLabel.Text = 'Endereço IP:';
            app.logger_IP = uidropdown(app.logger_Panel1a_Grid);
            app.logger_IP.Items = {};
            app.logger_IP.Value = {};
            app.logger_PortLabel = uilabel(app.logger_Panel1a_Grid);
            app.logger_PortLabel.Text = 'Porta:';
            app.logger_Port = uieditfield(app.logger_Panel1a_Grid, 'numeric');
            app.logger_Port.Limits = [0 65535];
            app.logger_Port.RoundFractionalValues = 'on';
            app.logger_Port.ValueDisplayFormat = '%.0f';
            app.logger_Port.Value = 8910;
            app.logger_udpDatagrams_Grid = uigridlayout(app.logger_Panel1_Grid);
            app.logger_AvailableDatagramsLabel = uilabel(app.logger_udpDatagrams_Grid);
            app.logger_AvailableDatagramsLabel.Visible = 'off';
            app.logger_AvailableDatagramsLabel.Text = 'Datagramas disponíveis leitura:';
            app.logger_AvailableDatagrams = uieditfield(app.logger_udpDatagrams_Grid, 'numeric');
            app.logger_AvailableDatagrams.Editable = 'off';
            app.logger_AvailableDatagrams.Visible = 'off';
            app.logger_PauseLabel = uilabel(app.logger_udpDatagrams_Grid);
            app.logger_PauseLabel.Visible = 'off';
            app.logger_PauseLabel.Text = 'Pausa entre leitura de datagramas (ms):';
            app.logger_Pause = uispinner(app.logger_udpDatagrams_Grid);
            app.logger_Pause.Step = 50;
            app.logger_Pause.Limits = [0 1000];
            app.logger_Pause.RoundFractionalValues = 'on';
            app.logger_Pause.ValueDisplayFormat = '%.0f';
            app.logger_Pause.Visible = 'off';
            app.logger_Pause.Value = 100;
            app.logger_Label2 = uilabel(app.logger_Grid1);
            app.logger_Label2.Text = 'ASPECTOS GERAIS';
            app.loger_Panel2 = uipanel(app.logger_Grid1);
            app.logger_Panel2_Grid = uigridlayout(app.loger_Panel2);
            app.logger_Sublabel1 = uilabel(app.logger_Panel2_Grid);
            app.logger_Sublabel1.Text = 'Faixa(s) de frequência em monitoração:';
            app.logger_Tree = uitree(app.logger_Panel2_Grid, 'checkbox');
            app.logger_Tree.SelectionChangedFcn = createCallbackFcn(app, @logger_TreeSelectionChanged, true);
            app.logger_Tree.Enable = 'off';
            % Assign Checked Nodes
            app.logger_Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @logger_TreeCheckedNodesChanged, true);
            app.logger_Sublabel2 = uilabel(app.logger_Panel2_Grid);
            app.logger_Sublabel2.Text = 'Arquivos relacionados à monitoração:';
            app.logger_Panel3 = uipanel(app.logger_Panel2_Grid);
            app.logger_Panel3_Grid = uigridlayout(app.logger_Panel3);
            app.logger_ScriptNameLabel = uilabel(app.logger_Panel3_Grid);
            app.logger_ScriptNameLabel.Text = 'Script:';
            app.logger_ScriptName = uieditfield(app.logger_Panel3_Grid, 'text');
            app.logger_ScriptName.Editable = 'off';
            app.logger_ScriptName.Enable = 'off';
            app.logger_MaskNameLabel = uilabel(app.logger_Panel3_Grid);
            app.logger_MaskNameLabel.Text = 'Máscara espectral:';
            app.logger_MaskName = uidropdown(app.logger_Panel3_Grid);
            app.logger_MaskName.Items = {};
            app.logger_MaskName.Enable = 'off';
            app.logger_MaskName.Value = {};
            app.logger_Script = uibutton(app.logger_Panel3_Grid, 'push');
            app.logger_Script.ButtonPushedFcn = createCallbackFcn(app, @logger_ScriptButtonPushed, true);
            app.logger_Script.Icon = 'Script.png';
            app.logger_Script.Enable = 'off';
            app.logger_Script.Tooltip = {'Script'};
            app.logger_Script.Text = '';
            app.logger_Mask = uibutton(app.logger_Panel3_Grid, 'push');
            app.logger_Mask.ButtonPushedFcn = createCallbackFcn(app, @logger_ScriptButtonPushed, true);
            app.logger_Mask.Icon = 'winMaskDraw.png';
            app.logger_Mask.Enable = 'off';
            app.logger_Mask.Tooltip = {'Máscara espectral'};
            app.logger_Mask.Text = '';
            app.logger_Sublabel3 = uilabel(app.logger_Panel2_Grid);
            app.logger_Sublabel3.Text = 'Faixa de frequência selecionada:';
            app.logger_Panel4 = uipanel(app.logger_Panel2_Grid);
            app.logger_Panel4_Grid = uigridlayout(app.logger_Panel4);
            app.logger_Description = uieditfield(app.logger_Panel4_Grid, 'text');
            app.logger_Description.Editable = 'off';
            app.logger_DescriptionLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_DescriptionLabel.Text = 'Descrição:';
            app.logger_Resolution = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_Resolution.ValueDisplayFormat = '%.3f';
            app.logger_Resolution.Editable = 'off';
            app.logger_Resolution.Value = -1;
            app.logger_ResolutionLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_ResolutionLabel.Text = 'Resolução (kHz):';
            app.logger_LevelUnit = uieditfield(app.logger_Panel4_Grid, 'text');
            app.logger_LevelUnit.Editable = 'off';
            app.logger_LevelUnitLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_LevelUnitLabel.Text = 'Unidade:';
            app.logger_ThreadIDLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_ThreadIDLabel.Text = 'ThreadID:';
            app.logger_ThreadID = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_ThreadID.Limits = [-1 Inf];
            app.logger_ThreadID.RoundFractionalValues = 'on';
            app.logger_ThreadID.ValueDisplayFormat = '%.0f';
            app.logger_ThreadID.Editable = 'off';
            app.logger_ThreadID.Value = -1;
            app.logger_FreqStartLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_FreqStartLabel.Text = 'Frequência inicial (MHz):';
            app.logger_FreqStart = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_FreqStart.Limits = [-1 Inf];
            app.logger_FreqStart.ValueDisplayFormat = '%.6f';
            app.logger_FreqStart.Editable = 'off';
            app.logger_FreqStart.Value = -1;
            app.logger_FreqStop = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_FreqStop.Limits = [-1 Inf];
            app.logger_FreqStop.ValueDisplayFormat = '%.6f';
            app.logger_FreqStop.Editable = 'off';
            app.logger_FreqStop.Tooltip = {'Editar lista de instrumentos'};
            app.logger_FreqStop.Value = -1;
            app.logger_FreqStopLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_FreqStopLabel.Text = 'Frequência final (MHz):';
            app.logger_StepWidthLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_StepWidthLabel.Text = 'Passo varredura (kHz):';
            app.logger_StepWidth = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_StepWidth.ValueDisplayFormat = '%.3f';
            app.logger_StepWidth.Editable = 'off';
            app.logger_StepWidth.Tooltip = {'Editar lista de instrumentos'};
            app.logger_StepWidth.Value = -1;
            app.logger_OperationLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_OperationLabel.Text = 'Operação:';
            app.logger_Operation = uieditfield(app.logger_Panel4_Grid, 'text');
            app.logger_Operation.Editable = 'off';
            app.logger_DataPointsLabel = uilabel(app.logger_Panel4_Grid);
            app.logger_DataPointsLabel.Text = 'Pontos por traço:';
            app.logger_DataPoints = uieditfield(app.logger_Panel4_Grid, 'numeric');
            app.logger_DataPoints.Limits = [-1 Inf];
            app.logger_DataPoints.ValueDisplayFormat = '%.0f';
            app.logger_DataPoints.Editable = 'off';
            app.logger_DataPoints.Value = -1;
            app.logger_TextInfoLabel = uilabel(app.logger_Panel2_Grid);
            app.logger_TextInfoLabel.Text = 'Informações textuais:';
            app.logger_TextInfo = uitextarea(app.logger_Panel2_Grid);
            app.logger_TextInfo.Editable = 'off';
            app.logger_TextInfo.Enable = 'off';
            app.logger_toolbar = uigridlayout(app.logger_Grid1);
            app.logger_info = uibutton(app.logger_toolbar, 'push');
            app.logger_info.ButtonPushedFcn = createCallbackFcn(app, @tool_Button3Pushed, true);
            app.logger_info.Icon = 'toolbar_info.png';
            app.logger_info.Text = '';
            app.logger_Separator1 = uiimage(app.logger_toolbar);
            app.logger_Separator1.ImageSource = 'Line.png';
            app.logger_EditRFeyeList = uibutton(app.logger_toolbar, 'push');
            app.logger_EditRFeyeList.ButtonPushedFcn = createCallbackFcn(app, @logger_rfeyeListEditButtonPushed, true);
            app.logger_EditRFeyeList.Icon = 'Menu_ConnectBlue2.png';
            app.logger_EditRFeyeList.Tooltip = {'Editar lista de instrumentos'; '(RFeye Node 20-6)'};
            app.logger_EditRFeyeList.Text = '';
            app.logger_Monitor = uibutton(app.logger_toolbar, 'push');
            app.logger_Monitor.ButtonPushedFcn = createCallbackFcn(app, @logger_MonitorButtonPushed, true);
            app.logger_Monitor.Icon = 'auto22.png';
            app.logger_Monitor.Tooltip = {'Monitor'; 'Logger +'};
            app.logger_Monitor.Text = '';
            app.logger_Separator2 = uiimage(app.logger_toolbar);
            app.logger_Separator2.ImageSource = 'Line.png';
            app.logger_winSCP = uibutton(app.logger_toolbar, 'push');
            app.logger_winSCP.ButtonPushedFcn = createCallbackFcn(app, @logger_winSCPValueChanged, true);
            app.logger_winSCP.Icon = 'winSCP.png';
            app.logger_winSCP.Tooltip = {'FTP/SFTP'};
            app.logger_winSCP.Text = '';
            app.logger_ftpStatus = uilabel(app.logger_toolbar);
            app.logger_ftpStatus.Text = '';
            app.logger_ButtonPlay = uibutton(app.logger_toolbar, 'push');
            app.logger_ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @logger_ButtonPlayValueChanged, true);
            app.logger_ButtonPlay.Icon = 'General_play.png';
            app.logger_ButtonPlay.Text = 'Tarefa Logger';
            app.tool_Panel = uipanel(app.Grid1);
            app.tool_Grid1 = uigridlayout(app.tool_Panel);
            app.tool_Grid2 = uigridlayout(app.tool_Grid1);
            app.tool_Button1 = uibutton(app.tool_Grid2, 'push');
            app.tool_Button1.ButtonPushedFcn = createCallbackFcn(app, @tool_Button1Pushed, true);
            app.tool_Button1.Icon = 'toolbar_layout.png';
            app.tool_Button1.Text = '';
            app.tool_Button2 = uibutton(app.tool_Grid2, 'push');
            app.tool_Button2.ButtonPushedFcn = createCallbackFcn(app, @tool_Button2Pushed, true);
            app.tool_Button2.Icon = 'toolbar_settings.png';
            app.tool_Button2.Text = '';
            app.tool_Button3 = uibutton(app.tool_Grid2, 'push');
            app.tool_Button3.ButtonPushedFcn = createCallbackFcn(app, @tool_Button3Pushed, true);
            app.tool_Button3.Icon = 'toolbar_info.png';
            app.tool_Button3.Text = '';
            app.tool_REC = uiimage(app.tool_Grid2);
            app.tool_REC.Visible = 'off';
            app.tool_REC.ImageSource = 'REC.gif';
            app.tool_Task = uilabel(app.tool_Grid2);
            app.tool_Task.Text = '';
            app.menu_Panel = uipanel(app.Grid1);
            app.menu_Grid1 = uigridlayout(app.menu_Panel);
            app.menu_Grid2 = uigridlayout(app.menu_Grid1);
            app.menu_Grid2B = uigridlayout(app.menu_Grid2);
            app.menu_searchPath = uiimage(app.menu_Grid2B);
            app.menu_searchPath.ImageClickedFcn = createCallbackFcn(app, @tool_userPathImageClicked, true);
            app.menu_searchPath.ImageSource = 'toolbar_userPath.png';
            app.menu_userPath = uidropdown(app.menu_Grid2B);
            app.menu_userPath.Items = {'Path'};
            app.menu_userPath.ValueChangedFcn = createCallbackFcn(app, @tool_userPathValueChanged, true);
            app.menu_userPath.Value = 'Path';
            app.menu_Grid2A = uigridlayout(app.menu_Grid2);
            app.Button_Task = uibutton(app.menu_Grid2A, 'state');
            app.Button_Task.ValueChangedFcn = createCallbackFcn(app, @Button_TaskValueChanged, true);
            app.Button_Task.Tag = 'TASK';
            app.Button_Task.Tooltip = {'Modo SCPI'};
            app.Button_Task.Icon = 'Menu_Task.png';
            app.Button_Task.Text = '';
            app.menu_Separator1 = uiimage(app.menu_Grid2A);
            app.menu_Separator1.ImageSource = 'Line.png';
            app.Button_Home = uibutton(app.menu_Grid2A, 'state');
            app.Button_Home.ValueChangedFcn = createCallbackFcn(app, @Button_HomeValueChanged, true);
            app.Button_Home.Tag = 'HOME';
            app.Button_Home.Icon = 'home.png';
            app.Button_Home.Text = '';
            app.Button_Home.Value = true;
            app.Button_Logger = uibutton(app.menu_Grid2A, 'state');
            app.Button_Logger.ValueChangedFcn = createCallbackFcn(app, @Button_LoggerValueChanged, true);
            app.Button_Logger.Tag = 'LOGGER';
            app.Button_Logger.Tooltip = {'Modo LOGGER'};
            app.Button_Logger.Icon = 'Instr_CRFS.png';
            app.Button_Logger.Text = '';
            app.Button_AnatelDB = uibutton(app.menu_Grid2A, 'push');
            app.Button_AnatelDB.ButtonPushedFcn = createCallbackFcn(app, @Button_AnatelDBPushed, true);
            app.Button_AnatelDB.Icon = 'home_Mosaico.png';
            app.Button_AnatelDB.Tooltip = {'anateldb'};
            app.Button_AnatelDB.Text = '';
            app.menu_Separator1_2 = uiimage(app.menu_Grid2A);
            app.menu_Separator1_2.ImageSource = 'Line.png';
            % Show the figure after all components are created
            app.MainApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainApp)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainApp)
        end
    end
end